classdef ciCameraWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISETComputationalImagingCameraControlPanelUIFigure  matlab.ui.Figure
        camInfo                   matlab.ui.control.Table
        sceneInfo                 matlab.ui.control.Table
        opticsInfo                matlab.ui.control.Table
        sensorInfo                matlab.ui.control.Table
        objMotionInfo             matlab.ui.control.Table
        camMotionInfo             matlab.ui.control.Table
        HDRFramesLabel            matlab.ui.control.Label
        HDRFramesSlider           matlab.ui.control.Slider
        CameraandControlsLabel    matlab.ui.control.Label
        BurstFramesLabel          matlab.ui.control.Label
        BurstFramesSlider         matlab.ui.control.Slider
        FocusFramesSliderLabel    matlab.ui.control.Label
        FocusFramesSlider         matlab.ui.control.Slider
        ImagePreviewButton        matlab.ui.control.Button
        TakePictureButton         matlab.ui.control.Button
        PhotoModeDropDownLabel    matlab.ui.control.Label
        PhotoModeDropDown         matlab.ui.control.DropDown
        ImageDisplay              matlab.ui.control.Image
        EnableAdvancedIPCheckBox  matlab.ui.control.CheckBox
        Label                     matlab.ui.control.Label
        PhotoModeDial             matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        ourCamera; % a camera of some form
        ourScene;       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ourCamera, ourScene)
            
            % Check if we're already running?
            %runningApp = getRunningApp(app);
            %not how to use this cleanly to allow us to refresh if it
            %happens?
            
            app.ourCamera = ourCamera;
            app.ourScene = ourScene;

            % Eventually we should implement property editing:
            camProps = ourCamera.showInfo();
            ourCamData = cell2table(camProps);
            app.camInfo.Data = ourCamData;
            
            sceneProps = ourScene.showInfo();
            ourSceneData = cell2table(sceneProps);
            app.sceneInfo.Data = ourSceneData;
            
            % just show first moving object
            if ~isempty(ourScene.objectMotion)
                motionInfo = [];
                for ii = 1:numel(ourScene.objectMotion)
                    motionInfo = [motionInfo ourScene.objectMotion{ii}];
                end
                app.objMotionInfo.Data = cell2table(motionInfo);
            end  

            if ~isempty(ourScene.cameraMotion)
                app.camMotionInfo.Data = cell2table(ourScene.cameraMotion{1});
            end

            app.CameraandControlsLabel.Text = "Camera of Type: " + class(ourCamera);
            
            ourOptics = ourCamera.cmodules(1).oi.optics;
            iePTable(ourOptics, 'uitable', app.opticsInfo,'format', 'embed');
            app.opticsInfo.ColumnName = {'Optics Property', 'Value'};
                        
            ourSensor = ourCamera.cmodules(1).sensor;
            iePTable(ourSensor, 'uitable', app.sensorInfo,'format', 'embed');
            app.sensorInfo.ColumnName = {'Sensor Property', 'Value'};
            
            % fill in camera control defaults
            if isprop(ourCamera,'numBurstFrames')
                app.BurstFramesSlider.Value = ourCamera.numBurstFrames;
            end
            if isprop(ourCamera, 'numHDRFrames')
                app.HDRFramesSlider.Value = ourCamera.numHDRFrames;
            end
            if isprop(ourCamera,'numFocusFrames')
                app.FocusFramesSlider.Value = ourCamera.numFocusFrames;
            end
            
            % If we ever get tired of dials, we can go back to a
            % drop-down:)
            %app.PhotoModeDropDown.Items = ourCamera.supportedIntents;
            %app.PhotoModeDropDown.Value = ourCamera.supportedIntents{1};

            app.PhotoModeDial.Items = ourCamera.supportedIntents;
            app.PhotoModeDial.Value = ourCamera.supportedIntents{1};

        end

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
            photoIntent = app.PhotoModeDial.Value;
            cam = app.ourCamera;
            
            % Advanced processing means let the ISP combine frames in RGB
            % space and give us a final photo, instead of just a simple
            % combination of raw frames
            useAdvancedProcessing = app.EnableAdvancedIPCheckBox.Value;
            if useAdvancedProcessing
                app.ImageDisplay.ImageSource =  cam.TakePicture(app.ourScene, photoIntent, ...
                    'returnIP', false);
            else
                photoIP = cam.TakePicture(app.ourScene, photoIntent,'returnIP',true);
                gam     = ipGet(photoIP,'display gamma');
                app.ImageDisplay.ImageSource = imageShowImage(photoIP, gam, true, 0);
                ipWindow(photoIP);
            end
        end

        % Value changed function: HDRFramesSlider
        function HDRFramesSliderValueChanged(app, event)
            value = round(app.HDRFramesSlider.Value);
            app.ourCamera.numHDRFrames = value;
            app.HDRFramesSlider.Value = value;
        end

        % Value changed function: BurstFramesSlider
        function BurstFramesSliderValueChanged(app, event)
            value = round(app.BurstFramesSlider.Value);
            app.ourCamera.numBurstFrames = value;
            app.BurstFramesSlider.Value = value;
            
        end

        % Value changed function: FocusFramesSlider
        function FocusFramesSliderValueChanged(app, event)
            value = round(app.FocusFramesSlider.Value);
            app.ourCamera.numFocusFrames = value;
            app.FocusFramesSlider.Value = value;

        end

        % Value changing function: HDRFramesSlider
        function HDRFramesSliderValueChanging(app, event)
            app.HDRFramesSlider.Value = round(event.Value);            
        end

        % Value changing function: BurstFramesSlider
        function BurstFramesSliderValueChanging(app, event)
            app.BurstFramesSlider.Value = round(event.Value); 
        end

        % Value changing function: FocusFramesSlider
        function FocusFramesSliderValueChanging(app, event)
            app.FocusFramesSlider.Value = round(event.Value); 
        end

        % Button pushed function: ImagePreviewButton
        function ImagePreviewButtonPushed(app, event)
            
            [tmpScenes, tmpSceneFiles] = app.ourScene.preview();
            
            % Not elegant, but let's see if it works
            app.ImageDisplay.ImageSource = tmpScenes{1}.data.photons;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISETComputationalImagingCameraControlPanelUIFigure = uifigure('Visible', 'off');
            app.ISETComputationalImagingCameraControlPanelUIFigure.Name = 'ISET Computational Imaging Camera Control Panel';
            app.ISETComputationalImagingCameraControlPanelUIFigure.Icon = 'Camera-n-brush-pd.png';
            app.camInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.camInfo.ColumnName = {'Camera Property'; 'Default Value'};
            app.camInfo.RowName = {};
            app.camInfo.Visible = 'off';
            app.sceneInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.sceneInfo.ColumnName = {'Scene Property'; 'Value'};
            app.sceneInfo.RowName = {};
            app.opticsInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.opticsInfo.ColumnName = {'Optics Property'; 'Value'};
            app.opticsInfo.RowName = {};
            app.sensorInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.sensorInfo.ColumnName = {'Sensor Property'; 'Value'};
            app.sensorInfo.RowName = {};
            app.objMotionInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.objMotionInfo.ColumnName = {'Moving Object Name'; 'Translation (x y z)'; 'Rotation (degrees)'};
            app.objMotionInfo.RowName = {};
            app.camMotionInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.camMotionInfo.ColumnName = {'Moving Camera Name'; 'Translation (x y z)'; 'Rotation (degrees)'};
            app.camMotionInfo.RowName = {};
            app.HDRFramesLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.HDRFramesLabel.Text = 'HDR Frames';
            app.HDRFramesSlider = uislider(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.HDRFramesSlider.Limits = [3 9];
            app.HDRFramesSlider.MajorTicks = [3 5 7 9];
            app.HDRFramesSlider.ValueChangedFcn = createCallbackFcn(app, @HDRFramesSliderValueChanged, true);
            app.HDRFramesSlider.ValueChangingFcn = createCallbackFcn(app, @HDRFramesSliderValueChanging, true);
            app.HDRFramesSlider.MinorTicks = [3 5 7 9];
            app.HDRFramesSlider.Value = 3;
            app.CameraandControlsLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.CameraandControlsLabel.Text = 'Camera and Controls:';
            app.BurstFramesLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.BurstFramesLabel.Text = 'Burst Frames';
            app.BurstFramesSlider = uislider(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.BurstFramesSlider.Limits = [3 9];
            app.BurstFramesSlider.MajorTicks = [3 5 7 9];
            app.BurstFramesSlider.ValueChangedFcn = createCallbackFcn(app, @BurstFramesSliderValueChanged, true);
            app.BurstFramesSlider.ValueChangingFcn = createCallbackFcn(app, @BurstFramesSliderValueChanging, true);
            app.BurstFramesSlider.MinorTicks = [];
            app.BurstFramesSlider.Value = 3;
            app.FocusFramesSliderLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.FocusFramesSliderLabel.Text = {'Focus'; 'Frames'};
            app.FocusFramesSlider = uislider(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.FocusFramesSlider.Limits = [3 12];
            app.FocusFramesSlider.MajorTicks = [3 5 7 9 11];
            app.FocusFramesSlider.ValueChangedFcn = createCallbackFcn(app, @FocusFramesSliderValueChanged, true);
            app.FocusFramesSlider.ValueChangingFcn = createCallbackFcn(app, @FocusFramesSliderValueChanging, true);
            app.FocusFramesSlider.MinorTicks = [];
            app.FocusFramesSlider.Value = 7;
            app.ImagePreviewButton = uibutton(app.ISETComputationalImagingCameraControlPanelUIFigure, 'push');
            app.ImagePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @ImagePreviewButtonPushed, true);
            app.ImagePreviewButton.Text = 'Image Preview';
            app.TakePictureButton = uibutton(app.ISETComputationalImagingCameraControlPanelUIFigure, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Icon = 'Crystal_Clear_device_camera.png';
            app.TakePictureButton.Text = 'Take Picture';
            app.PhotoModeDropDownLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.PhotoModeDropDownLabel.Enable = 'off';
            app.PhotoModeDropDownLabel.Visible = 'off';
            app.PhotoModeDropDownLabel.Text = 'Photo Mode:';
            app.PhotoModeDropDown = uidropdown(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.PhotoModeDropDown.Items = {'Auto', 'HDR', 'Burst', 'Focus'};
            app.PhotoModeDropDown.Enable = 'off';
            app.PhotoModeDropDown.Visible = 'off';
            app.PhotoModeDropDown.Value = 'Auto';
            app.ImageDisplay = uiimage(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.EnableAdvancedIPCheckBox = uicheckbox(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.EnableAdvancedIPCheckBox.Text = 'EnableAdvancedIP';
            app.EnableAdvancedIPCheckBox.Value = true;
            app.Label = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.Label.Text = '';
            app.PhotoModeDial = uiknob(app.ISETComputationalImagingCameraControlPanelUIFigure, 'discrete');
            app.PhotoModeDial.Items = {'Auto', 'HDR', 'Manual'};
            app.PhotoModeDial.Value = 'Auto';
            % Show the figure after all components are created
            app.ISETComputationalImagingCameraControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ciCameraWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ISETComputationalImagingCameraControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ISETComputationalImagingCameraControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISETComputationalImagingCameraControlPanelUIFigure)
        end
    end
end