classdef cpCameraWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISETComputationalImagingCameraControlPanelUIFigure  matlab.ui.Figure
        CameraandControlsLabel          matlab.ui.control.Label
        SeePreviewButton                matlab.ui.control.Button
        TakePictureButton               matlab.ui.control.Button
        PhotoModeDropDownLabel          matlab.ui.control.Label
        PhotoModeDropDown               matlab.ui.control.DropDown
        ImageDisplay                    matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        CameraContolsTab                matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        PhotoModeDial                   matlab.ui.control.DiscreteKnob
        HDRFramesLabel                  matlab.ui.control.Label
        HDRFramesSlider                 matlab.ui.control.Slider
        BurstFramesLabel                matlab.ui.control.Label
        BurstFramesSlider               matlab.ui.control.Slider
        FocusFramesSliderLabel          matlab.ui.control.Label
        FocusFramesSlider               matlab.ui.control.Slider
        EnableAdvancedProcessingButton  matlab.ui.control.StateButton
        Label_2                         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CameraModulePropertiesTab       matlab.ui.container.Tab
        opticsInfo                      matlab.ui.control.Table
        sensorInfo                      matlab.ui.control.Table
        camInfo                         matlab.ui.control.Table
        ScenePropertiesTab              matlab.ui.container.Tab
        sceneInfo                       matlab.ui.control.Table
        objMotionInfo                   matlab.ui.control.Table
        camMotionInfo                   matlab.ui.control.Table
        Image3                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        ourCamera; % a camera of some form
        ourScene;       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ourCamera, ourScene)
            
            % Check if we're already running?
            %runningApp = getRunningApp(app);
            %not how to use this cleanly to allow us to refresh if it
            %happens?
            
            app.ourCamera = ourCamera;
            app.ourScene = ourScene;

            % Eventually we should implement property editing:
            camProps = ourCamera.showInfo();
            ourCamData = cell2table(camProps);
            app.camInfo.Data = ourCamData;
            
            sceneProps = ourScene.showInfo();
            ourSceneData = cell2table(sceneProps);
            app.sceneInfo.Data = ourSceneData;
            
            % just show first moving object
            if ~isempty(ourScene.objectMotion)
                motionInfo = [];
                for ii = 1:numel(ourScene.objectMotion)
                    motionInfo = [motionInfo ourScene.objectMotion{ii}];
                end
                app.objMotionInfo.Data = cell2table(motionInfo);
            end  

            if ~isempty(ourScene.cameraMotion)
                app.camMotionInfo.Data = cell2table(ourScene.cameraMotion{1});
            end

            app.CameraandControlsLabel.Text = "Camera of Type: " + class(ourCamera);
            
            ourOptics = ourCamera.cmodules(1).oi.optics;
            iePTable(ourOptics, 'uitable', app.opticsInfo,'format', 'embed');
            app.opticsInfo.ColumnName = {'Optics Property', 'Value'};
                        
            ourSensor = ourCamera.cmodules(1).sensor;
            iePTable(ourSensor, 'uitable', app.sensorInfo,'format', 'embed');
            app.sensorInfo.ColumnName = {'Sensor Property', 'Value'};
            
            % fill in camera control defaults
            if isprop(ourCamera,'numBurstFrames')
                app.BurstFramesSlider.Value = ourCamera.numBurstFrames;
            end
            if isprop(ourCamera, 'numHDRFrames')
                app.HDRFramesSlider.Value = ourCamera.numHDRFrames;
            end
            if isprop(ourCamera,'numFocusFrames')
                app.FocusFramesSlider.Value = ourCamera.numFocusFrames;
            end
            
            % If we ever get tired of dials, we can go back to a
            % drop-down:)
            %app.PhotoModeDropDown.Items = ourCamera.supportedIntents;
            %app.PhotoModeDropDown.Value = ourCamera.supportedIntents{1};

            app.PhotoModeDial.Items = ourCamera.supportedIntents;
            app.PhotoModeDial.Value = ourCamera.supportedIntents{1};

        end

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
            
            app.TakePictureButton.Enable = false;
            photoIntent = app.PhotoModeDial.Value;
            cam = app.ourCamera;
            
            % Advanced processing means let the ISP combine frames in RGB
            % space and give us a final photo, instead of just a simple
            % combination of raw frames
            useAdvancedProcessing = app.EnableAdvancedProcessingButton.Value;
            if useAdvancedProcessing
                photoResult = cam.TakePicture(app.ourScene, photoIntent, ...
                    'insensorIP', false);
                % Not all camera types support returning a finished image,
                % some only return an ip, so we need to check
                if isstruct(photoResult)
                    gam     = ipGet(photoResult,'display gamma');
                    app.ImageDisplay.ImageSource = imageShowImage(photoResult, gam, true, 0);
                    ipWindow(photoResult);
                else
                    app.ImageDisplay.ImageSource =  photoResult;
                end
            else
                photoResult = cam.TakePicture(app.ourScene, photoIntent,'insensorIP',true);
                if isstruct(photoResult) % meaning we actually got an ip
                    gam     = ipGet(photoResult,'display gamma');
                    app.ImageDisplay.ImageSource = imageShowImage(photoResult, gam, true, 0);
                    ipWindow(photoResult);
                else
                    app.ImageDisplay.ImageSource =  photoResult;
                end
            end
            app.TakePictureButton.Enable = true;
        end

        % Value changed function: HDRFramesSlider
        function HDRFramesSliderValueChanged(app, event)
            value = round(app.HDRFramesSlider.Value);
            app.ourCamera.numHDRFrames = value;
            app.HDRFramesSlider.Value = value;
        end

        % Value changed function: BurstFramesSlider
        function BurstFramesSliderValueChanged(app, event)
            value = round(app.BurstFramesSlider.Value);
            app.ourCamera.numBurstFrames = value;
            app.BurstFramesSlider.Value = value;
            
        end

        % Value changed function: FocusFramesSlider
        function FocusFramesSliderValueChanged(app, event)
            value = round(app.FocusFramesSlider.Value);
            app.ourCamera.numFocusFrames = value;
            app.FocusFramesSlider.Value = value;

        end

        % Value changing function: HDRFramesSlider
        function HDRFramesSliderValueChanging(app, event)
            app.HDRFramesSlider.Value = round(event.Value);            
        end

        % Value changing function: BurstFramesSlider
        function BurstFramesSliderValueChanging(app, event)
            app.BurstFramesSlider.Value = round(event.Value); 
        end

        % Value changing function: FocusFramesSlider
        function FocusFramesSliderValueChanging(app, event)
            app.FocusFramesSlider.Value = round(event.Value); 
        end

        % Button pushed function: SeePreviewButton
        function SeePreviewButtonPushed(app, event)
            
            app.SeePreviewButton.Enable = false;
            [tmpScenes, tmpSceneFiles] = app.ourScene.preview();
            
            % Not elegant, but let's see if it works
            ourGamma = 2; % just a first shot
            app.ImageDisplay.ImageSource = sceneShowImage(tmpScenes{1},0,2,0);
            app.SeePreviewButton.Enable = true;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISETComputationalImagingCameraControlPanelUIFigure = uifigure('Visible', 'off');
            app.ISETComputationalImagingCameraControlPanelUIFigure.Name = 'ISET Computational Imaging Camera Control Panel';
            app.ISETComputationalImagingCameraControlPanelUIFigure.Icon = 'Camera-n-brush-pd.png';
            app.CameraandControlsLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.CameraandControlsLabel.Text = 'Camera and Controls:';
            app.SeePreviewButton = uibutton(app.ISETComputationalImagingCameraControlPanelUIFigure, 'push');
            app.SeePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SeePreviewButtonPushed, true);
            app.SeePreviewButton.Tooltip = {'Have your camera create the image you''d see when previwing, and which it will use to determine shooting parameters'};
            app.SeePreviewButton.Text = 'See Preview';
            app.TakePictureButton = uibutton(app.ISETComputationalImagingCameraControlPanelUIFigure, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Icon = 'Crystal_Clear_device_camera.png';
            app.TakePictureButton.Text = 'Take Picture';
            app.PhotoModeDropDownLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.PhotoModeDropDownLabel.Enable = 'off';
            app.PhotoModeDropDownLabel.Visible = 'off';
            app.PhotoModeDropDownLabel.Text = 'Photo Mode:';
            app.PhotoModeDropDown = uidropdown(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.PhotoModeDropDown.Items = {'Auto', 'HDR', 'Burst', 'Focus'};
            app.PhotoModeDropDown.Enable = 'off';
            app.PhotoModeDropDown.Visible = 'off';
            app.PhotoModeDropDown.Value = 'Auto';
            app.ImageDisplay = uiimage(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.TabGroup = uitabgroup(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.CameraContolsTab = uitab(app.TabGroup);
            app.CameraContolsTab.Title = 'Camera Contols';
            app.Label_3 = uilabel(app.CameraContolsTab);
            app.Label_3.Text = '';
            app.PhotoModeDial = uiknob(app.CameraContolsTab, 'discrete');
            app.PhotoModeDial.Items = {'Auto', 'HDR', 'Manual'};
            app.PhotoModeDial.Tooltip = {'Control the shooting mode of your camera. This Dial will automatically allow you to select between whichever photo intents it supports.'};
            app.PhotoModeDial.Value = 'Auto';
            app.HDRFramesLabel = uilabel(app.CameraContolsTab);
            app.HDRFramesLabel.Text = 'HDR Frames';
            app.HDRFramesSlider = uislider(app.CameraContolsTab);
            app.HDRFramesSlider.Limits = [3 9];
            app.HDRFramesSlider.MajorTicks = [3 5 7 9];
            app.HDRFramesSlider.ValueChangedFcn = createCallbackFcn(app, @HDRFramesSliderValueChanged, true);
            app.HDRFramesSlider.ValueChangingFcn = createCallbackFcn(app, @HDRFramesSliderValueChanging, true);
            app.HDRFramesSlider.MinorTicks = [3 5 7 9];
            app.HDRFramesSlider.Tooltip = {'Select how many frames your camera will record when you are shooting in HDR.'};
            app.HDRFramesSlider.Value = 3;
            app.BurstFramesLabel = uilabel(app.CameraContolsTab);
            app.BurstFramesLabel.Text = 'Burst Frames';
            app.BurstFramesSlider = uislider(app.CameraContolsTab);
            app.BurstFramesSlider.Limits = [3 9];
            app.BurstFramesSlider.MajorTicks = [3 5 7 9];
            app.BurstFramesSlider.ValueChangedFcn = createCallbackFcn(app, @BurstFramesSliderValueChanged, true);
            app.BurstFramesSlider.ValueChangingFcn = createCallbackFcn(app, @BurstFramesSliderValueChanging, true);
            app.BurstFramesSlider.MinorTicks = [];
            app.BurstFramesSlider.Value = 3;
            app.FocusFramesSliderLabel = uilabel(app.CameraContolsTab);
            app.FocusFramesSliderLabel.Text = {'Focus'; 'Frames'};
            app.FocusFramesSlider = uislider(app.CameraContolsTab);
            app.FocusFramesSlider.Limits = [3 12];
            app.FocusFramesSlider.MajorTicks = [3 5 7 9 11];
            app.FocusFramesSlider.ValueChangedFcn = createCallbackFcn(app, @FocusFramesSliderValueChanged, true);
            app.FocusFramesSlider.ValueChangingFcn = createCallbackFcn(app, @FocusFramesSliderValueChanging, true);
            app.FocusFramesSlider.MinorTicks = [];
            app.FocusFramesSlider.Value = 7;
            app.EnableAdvancedProcessingButton = uibutton(app.CameraContolsTab, 'state');
            app.EnableAdvancedProcessingButton.Text = 'Enable Advanced Processing';
            app.Label_2 = uilabel(app.CameraContolsTab);
            app.Label_2.Text = '';
            app.TextArea = uitextarea(app.CameraContolsTab);
            app.TextArea.Value = {'Control over registration, tonemapping, etc. could go here'; ''};
            app.CameraModulePropertiesTab = uitab(app.TabGroup);
            app.CameraModulePropertiesTab.Title = 'Camera Module Properties';
            app.opticsInfo = uitable(app.CameraModulePropertiesTab);
            app.opticsInfo.ColumnName = {'Optics Property'; 'Value'};
            app.opticsInfo.RowName = {};
            app.sensorInfo = uitable(app.CameraModulePropertiesTab);
            app.sensorInfo.ColumnName = {'Sensor Property'; 'Value'};
            app.sensorInfo.RowName = {};
            app.camInfo = uitable(app.CameraModulePropertiesTab);
            app.camInfo.ColumnName = {'Camera Property'; 'Default Value'};
            app.camInfo.RowName = {};
            app.ScenePropertiesTab = uitab(app.TabGroup);
            app.ScenePropertiesTab.Title = 'Scene Properties';
            app.sceneInfo = uitable(app.ScenePropertiesTab);
            app.sceneInfo.ColumnName = {'Scene Property'; 'Value'};
            app.sceneInfo.RowName = {};
            app.objMotionInfo = uitable(app.ScenePropertiesTab);
            app.objMotionInfo.ColumnName = {'Moving Object Name'; 'Translation (x y z)'; 'Rotation (degrees)'};
            app.objMotionInfo.RowName = {};
            app.camMotionInfo = uitable(app.ScenePropertiesTab);
            app.camMotionInfo.ColumnName = {'Moving Camera Name'; 'Translation (x y z)'; 'Rotation (degrees)'};
            app.camMotionInfo.RowName = {};
            app.Image3 = uiimage(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.Image3.ImageSource = 'Camera-n-brush-pd.svg';
            % Show the figure after all components are created
            app.ISETComputationalImagingCameraControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cpCameraWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ISETComputationalImagingCameraControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ISETComputationalImagingCameraControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISETComputationalImagingCameraControlPanelUIFigure)
        end
    end
end