classdef displayWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        menuFile                     matlab.ui.container.Menu
        menuLoadDisplayModel         matlab.ui.container.Menu
        menuSaveDisplayModel         matlab.ui.container.Menu
        menuFileLoadImage            matlab.ui.container.Menu
        menuFileRefresh              matlab.ui.container.Menu
        menuClose                    matlab.ui.container.Menu
        menuEdit                     matlab.ui.container.Menu
        menuEditRenameDisplay        matlab.ui.container.Menu
        menuEditNew                  matlab.ui.container.Menu
        menuDeleteCurrent            matlab.ui.container.Menu
        menuCopyCurrent              matlab.ui.container.Menu
        menuShowInNewWindow          matlab.ui.container.Menu
        menuEditChangeFontSize       matlab.ui.container.Menu
        menuPlot                     matlab.ui.container.Menu
        menuPlotDisplaySPD           matlab.ui.container.Menu
        menuPlotGamma                matlab.ui.container.Menu
        subpixeImage                 matlab.ui.container.Menu
        menuDisplay                  matlab.ui.container.Menu
        menuCRT                      matlab.ui.container.Menu
        menuLCD                      matlab.ui.container.Menu
        menuLCDVerticalStripesRGB    matlab.ui.container.Menu
        menuLCDVerticalStripesBGR    matlab.ui.container.Menu
        menuLCDHorizontalStripesRGB  matlab.ui.container.Menu
        menuLCDHorizontalStripesBGR  matlab.ui.container.Menu
        menuOLED                     matlab.ui.container.Menu
        menuAnalyze                  matlab.ui.container.Menu
        menuPlotGamut                matlab.ui.container.Menu
        menuPlotGamut3D              matlab.ui.container.Menu
        menuAnalyzeScene             matlab.ui.container.Menu
        menuAnalyzeSceneSubpixel     matlab.ui.container.Menu
        menuAnalyzeOutputImage       matlab.ui.container.Menu
        menuHelp                     matlab.ui.container.Menu
        menuProductHelp              matlab.ui.container.Menu
        menuAbout                    matlab.ui.container.Menu
        axes1                        matlab.ui.control.UIAxes
        uipanel1                     matlab.ui.container.Panel
        txtMaxLum                    matlab.ui.control.Label
        editMaxLum                   matlab.ui.control.EditField
        editVar                      matlab.ui.control.EditField
        txtPosVar                    matlab.ui.control.Label
        txtAmp                       matlab.ui.control.Label
        editPPI                      matlab.ui.control.EditField
        uipanel2                     matlab.ui.container.Panel
        axes4                        matlab.ui.control.UIAxes
        popupSelectDisplay           matlab.ui.control.DropDown
        text61                       matlab.ui.control.Label
        uipanelSummary               matlab.ui.container.Panel
        txtSummary                   matlab.ui.control.Label
        pushbutton9                  matlab.ui.control.Button
        txtMessage                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function SubpixelScale_Callback(app, hObject, eventdata, handles)
            % hObject    handle to SubpixelScale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            answer = inputdlg('New samples per pixel (10 ~ 30)');
            if isempty(answer), return; end
            answer = str2double(answer);
            assert(answer > 9 && answer < 31, 'samples per pixel out of range');
            d = vcGetObject('display', ind);
            psfs = displayGet(d, 'psfs');
            d = displaySet(d, 'psfs', imresize(psfs, [answer answer]));
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end
        
        function refresh(app)
            % Fill scene window fields based on the current display information
            %
            %     refresh(app)
            %
            
            %% Set values to boxes in display window
            d = vcGetObject('DISPLAY');
            
            if isempty(d)
                % No display, so set empty
                str = [];
                app.editMaxLum.Value = str;
                app.editVar.Value = 'N/A';
                app.editPPI.Value = str;
                % Select scene popup contents
                % app.popupSelectDisplay.Item = app.popupSelectDisplay.Items{1};
            else
                % Text boxes on right: we should reduce the fields in SCENE.
                xyz = displayGet(d, 'white xyz');
                app.editMaxLum.Value = num2str(xyz(2));
                app.editVar.Value = 'N/A';
                app.editPPI.Value = sprintf('%s dpi',num2str(displayGet(d, 'dpi')));
            end
            
            %% Description box on upper right
            set(handles.txtSummary,'String', displayDescription(d));
            
            %% Redraw the main image
            displayShowImage(d, [], handles.axes1);
            set(handles.txtMessage, 'String', 'Original Image');
            
            %% Set subpixel figure
            dixel_image = displayGet(d, 'dixel image');
            if ~isempty(dixel_image)
                if size(dixel_image,3) == 3
                    imshow(dixel_image / max(dixel_image(:)), 'Parent', handles.axes4);
                else
                    gam = 1;
                    wave = displayGet(d, 'wave');
                    photons = vcReadImage(dixel_image, 'rgb', d);
                    axes(handles.axes4);
                    imageSPD(photons, wave, gam, [], [], 1);
                    axes(handles.axes1);
                end
            end
            
        end
        
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.KeyPressFcn = 'ctDisplayKeyPress';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function displayWindow_OpeningFcn(app, varargin)

            dNames = vcGetObjectNames('DISPLAY');
            popupDisplayNames = cell(numel(dNames) + 1,1);
            popupDisplayNames{1} = 'Default';
            for ii=1:numel(dNames)
                popupDisplayNames{ii+1} = sprintf('%d - %s', dNames{ii});
            end
            app.popupSelectDisplay.Items = popupDisplayNames;
            
            % Refresh other components
            refresh(app);
        end

        % Value changed function: editMaxLum
        function editMaxLum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit box for max luminance
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            xyz = displayGet(d, 'white xyz');
            newLum = str2double(get(handles.editMaxLum, 'String'));
            spd = displayGet(d, 'spd') * newLum / xyz(2);
            d = displaySet(d, 'spd', spd);
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Value changed function: editPPI
        function editPPI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            newPPI = str2double(get(handles.editPPI, 'String'));
            d = displaySet(d, 'dpi', newPPI);
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Value changed function: editVar
        function editVar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            disp('Not yet implemented')
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            displayClose(handles.figure1);
        end

        % Menu selected function: menuAbout
        function menuAbout_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            warning('NYI')
        end

        % Menu selected function: menuAnalyzeOutputImage
        function menuAnalyzeOutputImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Output image (by units)
            ind = vcGetSelectedObject('display');
            d = vcGetObject('display', ind);
            if isempty(ind), disp('No display selected'); return; end
            
            h = get(handles.axes1, 'Children');
            I = get(h, 'CData');
            
            % select region
            set(handles.txtMessage, 'String', 'Select Region in Image');
            rect = floor(getrect);
            set(handles.txtMessage, 'String', 'Original Image');
            
            % round width and height to multiple of pixelsperdixel
            ppd = displayGet(d, 'pixels per dixel');
            rect(3:4) = ppd .* ceil(rect(3:4) ./ ppd);
            I = I(rect(2): rect(2)+rect(4)-1, rect(1) : rect(1) + rect(3)-1, :);
            
            % create a scene
            % For rgb image, we can use displayCompute the generate the output image
            % However, the output image will have same number of primaries as the
            % display (could be more than 3) and then imshow will not work. Thus, we
            % compute the scene radiance and get the srgb of the scene and show to the
            % window
            scene = sceneFromFile(I, 'rgb', [], d, [], 1, [], [20 20]);
            vcNewGraphWin; imshow(sceneGet(scene, 'rgb image'));
            
            
            return
        end

        % Menu selected function: menuAnalyzeSceneSubpixel
        function menuAnalyzeSceneSubpixel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Scene
            % Opens up a Scene Window
            ind = vcGetSelectedObject('display');
            d = vcGetObject('display', ind);
            
            % Get current image
            global vcSESSION;
            if isfield(vcSESSION, 'imgData')
                I = vcSESSION.imgData;
            else
                warning('No image set');
                return;
            end
            
            % select region
            set(handles.txtMessage, 'String', 'Select Region in Image');
            rect = floor(getrect);
            set(handles.txtMessage, 'String', 'Original Image');
            % round width and height to multiple of pixelsperdixel
            ppd = displayGet(d, 'pixels per dixel');
            rect(3:4) = ppd .* ceil(rect(3:4) ./ ppd);
            I = I(rect(2): rect(2)+rect(4)-1, rect(1) : rect(1) + rect(3)-1, :);
            
            % Get scene info
            overSample = displayGet(d, 'over sample');
            answer = inputdlg({'Scene name', 'Up sample (col)', 'Up sample (row)'}, ...
                'Scene Info', 1, ...
                {sprintf('scene-%s', displayGet(d, 'name')), ...
                num2str(overSample(1)), ...
                num2str(overSample(2))});
            if isempty(answer)
                return;
            else
                name = answer{1};
                overSample = [str2double(answer{2}) str2double(answer{3})];
            end
            
            % Generate scene
            if isempty(I), disp('No image set'); return; end
            scene = sceneFromFile(I, 'rgb', [], d, [], 1, [], overSample);
            scene = sceneSet(scene, 'name', name);
            
            % Compute scene size
            [r,c,~] = size(I);
            vDist = sceneGet(scene, 'distance');
            fov   = atand(max(r,c) * displayGet(d, 'metersperdot')/vDist);
            scene = sceneSet(scene, 'fov', fov);
            
            
            % Show scene window
            vcAddAndSelectObject('scene', scene);
            sceneWindow;
        end

        % Menu selected function: menuAnalyzeScene
        function menuAnalyzeScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Scene
            % Opens up a Scene Window
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            
            % Get current image
            global vcSESSION;
            if isfield(vcSESSION, 'imgData')
                I = vcSESSION.imgData;
            else
                warning('No image set');
                return;
            end
            
            % Get scene info
            name = inputdlg({'Scene name'}, 'Scene Info', 1, ...
                {sprintf('scene-%s', displayGet(d, 'name'))});
            if isempty(name), return; end
            
            % Generate scene
            if isempty(I),  warning('No image set'); return; end
            scene = sceneFromFile(I, 'rgb', [], d, [], 0);
            scene = sceneSet(scene, 'name', name{1});
            
            % Compute scene size
            [r,c,~] = size(I);
            vDist = sceneGet(scene, 'distance');
            fov   = atand(max(r,c)  * displayGet(d,'metersperdot')/vDist);
            scene = sceneSet(scene, 'fov', fov);
            
            % Show scene window
            vcAddAndSelectObject('scene', scene);
            sceneWindow;
        end

        % Menu selected function: menuCRT
        function menuCRT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | CRT
            d = displayCreate('CRT-Dell');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Menu selected function: menuClose
        function menuClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Close
            displayClose(handles.figure1);
        end

        % Menu selected function: menuCopyCurrent
        function menuCopyCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            dname = displayGet(d, 'name');
            d = displaySet(d, 'name', ['copy - ' dname]);
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Menu selected function: menuDeleteCurrent
        function menuDeleteCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete
            vcDeleteSelectedObject('display');
            refresh(app, [], [], handles);
            return
        end

        % Menu selected function: menuEditChangeFontSize
        function menuEditChangeFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % ctFontChangeSize(handles.figure1);
            answer = inputdlg('New Font Size (7~25)');
            if isempty(answer), return; end
            answer = str2double(answer);
            assert(answer > 6 && answer < 26, 'Front size out of range');
            set(handles.text61, 'FontSize', answer);
            set(handles.uipanelSummary, 'FontSize', answer);
            set(handles.txtSummary, 'FontSize', answer);
            set(handles.txtMaxLum, 'FontSize', answer);
            set(handles.txtPosVar, 'FontSize', answer);
            set(handles.txtAmp, 'FontSize', answer);
            set(handles.uipanel2, 'FontSize', answer);
        end

        % Menu selected function: menuEditNew
        function menuEditNew_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | New Model
            d = displayCreate('LCD-Apple');
            vcAddAndSelectObject('display', d);
            refresh(app, [], [], handles);
            return
        end

        % Menu selected function: menuEditRenameDisplay
        function menuEditRenameDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            answer = inputdlg('New Display Name');
            if isempty(answer), return; end
            d = vcGetObject('display', ind);
            d = displaySet(d, 'name', answer{1});
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Menu selected function: menuFileLoadImage
        function menuFileLoadImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load Image
            %
            fname = uigetfile('*.*', 'Choose Image File');
            if fname == 0, return; end
            I = im2double(imread(fname));
            
            global vcSESSION;
            vcSESSION.imgData = I;
            
            % Refresh other components
            refresh(app);
            
            return
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuFileRefresh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            refresh(app);
        end

        % Menu selected function: menuLCDHorizontalStripesBGR
        function menuLCDHorizontalStripesBGR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | LCD | Vertical BGR
            % displayGD   = ctGetObject('display');
            %
            % dpi = 72;
            % dSpacing = 0.001; % sample spacing in mm
            % vDisplayLCD = vDisplayCreate('lcd',dpi,dSpacing,'h','bgr');
            %
            % displayGD   = ctDisplaySet(displayGD,'vDisplay',vDisplayLCD);  % Add?  Replace?
            % ctSetObject('display', displayGD);
            % ctdpRefreshGUIWindow(hObject);
            warning('NYI');
        end

        % Menu selected function: menuLCDHorizontalStripesRGB
        function menuLCDHorizontalStripesRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % displayGD   = ctGetObject('display');
            % dpi = 72;
            % dSpacing = 0.001; % sample spacing in mm
            % vDisplayLCD = vDisplayCreate('lcd',dpi,dSpacing,'h','rgb');
            %
            % displayGD   = ctDisplaySet(displayGD,'vDisplay',vDisplayLCD);  % Add?  Replace?
            % ctSetObject('display', displayGD);
            % ctdpRefreshGUIWindow(hObject);
            warning('NYI');
        end

        % Menu selected function: menuLCDVerticalStripesBGR
        function menuLCDVerticalStripesBGR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | LCD | Vertical BGR
            % displayGD  = ctGetObject('display');
            %
            % dpi = 72;
            % dSpacing = 0.001; % sample spacing in mm
            % vDisplayLCD = vDisplayCreate('lcd',dpi,dSpacing,'v','bgr');
            %
            % displayGD  = ctDisplaySet(displayGD,'vDisplay',vDisplayLCD);  % Add?  Replace?
            % ctSetObject('display', displayGD);
            % ctdpRefreshGUIWindow(hObject);
            warning('NYI');
        end

        % Menu selected function: menuLCDVerticalStripesRGB
        function menuLCDVerticalStripesRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            d = displayCreate('LCD-Dell');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Menu selected function: menuLoadDisplayModel
        function menuLoadDisplayModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load Display
            %
            fname = uigetfile('*.mat', 'Load Display From MAT file');
            if fname == 0, return; end
            tmp = load(fname);
            if isfield(tmp, 'd'), d = tmp.d; else error('Not display file'); end
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Menu selected function: menuOLED
        function menuOLED_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuOLED (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            d = displayCreate('OLED-Sony');
            vcAddAndSelectObject('display', d);
            refresh(app);
        end

        % Menu selected function: menuPlotDisplaySPD
        function menuPlotDisplaySPD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            displayPlot(d, 'spd');
        end

        % Menu selected function: menuPlotGamma
        function menuPlotGamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            displayPlot(d, 'gamma');
        end

        % Menu selected function: menuPlotGamut3D
        function menuPlotGamut3D_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuPlotGamut3D (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); end
            d = vcGetObject('display', ind);
            displayPlot(d, 'gamut3d');
        end

        % Menu selected function: menuPlotGamut
        function menuPlotGamut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            displayPlot(d, 'gamut');
        end

        % Menu selected function: menuProductHelp
        function menuProductHelp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            warning('NYI');
        end

        % Menu selected function: menuSaveDisplayModel
        function menuSaveDisplayModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Save Display
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind); %#ok
            fname = uiputfile('*.mat');
            if fname == 0, return; end
            save(fname, 'd');
        end

        % Menu selected function: menuShowInNewWindow
        function menuShowInNewWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Show in Zoomed View
            pos = get(handles.figure1, 'Position');
            figure('Position', pos);
            % Get current image
            h = get(handles.axes1, 'children');
            I = get(h, 'CData');
            % Show image
            imshow(I);
        end

        % Value changed function: popupSelectDisplay
        function popupSelectDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Called when the 'Selected Display' popup is chosen
            val = get(handles.popupSelectDisplay,'value');
            vcSetSelectedObject('display',val);
            refresh(app);
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            menuAnalyzeSceneSubpixel_Callback(hObject, eventdata, handles);
        end

        % Menu selected function: subpixeImage
        function subpixeImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Sub pixel image
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('No display selected'); return; end
            d = vcGetObject('display', ind);
            psfs = displayGet(d, 'dixel image');
            if isempty(psfs)
                disp('no psfs in display model');
                return;
            else
                vcNewGraphWin;
                if size(psfs,3) == 3
                    imshow(psfs / max(psfs(:)));
                else
                    gam = 1;
                    wave = displayGet(d, 'wave');
                    photons = vcReadImage(psfs, 'rgb', d);
                    imageSPD(photons, wave, gam, [], [], 1);
                end
            end
            
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ISET-Display';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuLoadDisplayModel = uimenu(app.menuFile);
            app.menuLoadDisplayModel.MenuSelectedFcn = createCallbackFcn(app, @menuLoadDisplayModel_Callback, true);
            app.menuLoadDisplayModel.Text = 'Load Display';
            app.menuLoadDisplayModel.Tag = 'menuLoadDisplayModel';
            app.menuSaveDisplayModel = uimenu(app.menuFile);
            app.menuSaveDisplayModel.MenuSelectedFcn = createCallbackFcn(app, @menuSaveDisplayModel_Callback, true);
            app.menuSaveDisplayModel.Text = 'Save Display';
            app.menuSaveDisplayModel.Tag = 'menuSaveDisplayModel';
            app.menuFileLoadImage = uimenu(app.menuFile);
            app.menuFileLoadImage.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadImage_Callback, true);
            app.menuFileLoadImage.Separator = 'on';
            app.menuFileLoadImage.Text = 'Load image';
            app.menuFileLoadImage.Tag = 'menuFileLoadImage';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuClose = uimenu(app.menuFile);
            app.menuClose.MenuSelectedFcn = createCallbackFcn(app, @menuClose_Callback, true);
            app.menuClose.Separator = 'on';
            app.menuClose.Text = 'Close';
            app.menuClose.Tag = 'menuClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditRenameDisplay = uimenu(app.menuEdit);
            app.menuEditRenameDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuEditRenameDisplay_Callback, true);
            app.menuEditRenameDisplay.Text = 'Rename';
            app.menuEditRenameDisplay.Tag = 'menuEditRenameDisplay';
            app.menuEditNew = uimenu(app.menuEdit);
            app.menuEditNew.MenuSelectedFcn = createCallbackFcn(app, @menuEditNew_Callback, true);
            app.menuEditNew.Accelerator = 'N';
            app.menuEditNew.Text = 'New Display';
            app.menuEditNew.Tag = 'menuEditNew';
            app.menuDeleteCurrent = uimenu(app.menuEdit);
            app.menuDeleteCurrent.MenuSelectedFcn = createCallbackFcn(app, @menuDeleteCurrent_Callback, true);
            app.menuDeleteCurrent.Separator = 'on';
            app.menuDeleteCurrent.Text = 'Delete';
            app.menuDeleteCurrent.Tag = 'menuDeleteCurrent';
            app.menuCopyCurrent = uimenu(app.menuEdit);
            app.menuCopyCurrent.MenuSelectedFcn = createCallbackFcn(app, @menuCopyCurrent_Callback, true);
            app.menuCopyCurrent.Text = 'Copy';
            app.menuCopyCurrent.Tag = 'menuCopyCurrent';
            app.menuShowInNewWindow = uimenu(app.menuEdit);
            app.menuShowInNewWindow.MenuSelectedFcn = createCallbackFcn(app, @menuShowInNewWindow_Callback, true);
            app.menuShowInNewWindow.Separator = 'on';
            app.menuShowInNewWindow.Text = 'Original Image';
            app.menuShowInNewWindow.Tag = 'menuShowInNewWindow';
            app.menuEditChangeFontSize = uimenu(app.menuEdit);
            app.menuEditChangeFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditChangeFontSize_Callback, true);
            app.menuEditChangeFontSize.Text = 'Font Size';
            app.menuEditChangeFontSize.Tag = 'menuEditChangeFontSize';
            app.menuPlot = uimenu(app.figure1);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlotDisplaySPD = uimenu(app.menuPlot);
            app.menuPlotDisplaySPD.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDisplaySPD_Callback, true);
            app.menuPlotDisplaySPD.Text = 'SPD Primaries ';
            app.menuPlotDisplaySPD.Tag = 'menuPlotDisplaySPD';
            app.menuPlotGamma = uimenu(app.menuPlot);
            app.menuPlotGamma.MenuSelectedFcn = createCallbackFcn(app, @menuPlotGamma_Callback, true);
            app.menuPlotGamma.Text = 'Gamma';
            app.menuPlotGamma.Tag = 'menuPlotGamma';
            app.subpixeImage = uimenu(app.menuPlot);
            app.subpixeImage.MenuSelectedFcn = createCallbackFcn(app, @subpixeImage_Callback, true);
            app.subpixeImage.Text = 'Sub-pixel PSF';
            app.subpixeImage.Tag = 'subpixeImage';
            app.menuDisplay = uimenu(app.figure1);
            app.menuDisplay.Text = 'Display';
            app.menuDisplay.Tag = 'menuDisplay';
            app.menuCRT = uimenu(app.menuDisplay);
            app.menuCRT.MenuSelectedFcn = createCallbackFcn(app, @menuCRT_Callback, true);
            app.menuCRT.Text = 'CRT';
            app.menuCRT.Tag = 'menuCRT';
            app.menuLCD = uimenu(app.menuDisplay);
            app.menuLCD.Text = 'LCD ';
            app.menuLCD.Tag = 'menuLCD';
            app.menuLCDVerticalStripesRGB = uimenu(app.menuLCD);
            app.menuLCDVerticalStripesRGB.MenuSelectedFcn = createCallbackFcn(app, @menuLCDVerticalStripesRGB_Callback, true);
            app.menuLCDVerticalStripesRGB.Text = 'Vertical Stripes RGB';
            app.menuLCDVerticalStripesRGB.Tag = 'menuLCDVerticalStripesRGB';
            app.menuLCDVerticalStripesBGR = uimenu(app.menuLCD);
            app.menuLCDVerticalStripesBGR.MenuSelectedFcn = createCallbackFcn(app, @menuLCDVerticalStripesBGR_Callback, true);
            app.menuLCDVerticalStripesBGR.Text = 'Vertical Stripes BGR';
            app.menuLCDVerticalStripesBGR.Tag = 'menuLCDVerticalStripesBGR';
            app.menuLCDHorizontalStripesRGB = uimenu(app.menuLCD);
            app.menuLCDHorizontalStripesRGB.MenuSelectedFcn = createCallbackFcn(app, @menuLCDHorizontalStripesRGB_Callback, true);
            app.menuLCDHorizontalStripesRGB.Text = 'Horizontal Stripes RGB';
            app.menuLCDHorizontalStripesRGB.Tag = 'menuLCDHorizontalStripesRGB';
            app.menuLCDHorizontalStripesBGR = uimenu(app.menuLCD);
            app.menuLCDHorizontalStripesBGR.MenuSelectedFcn = createCallbackFcn(app, @menuLCDHorizontalStripesBGR_Callback, true);
            app.menuLCDHorizontalStripesBGR.Text = 'Horizontal Stripes BGR';
            app.menuLCDHorizontalStripesBGR.Tag = 'menuLCDHorizontalStripesBGR';
            app.menuOLED = uimenu(app.menuDisplay);
            app.menuOLED.MenuSelectedFcn = createCallbackFcn(app, @menuOLED_Callback, true);
            app.menuOLED.Text = 'OLED';
            app.menuOLED.Tag = 'menuOLED';
            app.menuAnalyze = uimenu(app.figure1);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.menuPlotGamut = uimenu(app.menuAnalyze);
            app.menuPlotGamut.MenuSelectedFcn = createCallbackFcn(app, @menuPlotGamut_Callback, true);
            app.menuPlotGamut.Text = 'Gamut (xy)';
            app.menuPlotGamut.Tag = 'menuPlotGamut';
            app.menuPlotGamut3D = uimenu(app.menuAnalyze);
            app.menuPlotGamut3D.MenuSelectedFcn = createCallbackFcn(app, @menuPlotGamut3D_Callback, true);
            app.menuPlotGamut3D.Text = 'Gamut (Lab)';
            app.menuPlotGamut3D.Tag = 'menuPlotGamut3D';
            app.menuAnalyzeScene = uimenu(app.menuAnalyze);
            app.menuAnalyzeScene.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeScene_Callback, true);
            app.menuAnalyzeScene.Separator = 'on';
            app.menuAnalyzeScene.Text = 'Scene (Pixel)';
            app.menuAnalyzeScene.Tag = 'menuAnalyzeScene';
            app.menuAnalyzeSceneSubpixel = uimenu(app.menuAnalyze);
            app.menuAnalyzeSceneSubpixel.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeSceneSubpixel_Callback, true);
            app.menuAnalyzeSceneSubpixel.Text = 'Scene (Subpixel)';
            app.menuAnalyzeSceneSubpixel.Tag = 'menuAnalyzeSceneSubpixel';
            app.menuAnalyzeOutputImage = uimenu(app.menuAnalyze);
            app.menuAnalyzeOutputImage.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeOutputImage_Callback, true);
            app.menuAnalyzeOutputImage.Text = 'Subpixel image';
            app.menuAnalyzeOutputImage.Tag = 'menuAnalyzeOutputImage';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuProductHelp = uimenu(app.menuHelp);
            app.menuProductHelp.MenuSelectedFcn = createCallbackFcn(app, @menuProductHelp_Callback, true);
            app.menuProductHelp.Text = 'Product help';
            app.menuProductHelp.Tag = 'menuProductHelp';
            app.menuAbout = uimenu(app.menuHelp);
            app.menuAbout.MenuSelectedFcn = createCallbackFcn(app, @menuAbout_Callback, true);
            app.menuAbout.Text = 'About';
            app.menuAbout.Tag = 'menuAbout';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = zeros(1,0);
            app.axes1.YTickLabel = '';
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Edit';
            app.uipanel1.Tag = 'uipanel1';
            app.txtMaxLum = uilabel(app.uipanel1);
            app.txtMaxLum.Tag = 'txtMaxLum';
            app.txtMaxLum.Text = 'Max lum (cd/m2)';
            app.editMaxLum = uieditfield(app.uipanel1, 'text');
            app.editMaxLum.ValueChangedFcn = createCallbackFcn(app, @editMaxLum_Callback, true);
            app.editMaxLum.Tag = 'editMaxLum';
            app.editMaxLum.Value = '100';
            app.editVar = uieditfield(app.uipanel1, 'text');
            app.editVar.ValueChangedFcn = createCallbackFcn(app, @editVar_Callback, true);
            app.editVar.Tag = 'editVar';
            app.editVar.Value = '0';
            app.txtPosVar = uilabel(app.uipanel1);
            app.txtPosVar.Tag = 'txtPosVar';
            app.txtPosVar.Tooltip = 'Sub-pixel position variance in microns';
            app.txtPosVar.Text = 'Position var';
            app.txtAmp = uilabel(app.uipanel1);
            app.txtAmp.Tag = 'txtAmp';
            app.txtAmp.Tooltip = 'Pixel Per Inch';
            app.txtAmp.Text = 'Display PPI';
            app.editPPI = uieditfield(app.uipanel1, 'text');
            app.editPPI.ValueChangedFcn = createCallbackFcn(app, @editPPI_Callback, true);
            app.editPPI.Tag = 'editPPI';
            app.editPPI.Value = '0';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'SubPixel';
            app.uipanel2.Tag = 'uipanel2';
            app.axes4 = uiaxes(app.uipanel2);
            app.axes4.XAxisLocation = 'top';
            app.axes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes4.Tag = 'axes4';
            app.popupSelectDisplay = uidropdown(app.figure1);
            app.popupSelectDisplay.Items = {'Default'};
            app.popupSelectDisplay.ValueChangedFcn = createCallbackFcn(app, @popupSelectDisplay_Callback, true);
            app.popupSelectDisplay.Tag = 'popupSelectDisplay';
            app.popupSelectDisplay.Value = 'Default';
            app.text61 = uilabel(app.figure1);
            app.text61.Tag = 'text61';
            app.text61.Text = 'Selected Display';
            app.uipanelSummary = uipanel(app.figure1);
            app.uipanelSummary.Title = 'Summary';
            app.uipanelSummary.Tag = 'uipanelSummary';
            app.txtSummary = uilabel(app.uipanelSummary);
            app.txtSummary.Tag = 'txtSummary';
            app.txtSummary.Text = '';
            app.pushbutton9 = uibutton(app.figure1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = 'Display to Scene';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = 'IP data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = displayWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)displayWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end