classdef ipWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        menuFile                     matlab.ui.container.Menu
        menuFileLoad                 matlab.ui.container.Menu
        menuFileSaveProcData         matlab.ui.container.Menu
        menuFileLoadImage            matlab.ui.container.Menu
        menuFileSave                 matlab.ui.container.Menu
        menuFileRefresh              matlab.ui.container.Menu
        menuFileClose                matlab.ui.container.Menu
        menuEdit                     matlab.ui.container.Menu
        menuEditName                 matlab.ui.container.Menu
        menuEditCreate               matlab.ui.container.Menu
        menuEditCopyImage            matlab.ui.container.Menu
        menuEditDelete               matlab.ui.container.Menu
        menuEditDeleteSome           matlab.ui.container.Menu
        menuImageWhite               matlab.ui.container.Menu
        menuEditResetWhite           matlab.ui.container.Menu
        menuScaleChooseMax           matlab.ui.container.Menu
        menuScaleDisplay             matlab.ui.container.Menu
        menuEditFontSize             matlab.ui.container.Menu
        menuEditClearMessage         matlab.ui.container.Menu
        menuEditZoom                 matlab.ui.container.Menu
        menuEditViewer               matlab.ui.container.Menu
        menuPlot                     matlab.ui.container.Menu
        menuPlImTrueSize             matlab.ui.container.Menu
        multipleImageRGB             matlab.ui.container.Menu
        menuPlotDisplay              matlab.ui.container.Menu
        plotDisplaySPD               matlab.ui.container.Menu
        plotGamut                    matlab.ui.container.Menu
        plotColorProcessingMatrices  matlab.ui.container.Menu
        plotMCCOverOff               matlab.ui.container.Menu
        newGraphWin                  matlab.ui.container.Menu
        menuDisplay                  matlab.ui.container.Menu
        menuReadSPD                  matlab.ui.container.Menu
        menuDisplayWindow            matlab.ui.container.Menu
        menuDisplayViewD             matlab.ui.container.Menu
        menuAnalyze                  matlab.ui.container.Menu
        menuAnColor                  matlab.ui.container.Menu
        menuAnROI                    matlab.ui.container.Menu
        menuAnLum                    matlab.ui.container.Menu
        menuAnChrom                  matlab.ui.container.Menu
        menuAnColorLAB               matlab.ui.container.Menu
        menuAnLUV                    matlab.ui.container.Menu
        menuRGBHist                  matlab.ui.container.Menu
        menuAnROIvSNR                matlab.ui.container.Menu
        menuAnColLumNoise            matlab.ui.container.Menu
        menuAnColMCCsRGB             matlab.ui.container.Menu
        menuAnColMCCEst              matlab.ui.container.Menu
        menuAnColVisualComp          matlab.ui.container.Menu
        menuAnLine                   matlab.ui.container.Menu
        menuAnLineH                  matlab.ui.container.Menu
        menuAnLineV                  matlab.ui.container.Menu
        menuAnalyzeCreateSB          matlab.ui.container.Menu
        menuAnalyzeISO12233          matlab.ui.container.Menu
        menuMetricsWindow            matlab.ui.container.Menu
        menuAnComputeFromSensor      matlab.ui.container.Menu
        menuComputeFromOI            matlab.ui.container.Menu
        menuAnComputeFromScene       matlab.ui.container.Menu
        menuHelp                     matlab.ui.container.Menu
        menuHelpProcessorOnline      matlab.ui.container.Menu
        menuHelpMetricsPG            matlab.ui.container.Menu
        menuHelpISETOnline           matlab.ui.container.Menu
        menuHelpAppNotes             matlab.ui.container.Menu
        txtDisplay                   matlab.ui.control.Label
        txtConsistency               matlab.ui.control.Label
        txtMessage                   matlab.ui.control.Label
        ProcessedimagePanel          matlab.ui.container.Panel
        axes1                        matlab.ui.control.UIAxes
        popSelect                    matlab.ui.control.DropDown
        btnNext                      matlab.ui.control.Button
        btnPrev                      matlab.ui.control.Button
        text10                       matlab.ui.control.Label
        editGamma                    matlab.ui.control.EditField
        PipelinesettingsPanel        matlab.ui.container.Panel
        panelDemosaic                matlab.ui.container.Panel
        textDemosaic                 matlab.ui.control.Label
        popDemosaic                  matlab.ui.control.DropDown
        btnCompute                   matlab.ui.control.Button
        panelIlluminant              matlab.ui.container.Panel
        txtIlluminant                matlab.ui.control.Label
        popBalance                   matlab.ui.control.DropDown
        panelSensor                  matlab.ui.container.Panel
        txtSensor                    matlab.ui.control.Label
        txtICS                       matlab.ui.control.Label
        txtMethod                    matlab.ui.control.Label
        popColorConversionM          matlab.ui.control.DropDown
        popColorSpace                matlab.ui.control.DropDown
        panelTransform               matlab.ui.container.Panel
        txtTransform                 matlab.ui.control.Label
        popTransform                 matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        function btnCustomOnOff_Callback(app, hObject, eventdata, handles)
            % On Custom button press
            
            vci = ieGetObject('vci');
            
            val = get(hObject,'Value');  % returns toggle state of btnCustomOnOff
            vci = ipSet(vci,'customRender',val);
            
            vcReplaceObject(vci);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end
        
        function drawRect(app, hObject, eventdata, handles, rect)
            % Not  implemented yet.
            rectangle('Position',rect);
            return
        end
        
        function ipRefresh(app, hObject, eventdata, handles)
            % Main refresh
            ipEditsAndButtons(handles,ieGetObject('VCIMAGE'));
            return
        end
        
        function menuAnColorMacbeth_Callback(app, hObject, eventdata, handles)
            % Analyze | ???
            vci = vcimageSRGB;
            ieAddObject(vci)
            ipRefresh(app, hObject, eventdata, handles);
            return
        end
        
        function menuAnalyzeSMTF_Callback(app, hObject, eventdata, handles)
            % Not currently used
            
            % Computes the responses to a series of harmonics. This routine assumes the
            % current parameters of the optics, isa and vcimage. It passes a set of
            % harmonics through these objects and analyzes the contrast of the
            % resulting displayed image. We have an ISO standard that we should
            % implement here.
            [rContrast,freq,fNames,rImages,sImages] = vcimageSystemMTF([],[],[],[],0);
            
            % Bad computation returns empty value.
            if isempty(rContrast), return;
            else
            
                % Plot the response contrast on the screen for each of the three display
                % primaries.  These two plotting/imaging calls should be in a separate
                % routine and they should be improved.
                vcNewGraphWin;
            
                rContrast = rContrast';
                plot(freq,rContrast(:,1),'ro-',freq,rContrast(:,2),'go-',freq,rContrast(:,3),'bo-')
                str = sprintf('System MTF (sensor %s)',fNames);
                title(str);
                xlabel('Spatial frequency (cpd)'); ylabel('Relative modulation');
                grid on
                line([0,freq(end)],[0,0],'color',[0,0,0]);
            
                uData.freq = freq;
                uData.rContrast = rContrast;
                uData.fNames = fNames;
                uData.rImages = rImages;
                uData.sImages = sImages;
                set(gca,'UserData',uData);
            end
            
            return
        end
        
        function menuCIELUV_Callback(app, hObject, eventdata, handles)
            % Analyze | Color and luminance | ROI | CIELUV
            warning('Not yet implemented.')
            return
        end
        
        function menuCIE_Callback(app, hObject, eventdata, handles)
            return
        end
        
        function menuDisProp_Callback(app, hObject, eventdata, handles)
            return
        end
        
        function menuDisplayDPI_Callback(app, hObject, eventdata, handles)
            % Display | Adjust | DPI
            vci = ieGetObject('vci');
            
            val = ipGet(vci,'display DPI');
            val = ieReadNumber('Dots per inch (dpi)',val,'%.2f');
            vci = ipSet(vci,'display DPI',val);
            
            vcReplaceObject(vci);
            return
        end
        
        function menuEditIPDefault_Callback(app, hObject, eventdata, handles)
            [val,vci] = vcGetSelectedObject('vcimage');
            vci.renderingMethod = 'imageval';
            vcReplaceObject(vci,val);
            return
        end
        
        function menuIm_Callback(app, hObject, eventdata, handles)
            return
        end
        
        function menuImportFile_Callback(app, hObject, eventdata, handles)
            warning('Import display from file not yet implemented.');
            return
        end
        
        function menuPattern_Callback(app, hObject, eventdata, handles)
            return
        end
        
        function menuSetLum_Callback(app, hObject, eventdata, handles)
            
            [val,vci] = vcGetSelectedObject('vcimage');
            vci = displaySetMaxLuminance(vci);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end
        
        function menuWhitePoint_Callback(app, hObject, eventdata, handles)
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            vci = displaySetWhitePoint(vci,'XYZ');
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject, eventdata, handles);
            return
        end
        
        function popCustomPath_Callback(app, hObject, eventdata, handles)
            %
            % Managing the custom render popup.  It is initialized with
            % ipComputer, Add Custom, Delete Custom, -----
            % We add and delete routines from the vcSESSION.CUSTOM.procMethod list.
            
            contents = get(handles.popCustomPath,'String');
            method = contents{get(handles.popCustomPath,'Value')};
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            vci = ipSet(vci,'render method',method);
            
            vci = ipSet(vci,'consistency',0);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end
        
        function popCustomPath_CreateFcn(app, hObject, eventdata, handles)
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
            return
        end
        
        function rbCustomPath_Callback(app, hObject, eventdata, handles)
            % Hint: get(hObject,'Value') returns toggle state of rbCustomPath
            ipRefresh(app, hObject,eventdata,handles);
            return
        end
        
        function vcImageDelete(app, hObject, eventdata, handles)
            % Edit | Delte Current Image
            
            vcDeleteSelectedObject('VCIMAGE');
            
            % What condition does this wolve?
            [val,vcImage] = vcGetSelectedObject('VCIMAGE');
            if isempty(val)
                vcImage = ipCreate('default');
                vcReplaceAndSelectObject(vcImage,1);
            end
            ipRefresh(app, hObject, eventdata, handles);
            return
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.CloseRequestFcn = 'vcimageClose';
            app.figure1.KeyPressFcn = 'vcimageKeyPress';
            app.figure1.WindowKeyPressFcn = 'ipKeyPress';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ipWindow_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % Permits calling as ipWindow(ip);
            if ~isempty(varargin) && ...
                    isstruct(varargin{1}) && ...
                    strcmp(varargin{1}.type,'vcimage')
                ieAddObject(varargin{1});
            end
            
            ipOpen(hObject,eventdata,handles);
            ipRefresh(app, hObject, eventdata, handles);
            
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if ~isempty(wPos{5}), set(hObject,'Position',wPos{5}); end
            end
            
            return
        end

        % Button pushed function: btnCompute
        function btnCompute_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Compute button
            
            [val,vci] = vcGetSelectedObject('vcimage');
            [~,sensor]  = vcGetSelectedObject('sensor');
            
            % We require the sensor data
            if isempty(sensorGet(sensor,'dvorvolts'))
                ieInWindowMessage('No sensor voltage data.',handles);
                return;
            else ieInWindowMessage('',handles);
            end
            
            vci = ipCompute(vci,sensor);
            vci = ipSet(vci,'consistency',1);
            vcReplaceObject(vci,val);
            
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Show next ip data (button next to popSelect)
            s  = ieSessionGet('selected','ip');
            nS = ieSessionGet('nobjects','ip');
            s = min(s + 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ip',s);
            ipRefresh(app, hObject, eventdata, handles);
            return
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Show previous ip data (button next to popSelect)
            s  = ieSessionGet('selected','ip');
            nS = ieSessionGet('nobjects','ip');
            s = min(s - 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ip',s);
            ipRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: editGamma
        function editGamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % The gamma edit box is read by the refresh routine during the re-display
            % of the image data.  The display gamma value is not stored in any data
            % objects.
            gam = str2num(get(handles.editGamma,'String'));
            
            [val,vci] = vcGetSelectedObject('ip');
            vci = ipSet(vci,'render Gamma',gam);
            vcReplaceObject(vci,val);
            
            ipRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuAnChrom
        function menuAnChrom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color and luminance | ROI | Chromaticity (xy)
            ip = ieGetObject('vcimage');
            plotDisplayColor(ip,'chromaticity');
            return
        end

        % Menu selected function: menuAnColLumNoise
        function menuAnColLumNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color and Luminance | MCC Luminance Noise
            macbethLuminanceNoise;
            return
        end

        % Menu selected function: menuAnColMCCEst
        function menuAnColMCCEst_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color | MCC Color Metrics (custom display)
            macbethColorError(ieGetObject('VCIMAGE'),[],[],'custom');
            return
        end

        % Menu selected function: menuAnColMCCsRGB
        function menuAnColMCCsRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color | MCC Color Metrics (sRGB)
            macbethColorError(ieGetObject('VCIMAGE'),[],[],'sRGB');
            return
        end

        % Menu selected function: menuAnColVisualComp
        function menuAnColVisualComp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color and Luminance | Visual Compare (D65)
            ip = ieGetObject('ip');
            macbethCompareIdeal(ip);
            return
        end

        % Menu selected function: menuAnColorLAB
        function menuAnColorLAB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ip = ieGetObject('VCIMAGE');
            plotDisplayColor(ip,'cielab');
            return
        end

        % Menu selected function: menuAnColor
        function menuAnColor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuAnComputeFromScene
        function menuAnComputeFromScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % Recompute the OI, ISA and VCIMAGE
            scene = ieGetObject('scene');
            if isempty(scene)
                warndlg('Creating default scene');
                scene = sceneCreate; val = 1;
                vcReplaceAndSelectObject(scene,val);
            end
            
            [val,oi] = vcGetSelectedObject('oi');
            if isempty(oi),
                warndlg('Creating default OI');
                oi = oiCreate; val = 1;
            end
            oi = oiCompute(scene,oi);
            vcReplaceAndSelectObject(oi,val);
            
            [val,isa] = vcGetSelectedObject('isa');
            if isempty(isa)
                warndlg('Creating default sensor');
                isa = sensorCreate; val = 1;
            end
            isa = sensorCompute(isa,oi);
            vcReplaceAndSelectObject(isa,val);
            
            btnCompute_Callback(hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuAnComputeFromSensor
        function menuAnComputeFromSensor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            btnCompute_Callback(hObject, eventdata, handles)
            return
        end

        % Menu selected function: menuAnLUV
        function menuAnLUV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ip = ieGetObject('VCIMAGE');
            plotDisplayColor(ip,'CIELUV');
            return
        end

        % Menu selected function: menuAnLineH
        function menuAnLineH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line intensities | Horizontal
            ip = ieGetObject('ip');
            plotDisplayLine(ip,'h');
            return
        end

        % Menu selected function: menuAnLineV
        function menuAnLineV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line intensities | Vertical
            ip = ieGetObject('ip');   % Image proessor, same as vci
            plotDisplayLine(ip,'v');
            return
        end

        % Menu selected function: menuAnLine
        function menuAnLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line intensities
            return
        end

        % Menu selected function: menuAnLum
        function menuAnLum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ip = ieGetObject('vcimage');
            plotDisplayColor(ip,'luminance');
            return
        end

        % Menu selected function: menuAnROI
        function menuAnROI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuAnROIvSNR
        function menuAnROIvSNR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color and Luminance | vSNR (0.5 m)
            
            vci = ieGetObject('vci');
            dpi = ipGet(vci,'display DPI');
            dist = ipGet(vci,'display Viewing Distance');
            
            vSNR = vcimageVSNR(vci,dpi,dist);
            fprintf('\n***vSNR***\n');
            fprintf('Assumed dpi: %.1f and viewing distance %.2f (m)\n',dpi,dist);
            fprintf('Uniform patch Visual SNR (vSNR): %f\n',vSNR)
            
            return
        end

        % Menu selected function: menuAnalyzeCreateSB
        function menuAnalyzeCreateSB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Create slanted bar
            
            % The new version of ISO MTF relies on camera structure.  So we create one.
            if exist('cameraCreate','file')
                camera = cameraCreate;
                sensor = ieGetObject('sensor');
                if isempty(sensor)
                    h = warndlg('No sensor defined.  Using default');
                    sensor = sensorCreate;
                end
                camera = cameraSet(camera,'sensor',sensor);
            else
                camera = [];
            end
            
            vci = vcimageISOMTF(camera);
            
            ieAddObject(vci);
            ipRefresh(app, hObject,eventdata,handles);
            
            % If we opened the warning dialog, close it.
            if exist('h','var'), close(h); end
            
            return
        end

        % Menu selected function: menuAnalyzeISO12233
        function menuAnalyzeISO12233_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | ISO 12233 (Slanted Bar)
            % Read a slanted bar in the display window and plot the ISO 12233 graph
            %
            vci = ieGetObject('VCIMAGE');
            
            % UPDATE THIS TO USE THE NEW iso functions
            figure(handles.figure1);
            
            ieInWindowMessage('Choose rectangular ROI (short sides must intersect edge)',handles);
            
            % Have the user select the edge.  Need more comments to the user in the
            % window.
            [roiLocs,rect] = vcROISelect(vci);
            
            % rect is [col,row,colWidth,rowHeight]
            
            % Extract the data
            barImage = vcGetROIData(vci,roiLocs,'results');
            c = rect(3)+1;
            r = rect(4)+1;
            barImage = reshape(barImage,r,c,3);
            % figure; imagesc(barImage(:,:,1)); axis image; colormap(gray);
            
            sensor = ieGetObject('ISA');
            if ~isempty(sensor)
                pixel = sensorGet(sensor,'pixel');
                dx = pixelGet(pixel,'width','mm');  % Pixel width in mm
            else
                fprintf('Assuming 2 um pixel');
                dx = [];
            end
            
            % Run the ISO 12233 code.  The results are stored in the window.
            [results, ~, ~, fig] = ISO12233(barImage,dx);
            results.rect = rect;
            set(fig,'userdata',results);
            
            ieInWindowMessage('',handles);
            
            return
        end

        % Menu selected function: menuAnalyze
        function menuAnalyze_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuComputeFromOI
        function menuComputeFromOI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % Don't recompute the OI.  Just Recompute the ISA and the vcimage.
            
            [val,oi] = vcGetSelectedObject('oi');
            if isempty(oi), errordlg('No optical image'); end
            
            [val,isa] = vcGetSelectedObject('isa');
            if isempty(isa)
                warndlg('Creating default sensor');
                isa = sensorCreate; val = 1;
            end
            isa = sensorCompute(isa,oi);
            vcReplaceAndSelectObject(isa,val);
            
            btnCompute_Callback(hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuDisplayViewD
        function menuDisplayViewD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | Adjust | Viewing Distance
            vci = ieGetObject('vci');
            
            val = ipGet(vci,'displayViewingDistance');
            val = ieReadNumber('Viewing distance (m)',val,'%0.2f');
            vci = ipSet(vci,'displayViewingDistance',val);
            
            vcReplaceObject(vci);
            return
        end

        % Menu selected function: menuDisplayWindow
        function menuDisplayWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | Display window
            
            % Place the display for this IP in the ISET database
            ip = ieGetObject('ip');
            d  = ipGet(ip,'display');
            ieAddObject(d);
            
            % Place the rendered srgb data into the vcSESSION.
            % This seems like a mistake to me, and we should be attaching it to the
            % display object. I think we should have
            %   d = displaySet(d,'rgb image');
            % Check with HJ why he did it this way
            global vcSESSION
            vcSESSION.imgData = imageShowImage(ip,[],[],0);
            
            % Bring up the display window.
            displayWindow;
            
            return
        end

        % Menu selected function: menuDisplay
        function menuDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuEditClearMessage
        function menuEditClearMessage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ieInWindowMessage('',ieSessionGet('vcimagehandle'),[]);
            return
        end

        % Menu selected function: menuEditCopyImage
        function menuEditCopyImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            warndlg('Not yet implemented');
            return
        end

        % Menu selected function: menuEditCreate
        function menuEditCreate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Create new image
            %
            % Create a new image processor that is a copy of the current one but with
            % the data cleared.
            
            ip = ieGetObject('ip');
            ip = vcimageClearData(ip);
            
            % We use this name for now and over-write when we compute
            ip = ipSet(ip,'name',sprintf('copy %s',ipGet(ip,'name')));
            ieAddObject(ip);
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuEditDeleteSome
        function menuEditDeleteSome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete Some Images
            vcDeleteSomeObjects('VCI');
            ipRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcImageDelete(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Change Font Size
            
            ieFontSizeSet(handles.figure1);
            
            return
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | (Re-) Name image
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            newName = ieReadString('New vcimage name','new-vcimage');
            
            if isempty(newName),  return;
            else                  vci = ipSet(vci,'name',newName);
            end
            
            vcReplaceAndSelectObject(vci,val)
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuEditResetWhite
        function menuEditResetWhite_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Reset White
            %
            % Put the image white point equal to the display white point.  This allows
            % the user to go back to a default position.
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            wp = ipGet(vci,'display whitepoint');
            vci = ipSet(vci,'data white point',wp);
            
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vci = ieGetObject('vcimage');
            result = ipGet(vci,'result');
            ieViewer(result);
            return
        end

        % Menu selected function: menuEditZoom
        function menuEditZoom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            zoom
            return
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcimageClose;
            return
        end

        % Menu selected function: menuFileLoadImage
        function menuFileLoadImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load Image Data
            
            % Maybe we should put up some kind of a warning.
            
            % Start with defaults of current image
            ip = ieGetObject('vcimage');
            
            fullName = vcSelectDataFile('stayput','r');
            if isempty(fullName), return; end
            scene = sceneFromFile(fullName,'rgb');
            srgb = sceneGet(scene,'rgb');
            % vcNewGraphWin; imagesc(srgb)
            
            % By setting result close to lrgb, the image rendered in the display window
            % is about what we see from just visualization the original image.
            % But this might defeat the purpose of reading in the image file.
            % lrgb = srgb2lrgb(srgb);
            
            [p,fname] = fileparts(fullName);
            ip = ipSet(ip,'name',fname);
            ip = ipSet(ip,'input',[]);   % Not sure what this should be.
            ip = ipSet(ip,'result',srgb);
            ip = ipSet(ip,'gamma',2);
            
            ieAddObject(ip);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Menu selected function: menuFileLoad
        function menuFileLoadProcData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load  (.mat)
            
            fullName = vcSelectDataFile([],'r','mat','Processor data');
            if isempty(fullName), return; end
            
            load(fullName,'vci');
            
            ieAddObject(vci)
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ipRefresh(app, hObject,eventdata,handles);
            return
        end

        % Menu selected function: menuFileSaveProcData
        function menuFileSaveProcData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File |  Save (.mat)
            vci = ieGetObject('vcimage');
            
            fullName = vcSelectDataFile([],'w','mat','Processor data');
            if isempty(fullName), return; end
            
            save(fullName,'vci');
            
            return
        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Save Image
            %
            % Write the rgb data in the ip result to a file containing an RGB image.
            % Output is in 8-bit png format.
            
            ip = ieGetObject('ip');
            
            fullName = vcSelectDataFile('stayPut','w');
            if isempty(fullName), return; end
            [p,n,e] = fileparts(fullName);
            if isempty(e), e = '.png'; fullName = fullfile(p,[n, e]);  end % Add ext
            
            ipSaveImage(ip,fullName);
            
            fprintf('Saved image file %s\n',fullName);
            
            return
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuHelpAppNotes
        function menuHelpAppNotes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Documentation (web)
            ieManualViewer('imageval code');
            return
        end

        % Menu selected function: menuHelpISETOnline
        function menuHelpISETOnline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | ISET functions
            ieManualViewer('iset functions');
            return
        end

        % Menu selected function: menuHelpMetricsPG
        function menuHelpMetricsPG_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Metrics functions
            ieManualViewer('metrics functions');
            return
        end

        % Menu selected function: menuHelpProcessorOnline
        function menuHelpProcessorOnline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Processor functions
            ieManualViewer('ip functions');
            return
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuImageWhite
        function menuImageWhite_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Image White Point (ROI)
            %
            %  Allow the user to pick a region of the image that will be used as the
            %  image white point in metrics calculations.
            %  This white point is the logical one to use for deltaE
            %  type calculations, as opposed to the white point of the monitor.
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            roiLocs = vcROISelect(vci);
            dataXYZ = imageDataXYZ(vci,roiLocs);
            vci.data.wp = mean(dataXYZ);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuMetricsWindow
        function menuMetricsWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            metricsWindow;
            return
        end

        % Menu selected function: menuPlImTrueSize
        function menuPlImTrueSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | True size image
            
            gam = str2double(get(handles.editGamma,'String'));
            ip = ieGetObject('ip');
            ip = ipSet(ip,'gamma',gam);
            
            imageShowImage(ip,gam,true,vcNewGraphWin);
            
            return
        end

        % Menu selected function: menuPlotDisplay
        function menuPlotDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuPlot
        function menuPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuRGBHist
        function menuRGBHist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color and luminance | ROI | RGB Histogram
            ip = ieGetObject('ip');
            plotDisplayColor(ip,'RGB');
            return
        end

        % Menu selected function: menuReadSPD
        function menuReadSPD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | Load
            %
            % Load a new display structure that will be attached to the ip.
            %
            
            % Chooses a new default display
            [val,ip] = vcGetSelectedObject('ip');
            dName = vcSelectDataFile('displays');
            if isempty(dName), disp('User canceled'); return; end
            
            d   = displayCreate(dName);
            ip = ipSet(ip,'display',d);
            
            ip = vcimageClearData(ip);
            vcReplaceObject(ip,val);
            ipRefresh(app, hObject,eventdata,handles)
            
            return
        end

        % Menu selected function: menuScaleChooseMax
        function menuScaleChooseMax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %  Edit | Scale data (ROI)
            %
            %  Let the user choose a region of the image (ROI).  Find the mean of this region.
            %  Scale the data values in vci.data.results so that the largest of the
            %  [R,G,B] values in the ROI is set to 1 (i.e. max display intensity).
            %
            %  The data may be clipped by this procedure; hence, this function cannot
            %  be inverted.
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            % Get the data from an ROI
            d = vcGetROIData(vci,vcROISelect(vci),'result');
            mn = mean(d(:));
            
            img = ipGet(vci,'result')/mn;
            img = ieClip(img,0,1);
            vci = ipSet(vci,'result',img);
            
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuScaleDisplay
        function menuScaleDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Scale data (max)
            %
            %  Scale the data in vci.data.result setting the largest value in the image
            %  to the maximum display range.  The display values are almost always in
            %  the [0,1] range.
            %
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            % Scale the displayed data so the max value is the max of the display.
            vci = ipSet(vci,'result',ipGet(vci,'result scaled to max'));
            
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: multipleImageRGB
        function multipleImageRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Multiple image (RGB)
            imageMultiview('vcimage');
            return
        end

        % Menu selected function: newGraphWin
        function newGraphWin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcNewGraphWin;
            return
        end

        % Menu selected function: plotColorProcessingMatrices
        function plotColorProcessingMatrices_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Color Processing matrices
            
            vci = ieGetObject('vci');
            T = ipGet(vci,'each Transform');
            
            fprintf('\n\n--------------------------------------------\n');
            fprintf('Sensor -> Internal color space\n'),  disp(T{1})
            fprintf('Illuminant correction\n'),  disp(T{2})
            fprintf('Internal color space -> Display primaries\n'), disp(T{3})
            
            % Now the product of the first three
            fprintf('Color matrices combined\n'),
            T{4} = ipGet(vci,'prodT');
            disp(T{4})
            fprintf('--------------------------------------------\n');
            
            return
        end

        % Menu selected function: plotDisplaySPD
        function plotDisplaySPD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vci = ieGetObject('VCIMAGE');
            plotDisplaySPD(vci);
            return
        end

        % Menu selected function: plotGamut
        function plotGamut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ip = ieGetObject('vci');
            displayPlot(ipGet(ip,'display'),'gamut');
            return
        end

        % Menu selected function: plotMCCOverOff
        function plotMCCOverOff_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | MCC Overlay Off
            %
            vci = ieGetObject('vcimage');
            vci = macbethDrawRects(vci,'off');
            vcReplaceObject(vci);
            ipRefresh(app, hObject,eventdata,handles);
            return
        end

        % Value changed function: popBalance
        function popBalance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            contents = get(handles.popBalance,'String');
            method = contents{get(handles.popBalance,'Value')};
            vci = ipSet(vci,'illuminant correction method',method);
            
            vci = ipSet(vci,'consistency',0);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Value changed function: popColorConversionM
        function popColorConversionM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            contents = get(handles.popColorConversionM,'String');
            method   = contents{get(handles.popColorConversionM,'Value')};
            vci      = ipSet(vci,'sensor conversion method',method);
            
            vci = ipSet(vci,'consistency',0);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Value changed function: popColorSpace
        function popColorSpace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % Set internal color space for image processing
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            contents = get(hObject,'String');
            vci = ipSet(vci,'internalcs',contents{get(hObject,'Value')});
            % ics = ipGet(vci,'internalcs');
            
            vci = ipSet(vci,'consistency',0);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Value changed function: popDemosaic
        function popDemosaic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,vci] = vcGetSelectedObject('VCIMAGE');
            
            contents = get(handles.popDemosaic,'String');
            method = contents{get(handles.popDemosaic,'Value')};
            
            if strncmp(method,'----',4)
                % If the Custom List is selected by mistake, then we set the answer to
                % Bilinear, at the top.
                vci = ipSet(vci,'demosaic method','Bilinear');
            else
                % We do some checking here for permissible combinations.  For example,
                % we don't have a Laplacian implemented for CMY.  So, we test for that
                % condition and disallow it here.
                isa = ieGetObject('isa');
                cOrder = sensorGet(isa,'filter Color Letters');
                filters = sort(unique(cOrder(:)))';
                switch(filters)
                    case {'bgr'}
                        vci = ipSet(vci,'demosaicmethod',method);
                        ieInWindowMessage('',handles)
            
                    case {'cmy'}
                        switch (lower(method))
                            case {'bilinear','nearest neighbor'}
                                vci = ipSet(vci,'demosaic method',method);
                                ieInWindowMessage('',handles);
                            otherwise
                                ieInWindowMessage('CMY: only bilinear and nearest neighbor available.',handles)
                                vci = ipSet(vci,'demosaic method','Bilinear');
                        end
                    otherwise
                        ieInWindowMessage('Not or (CMY/RGB) sensor: only bilinear demosaicing available.',handles)
                        vci = ipSet(vci,'demosaic method','Bilinear');
                end
            end
            
            vci = ipSet(vci,'consistency',0);
            vcReplaceObject(vci,val);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Value changed function: popSelect
        function popSelect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            switch get(hObject,'Value')
                case 1
                    % The first entry is always New.  When New is selected, we
                    % create a new VCIMAGE structure.  That structure is a copy of the current
                    % VCIMAGE structure with a new name.  Then we adjust the parameter
                    % settings and compute a new VCIMAGE image.
                    menuEditCreate_Callback(hObject, eventdata, handles);
            
                otherwise,
                    % Select one of the existing VCIMAGE objects.
                    val = get(hObject,'Value') - 1;
                    vcSetSelectedObject('ip',val);
            end
            
            ipRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: popTransform
        function popTransform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % popTransform - Transform processing popup
            %
            % Chooses between use current, enter new transform, or use adaptive methods
            % Perhaps there should be a 'None' option, which just copies the sensor
            % data to the result data.
            %
            
            % Read the popup string
            contents = get(hObject,'String');    % Contents of the popup
            s = contents{get(hObject,'Value')};  % returns selected item from
            
            % Store it in the transform method slot
            vci = ieGetObject('vcimage');
            vci = ipSet(vci,'transform method',s);
            vcReplaceObject(vci);
            ipRefresh(app, hObject,eventdata,handles);
            
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ISET-Image Processor';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileLoad = uimenu(app.menuFile);
            app.menuFileLoad.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadProcData_Callback, true);
            app.menuFileLoad.Text = 'Load processor data (.mat)';
            app.menuFileLoad.Tag = 'menuFileLoad';
            app.menuFileSaveProcData = uimenu(app.menuFile);
            app.menuFileSaveProcData.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveProcData_Callback, true);
            app.menuFileSaveProcData.Text = 'Save processor data (.mat)';
            app.menuFileSaveProcData.Tag = 'menuFileSaveProcData';
            app.menuFileLoadImage = uimenu(app.menuFile);
            app.menuFileLoadImage.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadImage_Callback, true);
            app.menuFileLoadImage.Separator = 'on';
            app.menuFileLoadImage.Text = 'Load image data (png, jpg, ...)';
            app.menuFileLoadImage.Tag = 'menuFileLoadImage';
            app.menuFileSave = uimenu(app.menuFile);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Separator = 'on';
            app.menuFileSave.Text = 'Save image';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Separator = 'on';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditName = uimenu(app.menuEdit);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = '(Re-) Name image';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditCreate = uimenu(app.menuEdit);
            app.menuEditCreate.MenuSelectedFcn = createCallbackFcn(app, @menuEditCreate_Callback, true);
            app.menuEditCreate.Separator = 'on';
            app.menuEditCreate.Text = 'Create new image';
            app.menuEditCreate.Tag = 'menuEditCreate';
            app.menuEditCopyImage = uimenu(app.menuEdit);
            app.menuEditCopyImage.MenuSelectedFcn = createCallbackFcn(app, @menuEditCopyImage_Callback, true);
            app.menuEditCopyImage.Text = 'Copy current image';
            app.menuEditCopyImage.Tag = 'menuEditCopyImage';
            app.menuEditDelete = uimenu(app.menuEdit);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current image';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.menuEditDeleteSome = uimenu(app.menuEdit);
            app.menuEditDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @menuEditDeleteSome_Callback, true);
            app.menuEditDeleteSome.Text = 'Delete some images';
            app.menuEditDeleteSome.Tag = 'menuEditDeleteSome';
            app.menuImageWhite = uimenu(app.menuEdit);
            app.menuImageWhite.MenuSelectedFcn = createCallbackFcn(app, @menuImageWhite_Callback, true);
            app.menuImageWhite.Separator = 'on';
            app.menuImageWhite.Text = 'Image white point (ROI)';
            app.menuImageWhite.Tag = 'menuImageWhite';
            app.menuEditResetWhite = uimenu(app.menuEdit);
            app.menuEditResetWhite.MenuSelectedFcn = createCallbackFcn(app, @menuEditResetWhite_Callback, true);
            app.menuEditResetWhite.Text = 'Set image white to display white';
            app.menuEditResetWhite.Tag = 'menuEditResetWhite';
            app.menuScaleChooseMax = uimenu(app.menuEdit);
            app.menuScaleChooseMax.MenuSelectedFcn = createCallbackFcn(app, @menuScaleChooseMax_Callback, true);
            app.menuScaleChooseMax.Separator = 'on';
            app.menuScaleChooseMax.Text = 'Scale data (ROI)';
            app.menuScaleChooseMax.Tag = 'menuScaleChooseMax';
            app.menuScaleDisplay = uimenu(app.menuEdit);
            app.menuScaleDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuScaleDisplay_Callback, true);
            app.menuScaleDisplay.Text = 'Scale data (max)';
            app.menuScaleDisplay.Tag = 'menuScaleDisplay';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditClearMessage = uimenu(app.menuEdit);
            app.menuEditClearMessage.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearMessage_Callback, true);
            app.menuEditClearMessage.Text = 'Clear window message';
            app.menuEditClearMessage.Tag = 'menuEditClearMessage';
            app.menuEditZoom = uimenu(app.menuEdit);
            app.menuEditZoom.MenuSelectedFcn = createCallbackFcn(app, @menuEditZoom_Callback, true);
            app.menuEditZoom.Separator = 'on';
            app.menuEditZoom.Text = 'Zoom';
            app.menuEditZoom.Tag = 'menuEditZoom';
            app.menuEditViewer = uimenu(app.menuEdit);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.menuPlot = uimenu(app.figure1);
            app.menuPlot.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_Callback, true);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlImTrueSize = uimenu(app.menuPlot);
            app.menuPlImTrueSize.MenuSelectedFcn = createCallbackFcn(app, @menuPlImTrueSize_Callback, true);
            app.menuPlImTrueSize.Text = 'True size image';
            app.menuPlImTrueSize.Tag = 'menuPlImTrueSize';
            app.multipleImageRGB = uimenu(app.menuPlot);
            app.multipleImageRGB.MenuSelectedFcn = createCallbackFcn(app, @multipleImageRGB_Callback, true);
            app.multipleImageRGB.Text = 'Multiple image (RGB)';
            app.multipleImageRGB.Tag = 'multipleImageRGB';
            app.menuPlotDisplay = uimenu(app.menuPlot);
            app.menuPlotDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDisplay_Callback, true);
            app.menuPlotDisplay.Text = 'Display color';
            app.menuPlotDisplay.Tag = 'menuPlotDisplay';
            app.plotDisplaySPD = uimenu(app.menuPlotDisplay);
            app.plotDisplaySPD.MenuSelectedFcn = createCallbackFcn(app, @plotDisplaySPD_Callback, true);
            app.plotDisplaySPD.Text = 'SPD of primaries';
            app.plotDisplaySPD.Tag = 'plotDisplaySPD';
            app.plotGamut = uimenu(app.menuPlotDisplay);
            app.plotGamut.MenuSelectedFcn = createCallbackFcn(app, @plotGamut_Callback, true);
            app.plotGamut.Text = 'Gamut';
            app.plotGamut.Tag = 'plotGamut';
            app.plotColorProcessingMatrices = uimenu(app.menuPlot);
            app.plotColorProcessingMatrices.MenuSelectedFcn = createCallbackFcn(app, @plotColorProcessingMatrices_Callback, true);
            app.plotColorProcessingMatrices.Separator = 'on';
            app.plotColorProcessingMatrices.Text = 'Color processing matrices';
            app.plotColorProcessingMatrices.Tag = 'plotColorProcessingMatrices';
            app.plotMCCOverOff = uimenu(app.menuPlot);
            app.plotMCCOverOff.MenuSelectedFcn = createCallbackFcn(app, @plotMCCOverOff_Callback, true);
            app.plotMCCOverOff.Text = 'MCC overlay off';
            app.plotMCCOverOff.Tag = 'plotMCCOverOff';
            app.newGraphWin = uimenu(app.menuPlot);
            app.newGraphWin.MenuSelectedFcn = createCallbackFcn(app, @newGraphWin_Callback, true);
            app.newGraphWin.Separator = 'on';
            app.newGraphWin.Text = 'New graph window';
            app.newGraphWin.Tag = 'newGraphWin';
            app.menuDisplay = uimenu(app.figure1);
            app.menuDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuDisplay_Callback, true);
            app.menuDisplay.Text = 'Display';
            app.menuDisplay.Tag = 'menuDisplay';
            app.menuReadSPD = uimenu(app.menuDisplay);
            app.menuReadSPD.MenuSelectedFcn = createCallbackFcn(app, @menuReadSPD_Callback, true);
            app.menuReadSPD.Text = 'Load display';
            app.menuReadSPD.Tag = 'menuReadSPD';
            app.menuDisplayWindow = uimenu(app.menuDisplay);
            app.menuDisplayWindow.MenuSelectedFcn = createCallbackFcn(app, @menuDisplayWindow_Callback, true);
            app.menuDisplayWindow.Text = 'Display window';
            app.menuDisplayWindow.Tag = 'menuDisplayWindow';
            app.menuDisplayViewD = uimenu(app.menuDisplay);
            app.menuDisplayViewD.MenuSelectedFcn = createCallbackFcn(app, @menuDisplayViewD_Callback, true);
            app.menuDisplayViewD.Separator = 'on';
            app.menuDisplayViewD.Text = 'Set viewing distance (m)';
            app.menuDisplayViewD.Tag = 'menuDisplayViewD';
            app.menuAnalyze = uimenu(app.figure1);
            app.menuAnalyze.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyze_Callback, true);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.menuAnColor = uimenu(app.menuAnalyze);
            app.menuAnColor.MenuSelectedFcn = createCallbackFcn(app, @menuAnColor_Callback, true);
            app.menuAnColor.Text = 'Color and luminance';
            app.menuAnColor.Tag = 'menuAnColor';
            app.menuAnROI = uimenu(app.menuAnColor);
            app.menuAnROI.MenuSelectedFcn = createCallbackFcn(app, @menuAnROI_Callback, true);
            app.menuAnROI.Text = 'ROI';
            app.menuAnROI.Tag = 'menuAnROI';
            app.menuAnLum = uimenu(app.menuAnROI);
            app.menuAnLum.MenuSelectedFcn = createCallbackFcn(app, @menuAnLum_Callback, true);
            app.menuAnLum.Text = 'Luminance (CIE)';
            app.menuAnLum.Tag = 'menuAnLum';
            app.menuAnChrom = uimenu(app.menuAnROI);
            app.menuAnChrom.MenuSelectedFcn = createCallbackFcn(app, @menuAnChrom_Callback, true);
            app.menuAnChrom.Text = 'Chromaticity (CIE xy)';
            app.menuAnChrom.Tag = 'menuAnChrom';
            app.menuAnColorLAB = uimenu(app.menuAnROI);
            app.menuAnColorLAB.MenuSelectedFcn = createCallbackFcn(app, @menuAnColorLAB_Callback, true);
            app.menuAnColorLAB.Separator = 'on';
            app.menuAnColorLAB.Text = 'CIELAB';
            app.menuAnColorLAB.Tag = 'menuAnColorLAB';
            app.menuAnLUV = uimenu(app.menuAnROI);
            app.menuAnLUV.MenuSelectedFcn = createCallbackFcn(app, @menuAnLUV_Callback, true);
            app.menuAnLUV.Text = 'CIELUV';
            app.menuAnLUV.Tag = 'menuAnLUV';
            app.menuRGBHist = uimenu(app.menuAnROI);
            app.menuRGBHist.MenuSelectedFcn = createCallbackFcn(app, @menuRGBHist_Callback, true);
            app.menuRGBHist.Text = 'RGB histogram';
            app.menuRGBHist.Tag = 'menuRGBHist';
            app.menuAnROIvSNR = uimenu(app.menuAnColor);
            app.menuAnROIvSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnROIvSNR_Callback, true);
            app.menuAnROIvSNR.Text = 'vSNR';
            app.menuAnROIvSNR.Tag = 'menuAnROIvSNR';
            app.menuAnColLumNoise = uimenu(app.menuAnColor);
            app.menuAnColLumNoise.MenuSelectedFcn = createCallbackFcn(app, @menuAnColLumNoise_Callback, true);
            app.menuAnColLumNoise.Text = 'MCC luminance noise';
            app.menuAnColLumNoise.Tag = 'menuAnColLumNoise';
            app.menuAnColMCCsRGB = uimenu(app.menuAnColor);
            app.menuAnColMCCsRGB.MenuSelectedFcn = createCallbackFcn(app, @menuAnColMCCsRGB_Callback, true);
            app.menuAnColMCCsRGB.Text = 'MCC color metrics (sRGB)';
            app.menuAnColMCCsRGB.Tag = 'menuAnColMCCsRGB';
            app.menuAnColMCCEst = uimenu(app.menuAnColor);
            app.menuAnColMCCEst.MenuSelectedFcn = createCallbackFcn(app, @menuAnColMCCEst_Callback, true);
            app.menuAnColMCCEst.Text = 'MCC color metrics (custom display)';
            app.menuAnColMCCEst.Tag = 'menuAnColMCCEst';
            app.menuAnColVisualComp = uimenu(app.menuAnColor);
            app.menuAnColVisualComp.MenuSelectedFcn = createCallbackFcn(app, @menuAnColVisualComp_Callback, true);
            app.menuAnColVisualComp.Text = 'MCC visual compare (D65)';
            app.menuAnColVisualComp.Tag = 'menuAnColVisualComp';
            app.menuAnLine = uimenu(app.menuAnalyze);
            app.menuAnLine.MenuSelectedFcn = createCallbackFcn(app, @menuAnLine_Callback, true);
            app.menuAnLine.Separator = 'on';
            app.menuAnLine.Text = 'Line intensities';
            app.menuAnLine.Tag = 'menuAnLine';
            app.menuAnLineH = uimenu(app.menuAnLine);
            app.menuAnLineH.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineH_Callback, true);
            app.menuAnLineH.Text = 'Horizontal';
            app.menuAnLineH.Tag = 'menuAnLineH';
            app.menuAnLineV = uimenu(app.menuAnLine);
            app.menuAnLineV.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineV_Callback, true);
            app.menuAnLineV.Text = 'Vertical';
            app.menuAnLineV.Tag = 'menuAnLineV';
            app.menuAnalyzeCreateSB = uimenu(app.menuAnalyze);
            app.menuAnalyzeCreateSB.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeCreateSB_Callback, true);
            app.menuAnalyzeCreateSB.Separator = 'on';
            app.menuAnalyzeCreateSB.Text = 'Create slanted bar';
            app.menuAnalyzeCreateSB.Tag = 'menuAnalyzeCreateSB';
            app.menuAnalyzeISO12233 = uimenu(app.menuAnalyze);
            app.menuAnalyzeISO12233.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeISO12233_Callback, true);
            app.menuAnalyzeISO12233.Text = 'ISO 12233 (slanted bar)';
            app.menuAnalyzeISO12233.Tag = 'menuAnalyzeISO12233';
            app.menuMetricsWindow = uimenu(app.menuAnalyze);
            app.menuMetricsWindow.MenuSelectedFcn = createCallbackFcn(app, @menuMetricsWindow_Callback, true);
            app.menuMetricsWindow.Separator = 'on';
            app.menuMetricsWindow.Text = 'Metrics window';
            app.menuMetricsWindow.Tag = 'menuMetricsWindow';
            app.menuAnComputeFromSensor = uimenu(app.menuAnalyze);
            app.menuAnComputeFromSensor.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromSensor_Callback, true);
            app.menuAnComputeFromSensor.Separator = 'on';
            app.menuAnComputeFromSensor.Text = 'Compute from sensor';
            app.menuAnComputeFromSensor.Tag = 'menuAnComputeFromSensor';
            app.menuComputeFromOI = uimenu(app.menuAnalyze);
            app.menuComputeFromOI.MenuSelectedFcn = createCallbackFcn(app, @menuComputeFromOI_Callback, true);
            app.menuComputeFromOI.Text = 'Compute from OI';
            app.menuComputeFromOI.Tag = 'menuComputeFromOI';
            app.menuAnComputeFromScene = uimenu(app.menuAnalyze);
            app.menuAnComputeFromScene.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromScene_Callback, true);
            app.menuAnComputeFromScene.Text = 'Compute from scene';
            app.menuAnComputeFromScene.Tag = 'menuAnComputeFromScene';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpProcessorOnline = uimenu(app.menuHelp);
            app.menuHelpProcessorOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpProcessorOnline_Callback, true);
            app.menuHelpProcessorOnline.Text = 'Processor functions';
            app.menuHelpProcessorOnline.Tag = 'menuHelpProcessorOnline';
            app.menuHelpMetricsPG = uimenu(app.menuHelp);
            app.menuHelpMetricsPG.MenuSelectedFcn = createCallbackFcn(app, @menuHelpMetricsPG_Callback, true);
            app.menuHelpMetricsPG.Text = 'Metrics functions';
            app.menuHelpMetricsPG.Tag = 'menuHelpMetricsPG';
            app.menuHelpISETOnline = uimenu(app.menuHelp);
            app.menuHelpISETOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpISETOnline_Callback, true);
            app.menuHelpISETOnline.Text = 'ISET functions';
            app.menuHelpISETOnline.Tag = 'menuHelpISETOnline';
            app.menuHelpAppNotes = uimenu(app.menuHelp);
            app.menuHelpAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuHelpAppNotes_Callback, true);
            app.menuHelpAppNotes.Text = 'Documentation (web)';
            app.menuHelpAppNotes.Tag = 'menuHelpAppNotes';
            app.txtDisplay = uilabel(app.figure1);
            app.txtDisplay.Tag = 'txtDisplay';
            app.txtDisplay.Text = 'Parameters';
            app.txtConsistency = uilabel(app.figure1);
            app.txtConsistency.Tag = 'txtConsistency';
            app.txtConsistency.Text = '';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.ProcessedimagePanel = uipanel(app.figure1);
            app.ProcessedimagePanel.Title = 'Processed image';
            app.axes1 = uiaxes(app.ProcessedimagePanel);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = zeros(1,0);
            app.axes1.YTickLabel = '';
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.popSelect = uidropdown(app.ProcessedimagePanel);
            app.popSelect.Items = {'New'};
            app.popSelect.ValueChangedFcn = createCallbackFcn(app, @popSelect_Callback, true);
            app.popSelect.Tag = 'popSelect';
            app.popSelect.Value = 'New';
            app.btnNext = uibutton(app.ProcessedimagePanel, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Icon = 'rightarrow.png';
            app.btnNext.Tooltip = 'Show next sensor';
            app.btnNext.Text = '';
            app.btnPrev = uibutton(app.ProcessedimagePanel, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Icon = 'leftarrow.png';
            app.btnPrev.Tooltip = 'Show previous sensor';
            app.btnPrev.Text = '';
            app.text10 = uilabel(app.ProcessedimagePanel);
            app.text10.Tag = 'text10';
            app.text10.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.text10.Text = 'Gamma';
            app.editGamma = uieditfield(app.ProcessedimagePanel, 'text');
            app.editGamma.ValueChangedFcn = createCallbackFcn(app, @editGamma_Callback, true);
            app.editGamma.Tag = 'editGamma';
            app.editGamma.Tooltip = 'Gamma for display';
            app.editGamma.Value = '1';
            app.PipelinesettingsPanel = uipanel(app.figure1);
            app.PipelinesettingsPanel.Title = 'Pipeline settings';
            app.panelDemosaic = uipanel(app.PipelinesettingsPanel);
            app.panelDemosaic.Tag = 'panelDemosaic';
            app.textDemosaic = uilabel(app.panelDemosaic);
            app.textDemosaic.Tag = 'textDemosaic';
            app.textDemosaic.Tooltip = 'Color filter array demosaic method ';
            app.textDemosaic.Text = 'Demosaic';
            app.popDemosaic = uidropdown(app.panelDemosaic);
            app.popDemosaic.Items = {'Bilinear', 'Laplacian', 'Adaptive Laplacian', 'Nearest Neighbor'};
            app.popDemosaic.ValueChangedFcn = createCallbackFcn(app, @popDemosaic_Callback, true);
            app.popDemosaic.Tag = 'popDemosaic';
            app.popDemosaic.Value = 'Bilinear';
            app.btnCompute = uibutton(app.PipelinesettingsPanel, 'push');
            app.btnCompute.ButtonPushedFcn = createCallbackFcn(app, @btnCompute_Callback, true);
            app.btnCompute.Tag = 'btnCompute';
            app.btnCompute.Text = 'Compute Display  Image';
            app.panelIlluminant = uipanel(app.PipelinesettingsPanel);
            app.panelIlluminant.Tag = 'panelIlluminant';
            app.txtIlluminant = uilabel(app.panelIlluminant);
            app.txtIlluminant.Tag = 'txtIlluminant';
            app.txtIlluminant.Tooltip = 'Illuminant correction method';
            app.txtIlluminant.Text = 'Illuminant';
            app.popBalance = uidropdown(app.panelIlluminant);
            app.popBalance.Items = {'None', 'Gray World', 'White World'};
            app.popBalance.ValueChangedFcn = createCallbackFcn(app, @popBalance_Callback, true);
            app.popBalance.Tag = 'popBalance';
            app.popBalance.Tooltip = 'Color balancing method options';
            app.popBalance.Value = 'None';
            app.panelSensor = uipanel(app.PipelinesettingsPanel);
            app.panelSensor.Tag = 'panelSensor';
            app.txtSensor = uilabel(app.panelSensor);
            app.txtSensor.Tag = 'txtSensor';
            app.txtSensor.Tooltip = 'Sensor correction method';
            app.txtSensor.Text = 'Sensor ';
            app.txtICS = uilabel(app.panelSensor);
            app.txtICS.Tag = 'txtICS';
            app.txtICS.Tooltip = 'Select calibarted color space for color processing';
            app.txtICS.Text = 'Color space';
            app.txtMethod = uilabel(app.panelSensor);
            app.txtMethod.Tag = 'txtMethod';
            app.txtMethod.Tooltip = 'Select method for converting sensor data to a calibrated color space';
            app.txtMethod.Text = 'Method';
            app.popColorConversionM = uidropdown(app.panelSensor);
            app.popColorConversionM.Items = {'MCC Optimized', 'Manual Matrix Entry', 'Current matrix', 'None'};
            app.popColorConversionM.ValueChangedFcn = createCallbackFcn(app, @popColorConversionM_Callback, true);
            app.popColorConversionM.Tag = 'popColorConversionM';
            app.popColorConversionM.Value = 'MCC Optimized';
            app.popColorSpace = uidropdown(app.panelSensor);
            app.popColorSpace.Items = {'Sensor', 'XYZ', 'stockman'};
            app.popColorSpace.ValueChangedFcn = createCallbackFcn(app, @popColorSpace_Callback, true);
            app.popColorSpace.Tag = 'popColorSpace';
            app.popColorSpace.Value = 'Sensor';
            app.panelTransform = uipanel(app.PipelinesettingsPanel);
            app.panelTransform.Tag = 'panelTransform';
            app.txtTransform = uilabel(app.panelTransform);
            app.txtTransform.Tag = 'txtTransform';
            app.txtTransform.Tooltip = 'Color transform is calculated (adaptive) or stored (current)';
            app.txtTransform.Text = 'Transform type';
            app.popTransform = uidropdown(app.panelTransform);
            app.popTransform.Items = {'Current', 'New', 'Adaptive'};
            app.popTransform.ValueChangedFcn = createCallbackFcn(app, @popTransform_Callback, true);
            app.popTransform.Tag = 'popTransform';
            app.popTransform.Tooltip = 'Select transform - current, enter new one, or use adaptive processing';
            app.popTransform.Value = 'Current';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ipWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end