classdef ieCameraDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        menuFile               matlab.ui.container.Menu
        menuLoadSession        matlab.ui.container.Menu
        menuFileRename         matlab.ui.container.Menu
        menuFileFontSize       matlab.ui.container.Menu
        menuFileWaitBar        matlab.ui.container.Menu
        menuFileRefresh        matlab.ui.container.Menu
        menuFileSave           matlab.ui.container.Menu
        menuFileSaveAs         matlab.ui.container.Menu
        menuFileClose          matlab.ui.container.Menu
        menuFileSaveClose      matlab.ui.container.Menu
        menuHelp               matlab.ui.container.Menu
        menuHelpImageval       matlab.ui.container.Menu
        menuHelpDocumentation  matlab.ui.container.Menu
        menuHelpFunctions      matlab.ui.container.Menu
        txtMessage             matlab.ui.control.Label
        uipanel1               matlab.ui.container.ButtonGroup
        btnScene               matlab.ui.control.Button
        btnOI                  matlab.ui.control.Button
        btnSensorImage         matlab.ui.control.Button
        btnProcessor           matlab.ui.control.Button
        GetimagesButton        matlab.ui.control.Button
        btnEvaluate            matlab.ui.control.Button
        btnTweak               matlab.ui.control.Button
        lblOptics              matlab.ui.control.Label
        lblSensor              matlab.ui.control.Label
        lblIP                  matlab.ui.control.Label
        lblTweak               matlab.ui.control.Label
        lblScores              matlab.ui.control.Label
        lblStatus              matlab.ui.control.Label
        btnRefreshSettings     matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        txtISET                matlab.ui.control.Label
        text1                  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        Image_2                matlab.ui.control.Image
    end

    properties (Access = private) % for now at least
        tweakTemplateFileName = fullfile(isetRootPath, "data", "cameraTweakTemplate.m")
        tweakFileName = "";
    end
        
    methods (Access = private)
        function refreshMain(app)
            % Called by routines in this function
            
            % Check on File | Wait bar menu item
            if ieSessionGet('wait bar')
                app.menuFileWaitBar.Checked = 'on';
            else
                app.menuFileWaitBar.Checked = 'off';
            end
            
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.CloseRequestFcn = 'ieMainClose';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ieMainW_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); 
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
                        
            % Update handles structure
            guidata(hObject, handles);
            
            ieSessionSet('mainwindow',hObject,eventdata,handles);
            
            % If the person has set a position and size preference, put the window
            % there
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if ~isempty(wPos{1}), set(hObject,'Position',wPos{1}); end
            end
            refreshMain(app);
            
            % djc -- This is the code from the de-rated OutputFCN
            %        don't know if we still need it?
            % varargout  cell array for returning output args (see VARARGOUT);
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Get default command line output from handles structure
            % OpenFcn doesn't have an output!
            %varargout{1} = handles.output;

            
            return
        end

        % Button pushed function: btnOI
        function btnOI_Callback(app, event)
            
            % Optics button
            oiWindow;

        end

        % Button pushed function: btnProcessor
        function btnProcessor_Callback(app, event)
            
            % Processor button
            uialert("Don't have a working ip window, I think");
            %ipWindow;

        end

        % Button pushed function: btnScene
        function btnScene_Callback(app, event)
            
            % Scene button
            sceneWindow;

        end

        % Button pushed function: btnSensorImage
        function btnSensorImage_Callback(app, event)
            
            uialert("Don't have a working sensor window, I think");
            % Sensor button
            %sensorImageWindow;

        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            
            % File | Close
            ieMainClose;

        end

        % Menu selected function: menuFileFontSize
        function menuFileFontSize_Callback(app, event)
            
            % File | Font Size
            % Change font size but to what?
            ieFontSizeSet(app.figure1);
            
            % Keep the big ISET big despite changing the other text
            app.text1.FontSize = 32;
            app.txtISET.FontSize = 18;
            
            refreshMain(app);
            return
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            
            % File | Refresh
            refreshMain(app);

        end

        % Menu selected function: menuFileRename
        function menuFileRename_Callback(app, event)
            
            % File | Rename session            
            sessionName = ieReadString('Select session name');
            if isempty(sessionName), return; end
            
            [~,fname,e] = fileparts(sessionName);
            if ~strcmp(e,'.mat'), e = '.mat'; fname = [fname,e]; end
            
            ieSessionSet('sessionname',fname);
            refreshMain(app);

        end

        % Menu selected function: menuFileSaveAs
        function menuFileSaveAs_Callback(app, event)

            % File | Save As
            fullName = vcSelectDataFile('session','w','mat');
            [sessionDir,fname] = fileparts(fullName);
            ieSessionSet('sessionname',fname);
            ieSessionSet('sessiondir',sessionDir);
            vcSaveSESSION(fname);
            refreshMain(app);

        end

        % Menu selected function: menuFileSaveClose
        function menuFileSaveClose_Callback(app, event)

            % File | Close and Save
            vcSaveSESSION;
            ieMainClose;

        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            
            % File | Save
            vcSaveSESSION;

        end

        % Menu selected function: menuFileWaitBar
        function menuFileWaitBar_Callback(app, event)
            
            % File | Waitbar
            % Toggle state of waitbar
            
            % h = guihandles;
            b = ieSessionGet('wait bar');
            if b
                ieSessionSet('wait bar',false);
            %     set(h.menuFileWaitBar,'Checked','off');
            else
                ieSessionSet('wait bar',true);
            %     set(h.menuFileWaitBar,'Checked','on');
            end
            refreshMain(app);

        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            
        end

        % Menu selected function: menuHelpDocumentation
        function menuHelpDocumentation_Callback(app, event)
            
            % Help | Documentation (web)
            ieManualViewer('imageval code');

        end

        % Menu selected function: menuHelpFunctions
        function menuHelpFunctions_Callback(app, event)
            
            % Help | Documentation (web)
            ieManualViewer('iset functions');

        end

        % Menu selected function: menuHelpImageval
        function menuHelpImageval_Callback(app, event)
            
            % Help | ImagEval (home)
            ieManualViewer('imageval');

        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            
        end

        % Callback function
        function menuInitKeyGet_Callback(app, event)
            
            % Initialize | Get Key
            ieKeyGet;

        end

        % Callback function
        function menuInitKeyStore_Callback(app, event)
            
            % Initialize | Store Key
            ieKeyStore;

        end

        % Callback function
        function menuInitLicenseStore_Callback(app, event)
            
            % Initialize | Store license
            ieLicenseStore;

        end

        % Callback function
        function menuInitMicrosoftLib_Callback(app, event)
            
            % Init | Verify mex
            ieInstall

        end

        % Callback function
        function menuInitVerifyMex_Callback(app, event)
            
            % Init | Verify mex
            ieInstall

        end

        % Callback function
        function menuInitVerify_Callback(app, event)
            
            % Initialize | Key Verify
            val = ieKeyVerify;
            if strcmp(val{1},md5([num2str(date),'1951']))
                ieInWindowMessage('License/Key Verified',app,5);
            else
                ieInWindowMessage('License/Key Not Verified',app,[]);
            end

        end

        % Menu selected function: menuLoadSession
        function menuLoadSession_Callback(app, event)
            
            % File | Load session
            
            sessionFileName = vcSelectDataFile('session','r','mat');
            cmd = ['load ',sessionFileName]; eval(cmd);
            disp(iePrintSessionInfo);
            refreshMain(app);
            return
        end

        % Button pushed function: GetimagesButton
        function GetimagesButtonPushed(app, event)
            webImageBrowser();
        end

        % Button pushed function: btnEvaluate
        function btnEvaluateButtonPushed(app, event)
            % now we can try to test our camera on some images
            oi = ieGetObject('oi');
            if ~isequal(class('oi'), 'struct'), oi = oiCreate(); end
            
            sensor = ieGetObject('sensor');
            if ~isequal(class('sensor'), 'struct'), sensor = sensorCreate(); end

            ip = ieGetObject('ip');
            if ~isequal(class('ip'), 'struct'), ip = ipCreate(); end
            
            if isfile(app.tweakFileName)
                % we want to allow the user to run their own custom code
                
            end
            scoreStats = expClassify('oi', oi, 'sensor', sensor, 'ip', ip);
            uialert(app.figure1,sprintf("Your camera scored %d out of a possible %d on %d images", scoreStats(1), scoreStats(2), scoreStats(3)),"Scoring your camera design", "Icon","info");
        end

        % Button pushed function: btnTweak
        function btnTweakButtonPushed(app, event)
            % eventually it might be fun to let the user choose their own file
            % but for now let's just pre-populate one with a template!
            % app.tweakFileName = uigetfile("*.m", "Select the Matlab script you've set up to customize the parameters of your camera", "cameratweak.m");
            if ~isfolder(fullfile(isetRootPath, "local", "data", "custom"))
                mkdir(fullfile(isetRootPath, "local", "data", "custom"));
            end
            if ~isfile(fullfile(isetRootPath, "local", "data", "custom", "tweaks.m"))
                copyfile(app.tweakTemplateFileName, fullfile(isetRootPath, "local", "data", "custom", "tweaks.m"));
            end
            edit fullfile(isetRootPath, "local", "data", "custom", "tweaks.m");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ISET';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuLoadSession = uimenu(app.menuFile);
            app.menuLoadSession.MenuSelectedFcn = createCallbackFcn(app, @menuLoadSession_Callback, true);
            app.menuLoadSession.Enable = 'off';
            app.menuLoadSession.Text = 'Load session';
            app.menuLoadSession.Tag = 'menuLoadSession';
            app.menuFileRename = uimenu(app.menuFile);
            app.menuFileRename.MenuSelectedFcn = createCallbackFcn(app, @menuFileRename_Callback, true);
            app.menuFileRename.Enable = 'off';
            app.menuFileRename.Text = 'Rename session';
            app.menuFileRename.Tag = 'menuFileRename';
            app.menuFileFontSize = uimenu(app.menuFile);
            app.menuFileFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuFileFontSize_Callback, true);
            app.menuFileFontSize.Separator = 'on';
            app.menuFileFontSize.Text = 'Font Size';
            app.menuFileFontSize.Tag = 'menuFileFontSize';
            app.menuFileWaitBar = uimenu(app.menuFile);
            app.menuFileWaitBar.MenuSelectedFcn = createCallbackFcn(app, @menuFileWaitBar_Callback, true);
            app.menuFileWaitBar.Text = 'Waitbar';
            app.menuFileWaitBar.Tag = 'menuFileWaitBar';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Enable = 'off';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileSave = uimenu(app.menuFile);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Enable = 'off';
            app.menuFileSave.Separator = 'on';
            app.menuFileSave.Text = 'Save';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuFileSaveAs = uimenu(app.menuFile);
            app.menuFileSaveAs.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveAs_Callback, true);
            app.menuFileSaveAs.Enable = 'off';
            app.menuFileSaveAs.Text = 'Save as';
            app.menuFileSaveAs.Tag = 'menuFileSaveAs';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuFileSaveClose = uimenu(app.menuFile);
            app.menuFileSaveClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveClose_Callback, true);
            app.menuFileSaveClose.Enable = 'off';
            app.menuFileSaveClose.Text = 'Save and close';
            app.menuFileSaveClose.Tag = 'menuFileSaveClose';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpImageval = uimenu(app.menuHelp);
            app.menuHelpImageval.MenuSelectedFcn = createCallbackFcn(app, @menuHelpImageval_Callback, true);
            app.menuHelpImageval.Text = 'ImagEval home';
            app.menuHelpImageval.Tag = 'menuHelpImageval';
            app.menuHelpDocumentation = uimenu(app.menuHelp);
            app.menuHelpDocumentation.MenuSelectedFcn = createCallbackFcn(app, @menuHelpDocumentation_Callback, true);
            app.menuHelpDocumentation.Text = 'Documentation (web)';
            app.menuHelpDocumentation.Tag = 'menuHelpDocumentation';
            app.menuHelpFunctions = uimenu(app.menuHelp);
            app.menuHelpFunctions.MenuSelectedFcn = createCallbackFcn(app, @menuHelpFunctions_Callback, true);
            app.menuHelpFunctions.Text = 'Functions (web)';
            app.menuHelpFunctions.Tag = 'menuHelpFunctions';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.btnScene = uibutton(app.uipanel1, 'push');
            app.btnScene.ButtonPushedFcn = createCallbackFcn(app, @btnScene_Callback, true);
            app.btnScene.Tag = 'btnScene';
            app.btnScene.Text = 'See as Scenes...';
            app.btnOI = uibutton(app.uipanel1, 'push');
            app.btnOI.ButtonPushedFcn = createCallbackFcn(app, @btnOI_Callback, true);
            app.btnOI.Tag = 'btnOI';
            app.btnOI.Text = 'Choose Optics...';
            app.btnSensorImage = uibutton(app.uipanel1, 'push');
            app.btnSensorImage.ButtonPushedFcn = createCallbackFcn(app, @btnSensorImage_Callback, true);
            app.btnSensorImage.Tag = 'btnSensorImage';
            app.btnSensorImage.Text = 'Design Sensor...';
            app.btnProcessor = uibutton(app.uipanel1, 'push');
            app.btnProcessor.ButtonPushedFcn = createCallbackFcn(app, @btnProcessor_Callback, true);
            app.btnProcessor.Tag = 'btnProcessor';
            app.btnProcessor.Text = 'Design IP...';
            app.GetimagesButton = uibutton(app.uipanel1, 'push');
            app.GetimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GetimagesButtonPushed, true);
            app.GetimagesButton.Text = 'Get images...';
            app.btnEvaluate = uibutton(app.uipanel1, 'push');
            app.btnEvaluate.ButtonPushedFcn = createCallbackFcn(app, @btnEvaluateButtonPushed, true);
            app.btnEvaluate.Tag = 'btnProcessor';
            app.btnEvaluate.Text = 'Evaluate...';
            app.btnTweak = uibutton(app.uipanel1, 'push');
            app.btnTweak.ButtonPushedFcn = createCallbackFcn(app, @btnTweakButtonPushed, true);
            app.btnTweak.Tag = 'btnProcessor';
            app.btnTweak.Text = 'Customize...';
            app.lblOptics = uilabel(app.uipanel1);
            app.lblOptics.Text = '(current optics)';
            app.lblSensor = uilabel(app.uipanel1);
            app.lblSensor.Text = '(current sensor)';
            app.lblIP = uilabel(app.uipanel1);
            app.lblIP.Text = {'(current ip)'; ''};
            app.lblTweak = uilabel(app.uipanel1);
            app.lblTweak.Text = '(current tweak file)';
            app.lblScores = uilabel(app.uipanel1);
            app.lblScores.Text = '(recent scores)';
            app.lblStatus = uilabel(app.uipanel1);
            app.lblStatus.Text = 'Current Settings:';
            app.btnRefreshSettings = uibutton(app.uipanel1, 'push');
            app.btnRefreshSettings.Text = 'Refresh settings...';
            app.Panel = uipanel(app.figure1);
            app.txtISET = uilabel(app.Panel);
            app.txtISET.Tag = 'txtISET';
            app.txtISET.Text = 'Image Systems Engineering Toolbox';
            app.text1 = uilabel(app.Panel);
            app.text1.Tag = 'text1';
            app.text1.Text = {'ISET'; 'Camera Designer'};
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'mainIcon.jpg';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = 'Stanford Logo.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ieCameraDesigner(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ieMainW_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end