classdef ieScoreWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        tblScore               matlab.ui.control.Table
        ClasscomparisonsLabel  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        OriginalTab            matlab.ui.container.Tab
        imgOriginal            matlab.ui.control.Image
        ProcessedTab           matlab.ui.container.Tab
        imgProcessed           matlab.ui.control.Image
        lblImageLabel          matlab.ui.control.Label
        mnuFile                matlab.ui.container.Menu
        mnuFileExportScore     matlab.ui.container.Menu
        mnuView                matlab.ui.container.Menu
        CameraPropertiesMenu   matlab.ui.container.Menu
        mnuViewOptics          matlab.ui.container.Menu
        mnuViewSensor          matlab.ui.container.Menu
        mnuViewIP              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mainapp % Calling app window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = sprintf("Scores for your %s test run", mainapp.ourClassifier);
            app.tblScore.ColumnName = {'Original:', 'Processed:', 'Path to Original image:', 'Path to Processed image:'};
            app.tblScore.ColumnWidth = {floor(app.tblScore.Position(3)/2), floor(app.tblScore.Position(3)/2)};
            app.tblScore.RowName = "";

            % hmmm, we'd like to make the table prettier, but neither we
            % nor expClassify have all the information needed...
            % we could either have it return the entire table, or let it
            % tell us what we need to do
            app.tblScore.Data = mainapp.scoreTable;
            
            %try setting based on fixed stringg (ugh!)
            styleBackground = uistyle("BackgroundColor", 'yellow'); % pre-defined are kind of garish
            styleBold = uistyle("FontWeight", 'bold');
            for tblRows = 1:length(app.tblScore.Data)
                if contains(app.tblScore.Data(tblRows, 1), 'Image Name:')
                    addStyle(app.tblScore, styleBackground,'row',tblRows);
                end
                if contains(app.tblScore.Data(tblRows, 2), 'Score:')
                    addStyle(app.tblScore, styleBold,'cell', [tblRows,2]);                    
                end
            end
            app.mainapp = mainapp; % stash the calling window so we can query it!

        end

        % Cell selection callback: tblScore
        function tblScoreCellSelection(app, event)
            indices = event.Indices;
            try % hope we get a click on the right thing:)
                if indices(2) == 1 || indices(2) == 2 % right column
                    origFName = app.mainapp.scoreTable(indices(1), 3);
                    processedFName = app.mainapp.scoreTable(indices(1), 4);
                    if isfile(origFName)
                        % set image file!
                        app.imgOriginal.ImageSource = origFName;
                        [~, previewFName, ~] = fileparts(origFName);
                        app.lblImageLabel.Text = sprintf("Image: %s", previewFName); 
                    end
                    if isfile(processedFName)
                        app.imgProcessed.ImageSource = processedFName;
                    end
                end
            catch
                uialert(app.UIFigure, "Click on one of the filenames to see the image.","Click to select");
            end

        end

        % Menu selected function: mnuViewOptics
        function mnuViewOpticsMenuSelected(app, event)
            if isempty(app.mainapp.oi)
                uialert(app.UIFigure,"No specified optics found. Sorry.","No optics");
            else
                iePTable(app.mainapp.oi, 'format', 'window');
            end
        end

        % Menu selected function: mnuViewSensor
        function mnuViewSensorMenuSelected(app, event)
            if isempty(app.mainapp.sensor)
                uialert(app.UIFigure,"No specified sensor found. Sorry.","No sensor");
            else
                iePTable(app.mainapp.sensor, 'format', 'window');
            end

        end

        % Menu selected function: mnuViewIP
        function mnuViewIPMenuSelected(app, event)
            if isempty(app.mainapp.ip)
                uialert(app.UIFigure,"No specified IP found. Sorry.","No IP");
            else
                iePTable(app.mainapp.ip, 'format', 'window');
            end

        end

        % Menu selected function: mnuFileExportScore
        function mnuFileExportScoreMenuSelected(app, event)
            % allow user to save their score data for later/other use
            scoreFolder = fullfile(isetRootPath, "local", "scores");
            if ~isfolder(scoreFolder)
                mkdir(scoreFolder);
            end
            [scoreFileName, scoreFilePath] = uiputfile(fullfile(scoreFolder,"*.csv"),"Choose a filename for your Score data");
            if ~isequal(scoreFileName,0)
               writematrix(app.tblScore.Data, fullfile(scoreFilePath, scoreFileName));
            end

        end

        % Menu selected function: CameraPropertiesMenu
        function CameraPropertiesMenuSelected(app, event)
            ourCamera = cameraCreate('current');
            iePTable(ourCamera, 'format', 'window');
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.tblScore = uitable(app.LeftPanel);
            app.tblScore.ColumnName = {'Original:'; 'Processed:'};
            app.tblScore.RowName = {};
            app.tblScore.CellSelectionCallback = createCallbackFcn(app, @tblScoreCellSelection, true);
            app.tblScore.Tooltip = {'Score information for each tested image. Click on an image''s filename to see a preview on the right.'};
            app.ClasscomparisonsLabel = uilabel(app.LeftPanel);
            app.ClasscomparisonsLabel.Text = 'Class comparisons:';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OriginalTab = uitab(app.TabGroup);
            app.OriginalTab.Title = 'Original';
            app.imgOriginal = uiimage(app.OriginalTab);
            app.imgOriginal.ImageSource = 'Stanford Logo.png';
            app.ProcessedTab = uitab(app.TabGroup);
            app.ProcessedTab.Title = 'Processed';
            app.imgProcessed = uiimage(app.ProcessedTab);
            app.lblImageLabel = uilabel(app.RightPanel);
            app.lblImageLabel.Text = 'Select an image filename from the table on the left to preview it here.';
            app.mnuFile = uimenu(app.UIFigure);
            app.mnuFile.Text = 'File';
            app.mnuFileExportScore = uimenu(app.mnuFile);
            app.mnuFileExportScore.MenuSelectedFcn = createCallbackFcn(app, @mnuFileExportScoreMenuSelected, true);
            app.mnuFileExportScore.Text = 'Export Score...';
            app.mnuView = uimenu(app.UIFigure);
            app.mnuView.Text = 'View';
            app.CameraPropertiesMenu = uimenu(app.mnuView);
            app.CameraPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @CameraPropertiesMenuSelected, true);
            app.CameraPropertiesMenu.Text = 'Camera Properties...';
            app.mnuViewOptics = uimenu(app.mnuView);
            app.mnuViewOptics.MenuSelectedFcn = createCallbackFcn(app, @mnuViewOpticsMenuSelected, true);
            app.mnuViewOptics.Separator = 'on';
            app.mnuViewOptics.Text = 'Optics Properties...';
            app.mnuViewSensor = uimenu(app.mnuView);
            app.mnuViewSensor.MenuSelectedFcn = createCallbackFcn(app, @mnuViewSensorMenuSelected, true);
            app.mnuViewSensor.Text = 'Sensor Properties...';
            app.mnuViewIP = uimenu(app.mnuView);
            app.mnuViewIP.MenuSelectedFcn = createCallbackFcn(app, @mnuViewIPMenuSelected, true);
            app.mnuViewIP.Text = 'IP Properties...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ieScoreWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end