classdef metricsWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        menuFile                   matlab.ui.container.Menu
        menuFileLoadImage          matlab.ui.container.Menu
        menuFileLoadImagePair      matlab.ui.container.Menu
        menuFileLoadImage1         matlab.ui.container.Menu
        menuFileLoadImage2         matlab.ui.container.Menu
        menuFileLoadVCI            matlab.ui.container.Menu
        menuFileSaveImage          matlab.ui.container.Menu
        menuSaveData               matlab.ui.container.Menu
        menuSave                   matlab.ui.container.Menu
        menuFileSaveVCI            matlab.ui.container.Menu
        menuRefresh                matlab.ui.container.Menu
        menuClose                  matlab.ui.container.Menu
        menuEdit                   matlab.ui.container.Menu
        menuEditFontSize           matlab.ui.container.Menu
        menuPlot                   matlab.ui.container.Menu
        menuPlotROIHist            matlab.ui.container.Menu
        menuNewGraphWindow         matlab.ui.container.Menu
        menuAnalyze                matlab.ui.container.Menu
        menuAnalyzeComparePatches  matlab.ui.container.Menu
        menuAnalyzeSummary         matlab.ui.container.Menu
        menuHelp                   matlab.ui.container.Menu
        img1                       matlab.ui.control.UIAxes
        img2                       matlab.ui.control.UIAxes
        imgMetric                  matlab.ui.control.UIAxes
        txt1                       matlab.ui.control.Label
        text2                      matlab.ui.control.Label
        txtInfo                    matlab.ui.control.Label
        editGamma                  matlab.ui.control.EditField
        txtGamma                   matlab.ui.control.Label
        btnCompute                 matlab.ui.control.Button
        txtMetric                  matlab.ui.control.Label
        txtMessage                 matlab.ui.control.Label
        txtMetricValue             matlab.ui.control.Label
        popMetric                  matlab.ui.control.DropDown
        popImageList2              matlab.ui.control.DropDown
        popImageList1              matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.KeyPressFcn = 'metricsKeyPress';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function metricsWindow_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            %
            
            global vcSESSION
            
            vcSetFigureHandles('METRICS',hObject,eventdata,handles);
            
            % Choose default command line output for metricsWindow
            handles.output = hObject;
            
            %  Place to store the results of metrics calculations
            handles.metricImage = [];
            
            % Update handles structure
            guidata(hObject, handles);
            axes(handles.imgMetric); axis image; axis off;
            axes(handles.img1); axis image; axis off;
            axes(handles.img2); axis image; axis off;
            
            ieFontInit(hObject);
            
            % In this case, hObject is the metrics window itself, I think.
            metricsRefresh(handles)
            
            return
        end

        % Button pushed function: btnCompute
        function btnCompute_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Read the names of the selected vc images that will be used in the metric
            % computation
            val = metricsGet(handles,'vcipair');
            
            % Convert from cell array to a string
            metricName = metricsGet(handles,'currentmetric');
            
            % Compute the metric and attach it to the handles
            metricData = metricsCompute(val.vci1,val.vci2,metricName);
            handles = metricsSet(handles,'metricData',metricData);
            
            % Attach the handles to the window
            guidata(hObject, handles);
            
            metricsRefresh(handles)
            
            return
        end

        % Value changed function: editGamma
        function editGamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Gamma is stored in the string of this object.
            metricsRefresh(handles);
            return
        end

        % Menu selected function: menuAnalyze
        function menuAnalyze_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuClose
        function menuClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            metricsClose;
            return
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ieFontSizeSet(handles.figure1);
            return
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            iseHelp('metrics');
            return
        end

        % Menu selected function: menuNewGraphWindow
        function menuNewGraphWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcNewGraphWin;
            return
        end

        % Menu selected function: menuPlotROIHist
        function menuPlotROIHist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            plotMetrics(handles);
            return
        end

        % Menu selected function: menuPlot
        function menuPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuRefresh
        function menuRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            metricsRefresh(handles);
            return
        end

        % Menu selected function: menuSaveData
        function menuSaveData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            metricsSaveData(gcbf)
            return
        end

        % Menu selected function: menuSave
        function menuSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            metricsSaveImage(gcbf);
            return
        end

        % Value changed function: popImageList1
        function popImageList1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Values are stored in this popimage list, like in the other one.
            metricsRefresh(handles);
            return
        end

        % Value changed function: popImageList2
        function popImageList2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Values are stored in this popimage list, like in the other one.
            metricsRefresh(handles);
            return
        end

        % Value changed function: popMetric
        function popMetric_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Store the metric choice in the handles.
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ISET-Metrics';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileLoadImage = uimenu(app.menuFile);
            app.menuFileLoadImage.Text = 'Load (image)';
            app.menuFileLoadImage.Tag = 'menuFileLoadImage';
            app.menuFileLoadImagePair = uimenu(app.menuFileLoadImage);
            app.menuFileLoadImagePair.Text = 'Image pair';
            app.menuFileLoadImagePair.Tag = 'menuFileLoadImagePair';
            app.menuFileLoadImage1 = uimenu(app.menuFileLoadImage);
            app.menuFileLoadImage1.Text = 'Image 1';
            app.menuFileLoadImage1.Tag = 'menuFileLoadImage1';
            app.menuFileLoadImage2 = uimenu(app.menuFileLoadImage);
            app.menuFileLoadImage2.Text = 'Image 2';
            app.menuFileLoadImage2.Tag = 'menuFileLoadImage2';
            app.menuFileLoadVCI = uimenu(app.menuFile);
            app.menuFileLoadVCI.Text = 'Load VCI';
            app.menuFileLoadVCI.Tag = 'menuFileLoadVCI';
            app.menuFileSaveImage = uimenu(app.menuFile);
            app.menuFileSaveImage.Text = 'Save (image)';
            app.menuFileSaveImage.Tag = 'menuFileSaveImage';
            app.menuSaveData = uimenu(app.menuFileSaveImage);
            app.menuSaveData.MenuSelectedFcn = createCallbackFcn(app, @menuSaveData_Callback, true);
            app.menuSaveData.Text = 'Save VCI (.mat)';
            app.menuSaveData.Tag = 'menuSaveData';
            app.menuSave = uimenu(app.menuFileSaveImage);
            app.menuSave.MenuSelectedFcn = createCallbackFcn(app, @menuSave_Callback, true);
            app.menuSave.Text = 'Save image (.tif)';
            app.menuSave.Tag = 'menuSave';
            app.menuFileSaveVCI = uimenu(app.menuFile);
            app.menuFileSaveVCI.Text = 'Save VCI';
            app.menuFileSaveVCI.Tag = 'menuFileSaveVCI';
            app.menuRefresh = uimenu(app.menuFile);
            app.menuRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuRefresh_Callback, true);
            app.menuRefresh.Separator = 'on';
            app.menuRefresh.Text = 'Refresh';
            app.menuRefresh.Tag = 'menuRefresh';
            app.menuClose = uimenu(app.menuFile);
            app.menuClose.MenuSelectedFcn = createCallbackFcn(app, @menuClose_Callback, true);
            app.menuClose.Separator = 'on';
            app.menuClose.Text = 'Close';
            app.menuClose.Tag = 'menuClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuPlot = uimenu(app.figure1);
            app.menuPlot.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_Callback, true);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlotROIHist = uimenu(app.menuPlot);
            app.menuPlotROIHist.MenuSelectedFcn = createCallbackFcn(app, @menuPlotROIHist_Callback, true);
            app.menuPlotROIHist.Text = 'ROI histogram (metric)';
            app.menuPlotROIHist.Tag = 'menuPlotROIHist';
            app.menuNewGraphWindow = uimenu(app.menuPlot);
            app.menuNewGraphWindow.MenuSelectedFcn = createCallbackFcn(app, @menuNewGraphWindow_Callback, true);
            app.menuNewGraphWindow.Separator = 'on';
            app.menuNewGraphWindow.Text = 'New graph window';
            app.menuNewGraphWindow.Tag = 'menuNewGraphWindow';
            app.menuAnalyze = uimenu(app.figure1);
            app.menuAnalyze.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyze_Callback, true);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.menuAnalyzeComparePatches = uimenu(app.menuAnalyze);
            app.menuAnalyzeComparePatches.Text = 'Compare patches';
            app.menuAnalyzeComparePatches.Tag = 'menuAnalyzeComparePatches';
            app.menuAnalyzeSummary = uimenu(app.menuAnalyze);
            app.menuAnalyzeSummary.Text = 'Error summary';
            app.menuAnalyzeSummary.Tag = 'menuAnalyzeSummary';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.img1 = uiaxes(app.figure1);
            app.img1.Tag = 'img1';
            app.img2 = uiaxes(app.figure1);
            app.img2.Tag = 'img2';
            app.imgMetric = uiaxes(app.figure1);
            app.imgMetric.Tag = 'imgMetric';
            app.txt1 = uilabel(app.figure1);
            app.txt1.Tag = 'txt1';
            app.txt1.Tooltip = 'First comparison image';
            app.txt1.Text = 'Image 1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Tooltip = 'Second comparison image';
            app.text2.Text = 'Image 2';
            app.txtInfo = uilabel(app.figure1);
            app.txtInfo.Tag = 'txtInfo';
            app.txtInfo.Text = 'Info';
            app.editGamma = uieditfield(app.figure1, 'text');
            app.editGamma.ValueChangedFcn = createCallbackFcn(app, @editGamma_Callback, true);
            app.editGamma.Tag = 'editGamma';
            app.editGamma.Value = '1';
            app.txtGamma = uilabel(app.figure1);
            app.txtGamma.Tag = 'txtGamma';
            app.txtGamma.Tooltip = 'Set gamma for rendering to screen - doesn''t change data';
            app.txtGamma.Text = 'Gamma';
            app.btnCompute = uibutton(app.figure1, 'push');
            app.btnCompute.ButtonPushedFcn = createCallbackFcn(app, @btnCompute_Callback, true);
            app.btnCompute.Tag = 'btnCompute';
            app.btnCompute.Tooltip = 'Click to compute metric';
            app.btnCompute.Text = 'Compute Metric';
            app.txtMetric = uilabel(app.figure1);
            app.txtMetric.Tag = 'txtMetric';
            app.txtMetric.Tooltip = 'Select metric';
            app.txtMetric.Text = 'Metric';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.txtMetricValue = uilabel(app.figure1);
            app.txtMetricValue.Tag = 'txtMetricValue';
            app.txtMetricValue.Text = '';
            app.popMetric = uidropdown(app.figure1);
            app.popMetric.Items = {'CIELAB (dE)', 'CIELUV (dE)', 'Spatial CIELAB', 'DCTune', 'RMSE', 'MSE', 'PSNR'};
            app.popMetric.ValueChangedFcn = createCallbackFcn(app, @popMetric_Callback, true);
            app.popMetric.Tag = 'popMetric';
            app.popMetric.Value = 'CIELAB (dE)';
            app.popImageList2 = uidropdown(app.figure1);
            app.popImageList2.Items = {'Popup Menu'};
            app.popImageList2.ValueChangedFcn = createCallbackFcn(app, @popImageList2_Callback, true);
            app.popImageList2.Tag = 'popImageList2';
            app.popImageList2.Tooltip = 'select 2nd image for metric comparison';
            app.popImageList2.Value = 'Popup Menu';
            app.popImageList1 = uidropdown(app.figure1);
            app.popImageList1.Items = {'Popup Menu'};
            app.popImageList1.ValueChangedFcn = createCallbackFcn(app, @popImageList1_Callback, true);
            app.popImageList1.Tag = 'popImageList1';
            app.popImageList1.Tooltip = 'select 1st image for metric comparison';
            app.popImageList1.Value = 'Popup Menu';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metricsWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)metricsWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end