classdef sensorDesignCFA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        menuFile                      matlab.ui.container.Menu
        menuSaveCFA                   matlab.ui.container.Menu
        menuFileSaveFilters           matlab.ui.container.Menu
        fileFontSize                  matlab.ui.container.Menu
        menuRefresh                   matlab.ui.container.Menu
        menuClose                     matlab.ui.container.Menu
        menuEdit                      matlab.ui.container.Menu
        menuScaleQE_Callback_1        matlab.ui.container.Menu
        menuEditEquateTransmittances  matlab.ui.container.Menu
        text1                         matlab.ui.control.Label
        btnScale                      matlab.ui.control.Button
        BlocksizeandarrangementPanel  matlab.ui.container.Panel
        popName1                      matlab.ui.control.DropDown
        popName2                      matlab.ui.control.DropDown
        popName4                      matlab.ui.control.DropDown
        popName3                      matlab.ui.control.DropDown
        popBlockSize                  matlab.ui.control.DropDown
        Panel                         matlab.ui.container.Panel
        axesPlot                      matlab.ui.control.UIAxes
        FilterpeaktransmittancePanel  matlab.ui.container.Panel
        SelectfilterDropDownLabel     matlab.ui.control.Label
        SelectfilterDropDown          matlab.ui.control.DropDown
        SetpeakEditFieldLabel         matlab.ui.control.Label
        SetpeakEditField              matlab.ui.control.NumericEditField
        popEdit                       matlab.ui.control.DropDown
        EditfilterLabel               matlab.ui.control.Label
    end

    
    methods (Access = private)
        function resetColorOrder(app)
            % Gather up the filternames.
            
            sensor = ieGetObject('sensor');
            filterNames = sensorGet(sensor,'filternames');
            
            blockSize = app.popBlockSize.Value;
            
            switch lower(blockSize)
                case '1x1'
                    % Monochrome case
                    pattern(1) = find(strcmpi(app.popName1.Value,filterNames));
                case '2x2'
                    % Typical
                    pattern(1,1) = find(strcmpi(app.popName1.Value,filterNames));
                    pattern(2,1) = find(strcmpi(app.popName2.Value,filterNames));
                    pattern(1,2) = find(strcmpi(app.popName3.Value,filterNames));
                    pattern(2,2) = find(strcmpi(app.popName4.Value,filterNames));
                otherwise
                    error('Unknown block size: %s',blockSize);
            end
            sensor = sensorSet(sensor,'pattern',pattern);
            ieReplaceObject(sensor);
        end
    end
    
    methods (Access = public)
        
        function refresh(app)
            % Refresh the sensorDesignCFA window.
            %
            %   sensorDesignRefresh(handles)
            %
            % Used by the sensorDesignCFA window
            %
            % Copyright ImagEval Consultants, LLC, 2005
            
            sensor  = ieGetObject('sensor');
            filterSpectra =   sensorGet(sensor,'filterspectra');
            % nFilters   = sensorGet(sensor,'nfilters');
            
            wave       = sensorGet(sensor,'wave');
            % colorOrder = sensorGet(sensor,'colororder');
            % filterNames= sensorGet(sensor,'filterNames');
            
            %  Update the screen
            blockSize = app.popBlockSize.Value;
            
            switch blockSize
                case '1x1'
                    setButtons(app,'off');
                case '2x2'
                    setButtons(app,'on');
                otherwise
                    error('Unknown blockSize %s',blockSize);
            end
            
            setColorNames(app,sensor);            
            nFilters = sensorGet(sensor,'nfilters');
            
            % If the color filter name is white or other, use black line.
            filterNames = sensorGet(sensor,'filterNamesCellArray');
            lgnd = cell(nFilters,1);

            for ii=1:nFilters
                
                % Hasn't been checked after change to color filter name management.
                
                plot(app.axesPlot, wave,filterSpectra(:,ii),[filterNames{ii},'-']); hold on;
                lgnd{ii} = sprintf('%s',filterNames{ii});
                set(gca,'ylim',[0, 1.05]);
            end
            
            hold off; grid on
            xlabel('Wavelength (nm)'); ylabel('Transmissivity'); title('Current filters');
            legend(lgnd);
            
        end
        
        %----------------------------
        function setButtons(app,state)
            %
            switch state
                
                case 'on'
                    app.popName2.Visible = 'on';
                    app.popName3.Visible = 'on';
                    app.popName4.Visible = 'on';
                    
                case 'off'
                    app.popName2.Visible = 'off';
                    app.popName3.Visible = 'off';
                    app.popName4.Visible = 'off';
                    
                otherwise
                    error('ugh.')
            end
            
        end
        %-------------------------------------------
        function setColorNames(app, sensor)
            %
            % Set the color names in the popName boxes.
            % Each filter has a name drawn from a list in sensorColorOrder.
            % This routine we use the filter name and, depending on the pattern, we
            % assign the popup string.
            
            filterNames = sensorGet(sensor,'filternames');
            pattern = sensorGet(sensor,'pattern');
            
            % Set the list of characters in the pop up name boxes.  By doing it this
            % way, if we decide to add a new color label, we can just change the
            % sensorColorOrder function.  I hope.
            % cfaOrdering = sensorColorOrder;
            
            app.popName1.Items = filterNames;
            app.popName2.Items = filterNames;
            app.popName3.Items = filterNames;
            app.popName4.Items = filterNames;
            
            % We should probably check the dimensionality here.
            if length(pattern) == 1
                app.popName1.Value = pattern(1);
            elseif length(pattern(:)) == 4
                app.popName1.Value=filterNames{pattern(1,1)};
                app.popName2.Value=filterNames{pattern(2,1)};
                app.popName3.Value=filterNames{pattern(1,2)};
                app.popName4.Value=filterNames{pattern(2,2)};
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sensorDesignCFA_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            app.refresh;
        end

        % Button pushed function: btnScale
        function btnScale_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            menuScaleQE_Callback(app)
        end

        % Menu selected function: fileFontSize
        function fileFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>            
            fSize = ieFontSizeSet(handles.figure1);
            app.text1.fontsize= (fSize + 4);
            app.txtBlockSize.fontsize=(fSize + 2);

        end

        % Menu selected function: menuClose
        function menuClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Clean up the filter names and the filter spectra.  If the block was set
            % up to monochrome, get rid of stuff.  If there are any unused filters, get
            % rid of them.
            
            sensor = ieGetObject('sensor');
            
            % contents = get(handles.popBlockSize,'String');
            % blockSize = contents{get(handles.popBlockSize,'Value')};
            
            filterSpectra = sensorGet(sensor,'filterspectra');
            filterNames   = sensorGet(sensor,'filternames');
            nFilters      = sensorGet(sensor,'nfilters');
            pattern       = sensorGet(sensor,'pattern');
            
            blockSize = app.popBlockSize.Value;

            switch lower(blockSize)
                case '1x1'
                    toKeep = pattern(1);
                    filterSpectra = filterSpectra(:,toKeep);
                    newFilterNames{1} = filterNames{toKeep};
                    filterNames = newFilterNames;
                    pattern = 1;
                    
                case '2x2'
                    
                    % See which sensors are used in p attern
                    usedFilters = unique(sort(pattern(:)));
                    if length(usedFilters) == sensorGet(sensor,'nfilters')
                        % do nothing
                    elseif length(usedFilters) < nFilters
                        warndlg('Removing unused filters from sensor.');
                        
                        % Remove the unused spectra
                        filterSpectra = filterSpectra(:,usedFilters);
                        
                        % Move the names
                        newFilterNames = cell(numel(usedFilters),1);
                        for ii=1:length(usedFilters)
                            newFilterNames{ii} = filterNames{usedFilters(ii)};
                        end
                        filterNames = newFilterNames;
                        
                        % Adjust the pattern by reducing the value of any
                        % terms greater than a removed filter. 
                        for ii=1:nFilters
                            m = find(~[usedFilters - ii]);
                            if isempty(m)
                                l = pattern >= ii;
                                if ~isempty(l), pattern(l) = pattern(l) - 1; end
                            end
                        end
                        
                    else
                        error('The pattern is specifying filters that are not present.');
                    end
                    
                otherwise
                    error('Unknown block size setting.');
            end
            
            % sensor = sensorSet(sesnsor,'unitblock',sensorUnitBlock(sensor));
            sensor = sensorSet(sensor,'filter spectra',filterSpectra);
            sensor = sensorSet(sensor,'filter Names',filterNames);
            
            % Pattern's shape now codes the unit block shape
            sensor = sensorSet(sensor,'pattern',pattern);
            
            % This part could be avoided on a cancel, rather than save and close
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor);
            
            sensorW = sensorWindow;
            sensorW.refresh;
            delete(app);
            
        end

        % Menu selected function: menuEditEquateTransmittances
        function menuEditEquateTransmittances_Callback(app, event)
           
            % Equalizes the area under the filter curves and sets the overall peak to
            % 1.0.
            
            sensor = ieGetObject('sensor');
            
            filters = sensorGet(sensor,'filterspectra');
            filters = sensorEquateTransmittances(filters);
            
            sensor = sensorSet(sensor,'filterSpectra',filters);
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
           
        end

        % Menu selected function: menuFileSaveFilters
        function menuFileSaveFilters_Callback(app, event)
          
            sensor = ieGetObject('sensor');
            ieSaveColorFilters(sensor);
            
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            

        end

        % Menu selected function: menuRefresh
        function menuRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.refresh;
        end

        % Menu selected function: menuSaveCFA
        function menuSaveCFA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            sensor = ieGetObject('sensor');
            sensorCfaSave(sensor);
        end

        % Menu selected function: menuScaleQE_Callback_1
        function menuScaleQE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %
            % Set the peak QE in each of the three color filters
            
            sensor = ieGetObject('sensor');
            cf = sensorGet(sensor,'filterspectra');
            filterNames = sensorGet(sensor,'filternames');
            currentPeaks = max(cf);
            for ii=1:size(cf,2)
                prompt{ii} = sprintf('Set %s peak',char(filterNames{ii}));
                def{ii}=num2str(currentPeaks(ii));
            end
            
            dlgTitle='Scale peak transmission'; lineNo=1;
            answer = inputdlg(prompt,dlgTitle,lineNo,def);
            if isempty(answer), return; end
            
            newPeaks = zeros(numel(answer),1);
            for ii=1:length(answer), newPeaks(ii) = str2double(answer{ii}); end
            cf = cf*diag(newPeaks./currentPeaks);
            sensor = sensorSet(sensor,'filterspectra',cf);
            
            ieReplaceObject(sensor);
            app.refresh;

        end

        % Value changed function: popBlockSize
        function popBlockSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            app.refresh;
        end

        % Value changed function: popEdit
        function popEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensor = ieGetObject('sensor');
            contents = get(hObject,'String');
            
            switch contents{get(hObject,'Value')}
                
                case 'Add Filter'
                    sensor = sensorAddFilter(sensor);
                    
                case 'Replace Filter'
                    sensor = sensorReplaceFilter(sensor);
                    
                case 'Delete Filter'
                    sensor = sensorDeleteFilter(sensor);
                    
                otherwise
                    error('bad selection.');
            end
            
            ieReplaceObject(sensor);
            app.refresh;           
        end

        % Value changed function: popName1
        function popName1_Callback(app, event)
            %
            % These routines reset the entries in the pattern variable.  We find the
            % name of the selected filter for each position (in this case position 1),
            % and then we place the filter number in that position in isa.cfa.pattern.
            
            % This is the selected filter name
            resetColorOrder(app);
            app.refresh;            
        end

        % Value changed function: popName2
        function popName2_Callback(app, event)
            
            resetColorOrder(app);
            app.refresh;            
        end

        % Value changed function: popName3
        function popName3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            resetColorOrder(app);
            app.refresh;            
        end

        % Value changed function: popName4
        function popName4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            resetColorOrder(app);
            app.refresh;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'sensorDesignCFA';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuSaveCFA = uimenu(app.menuFile);
            app.menuSaveCFA.MenuSelectedFcn = createCallbackFcn(app, @menuSaveCFA_Callback, true);
            app.menuSaveCFA.Text = 'Save CFA';
            app.menuSaveCFA.Tag = 'menuSaveCFA';
            app.menuFileSaveFilters = uimenu(app.menuFile);
            app.menuFileSaveFilters.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveFilters_Callback, true);
            app.menuFileSaveFilters.Text = 'Save Filters';
            app.menuFileSaveFilters.Tag = 'menuFileSaveFilters';
            app.fileFontSize = uimenu(app.menuFile);
            app.fileFontSize.MenuSelectedFcn = createCallbackFcn(app, @fileFontSize_Callback, true);
            app.fileFontSize.Separator = 'on';
            app.fileFontSize.Text = 'Font Size';
            app.fileFontSize.Tag = 'fileFontSize';
            app.menuRefresh = uimenu(app.menuFile);
            app.menuRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuRefresh_Callback, true);
            app.menuRefresh.Separator = 'on';
            app.menuRefresh.Text = 'Refresh';
            app.menuRefresh.Tag = 'menuRefresh';
            app.menuClose = uimenu(app.menuFile);
            app.menuClose.MenuSelectedFcn = createCallbackFcn(app, @menuClose_Callback, true);
            app.menuClose.Text = 'Apply and Close';
            app.menuClose.Tag = 'menuClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuScaleQE_Callback_1 = uimenu(app.menuEdit);
            app.menuScaleQE_Callback_1.MenuSelectedFcn = createCallbackFcn(app, @menuScaleQE_Callback, true);
            app.menuScaleQE_Callback_1.Text = 'Set Peak Transmittance';
            app.menuScaleQE_Callback_1.Tag = 'menuScaleQE_Callback_1';
            app.menuEditEquateTransmittances = uimenu(app.menuEdit);
            app.menuEditEquateTransmittances.MenuSelectedFcn = createCallbackFcn(app, @menuEditEquateTransmittances_Callback, true);
            app.menuEditEquateTransmittances.Text = 'Equate Filter Transmittances';
            app.menuEditEquateTransmittances.Tag = 'menuEditEquateTransmittances';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Design CFA';
            app.btnScale = uibutton(app.figure1, 'push');
            app.btnScale.ButtonPushedFcn = createCallbackFcn(app, @btnScale_Callback, true);
            app.btnScale.Tag = 'btnScale';
            app.btnScale.Text = 'Set Peak Transmittances';
            app.BlocksizeandarrangementPanel = uipanel(app.figure1);
            app.BlocksizeandarrangementPanel.Title = 'Block size and arrangement';
            app.popName1 = uidropdown(app.BlocksizeandarrangementPanel);
            app.popName1.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName1.ValueChangedFcn = createCallbackFcn(app, @popName1_Callback, true);
            app.popName1.Tag = 'popName1';
            app.popName1.Value = 'r';
            app.popName2 = uidropdown(app.BlocksizeandarrangementPanel);
            app.popName2.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName2.ValueChangedFcn = createCallbackFcn(app, @popName2_Callback, true);
            app.popName2.Tag = 'popName2';
            app.popName2.Value = 'g';
            app.popName4 = uidropdown(app.BlocksizeandarrangementPanel);
            app.popName4.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName4.ValueChangedFcn = createCallbackFcn(app, @popName4_Callback, true);
            app.popName4.Tag = 'popName4';
            app.popName4.Value = 'b';
            app.popName3 = uidropdown(app.BlocksizeandarrangementPanel);
            app.popName3.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName3.ValueChangedFcn = createCallbackFcn(app, @popName3_Callback, true);
            app.popName3.Tag = 'popName3';
            app.popName3.Value = 'g';
            app.popBlockSize = uidropdown(app.BlocksizeandarrangementPanel);
            app.popBlockSize.Items = {'2x2', '1x1'};
            app.popBlockSize.ValueChangedFcn = createCallbackFcn(app, @popBlockSize_Callback, true);
            app.popBlockSize.Tag = 'popBlockSize';
            app.popBlockSize.Value = '2x2';
            app.Panel = uipanel(app.figure1);
            app.axesPlot = uiaxes(app.Panel);
            app.axesPlot.Tag = 'axes1';
            app.FilterpeaktransmittancePanel = uipanel(app.figure1);
            app.FilterpeaktransmittancePanel.Title = 'Filter peak transmittance';
            app.SelectfilterDropDownLabel = uilabel(app.FilterpeaktransmittancePanel);
            app.SelectfilterDropDownLabel.Text = 'Select filter';
            app.SelectfilterDropDown = uidropdown(app.FilterpeaktransmittancePanel);
            app.SetpeakEditFieldLabel = uilabel(app.FilterpeaktransmittancePanel);
            app.SetpeakEditFieldLabel.Text = 'Set peak';
            app.SetpeakEditField = uieditfield(app.FilterpeaktransmittancePanel, 'numeric');
            app.popEdit = uidropdown(app.figure1);
            app.popEdit.Items = {'Add Filter', 'Replace Filter', 'Delete Filter'};
            app.popEdit.ValueChangedFcn = createCallbackFcn(app, @popEdit_Callback, true);
            app.popEdit.Tag = 'popEdit';
            app.popEdit.Value = 'Add Filter';
            app.EditfilterLabel = uilabel(app.figure1);
            app.EditfilterLabel.Text = 'Edit filter';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensorDesignCFA_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)sensorDesignCFA_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end