classdef sensorImageWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sensorImageWindow              matlab.ui.Figure
        menuFile                       matlab.ui.container.Menu
        menuFileLoad                   matlab.ui.container.Menu
        menuSensorImport               matlab.ui.container.Menu
        menuFileLoadVoltsMat           matlab.ui.container.Menu
        menuFileSave                   matlab.ui.container.Menu
        menuSensorExport               matlab.ui.container.Menu
        menuFileSaveVoltsMat           matlab.ui.container.Menu
        menuSaveImage                  matlab.ui.container.Menu
        menuFileSpecsheet              matlab.ui.container.Menu
        menuFileResh                   matlab.ui.container.Menu
        menuFileClose                  matlab.ui.container.Menu
        menuEdit                       matlab.ui.container.Menu
        menuEditName                   matlab.ui.container.Menu
        menuEditCreate                 matlab.ui.container.Menu
        menuCopySensor                 matlab.ui.container.Menu
        menuEditDelete                 matlab.ui.container.Menu
        editDeleteSome                 matlab.ui.container.Menu
        menuEditResWave                matlab.ui.container.Menu
        sensorEditClearData            matlab.ui.container.Menu
        menuEditFontSize               matlab.ui.container.Menu
        menuEditClearMessage           matlab.ui.container.Menu
        menuEditZoom                   matlab.ui.container.Menu
        menuEditViewer                 matlab.ui.container.Menu
        LoadSaveSensorMenu             matlab.ui.container.Menu
        menuPlot                       matlab.ui.container.Menu
        menuImDSNU                     matlab.ui.container.Menu
        menuImPRNU                     matlab.ui.container.Menu
        menuImShotNoise                matlab.ui.container.Menu
        menuPlotSpectra                matlab.ui.container.Menu
        menuPlotColorFilters           matlab.ui.container.Menu
        menuPlotIR                     matlab.ui.container.Menu
        plotSpecCFApattern             matlab.ui.container.Menu
        menuPlotPDSpectralQE           matlab.ui.container.Menu
        menuPlotSpecResp               matlab.ui.container.Menu
        menuPlotPixelSR                matlab.ui.container.Menu
        menuPlotSensorImageTSize       matlab.ui.container.Menu
        plotMccOverlay                 matlab.ui.container.Menu
        menuPlotHumanCone              matlab.ui.container.Menu
        menuPlotNewGraphWindow         matlab.ui.container.Menu
        menuSensor                     matlab.ui.container.Menu
        menuSensorCIF                  matlab.ui.container.Menu
        menuSensorQQCIFSixteenthInch   matlab.ui.container.Menu
        menuSensorQQCIFQuartInch       matlab.ui.container.Menu
        menuSensorQCIFQuartInch        matlab.ui.container.Menu
        menuSensorCIFHalfInch          matlab.ui.container.Menu
        menuSensorVGA                  matlab.ui.container.Menu
        menuSensorQQVGASixteenthInch   matlab.ui.container.Menu
        menuSensorQQVGAQuartInch       matlab.ui.container.Menu
        menuSensorQVGAQuartInch        matlab.ui.container.Menu
        menuSensorQVGAHalfInch         matlab.ui.container.Menu
        menuSensorVGAHalfInch          matlab.ui.container.Menu
        menuSensorHumanCones           matlab.ui.container.Menu
        humanCones631                  matlab.ui.container.Menu
        humanConesKLMS1631             matlab.ui.container.Menu
        menuLoadCFA                    matlab.ui.container.Menu
        menuColorFilters               matlab.ui.container.Menu
        menuPDQE                       matlab.ui.container.Menu
        menuInfraRed                   matlab.ui.container.Menu
        menuPDSize                     matlab.ui.container.Menu
        menuSensorMicrolens            matlab.ui.container.Menu
        menuDesignCFA                  matlab.ui.container.Menu
        menuSensorCDS                  matlab.ui.container.Menu
        menuSensorColFPN               matlab.ui.container.Menu
        menuSensorPixelVignetting      matlab.ui.container.Menu
        menuSensorSetComputeGrid       matlab.ui.container.Menu
        menuAnalyze                    matlab.ui.container.Menu
        menuAnLine                     matlab.ui.container.Menu
        menuHorizontal                 matlab.ui.container.Menu
        menuAnLineH                    matlab.ui.container.Menu
        menuHorLineE                   matlab.ui.container.Menu
        menuHorLineDV                  matlab.ui.container.Menu
        menuFFThor                     matlab.ui.container.Menu
        menuVertical                   matlab.ui.container.Menu
        menuAnLineV                    matlab.ui.container.Menu
        menuVertLineE                  matlab.ui.container.Menu
        menuVertLineDV                 matlab.ui.container.Menu
        menuFFTVert                    matlab.ui.container.Menu
        menuAnROIStats                 matlab.ui.container.Menu
        menuAnBasic                    matlab.ui.container.Menu
        menuAnBasicE                   matlab.ui.container.Menu
        menuAnHistogramVolts           matlab.ui.container.Menu
        menuAnPixHistQ                 matlab.ui.container.Menu
        menuAnPO                       matlab.ui.container.Menu
        menuAnalyzePOVignetting        matlab.ui.container.Menu
        menuAnalyzeMicroLens           matlab.ui.container.Menu
        menuAnPixOptLoadUL             matlab.ui.container.Menu
        menuAnPixOptSaveUl             matlab.ui.container.Menu
        menuAnPOShowUL                 matlab.ui.container.Menu
        menuAnSNR                      matlab.ui.container.Menu
        menuAnPixelSNR                 matlab.ui.container.Menu
        menuAnSensorSNR                matlab.ui.container.Menu
        menuPixelLuxSec                matlab.ui.container.Menu
        menuSensorLuxSec               matlab.ui.container.Menu
        menuISOSat                     matlab.ui.container.Menu
        menuAnExposureValue            matlab.ui.container.Menu
        menuAnPhotExp                  matlab.ui.container.Menu
        menuAnColor                    matlab.ui.container.Menu
        menuAnColorRB                  matlab.ui.container.Menu
        menuAnColorRG                  matlab.ui.container.Menu
        menuAnColCCM                   matlab.ui.container.Menu
        menuEdgeOp                     matlab.ui.container.Menu
        menuAnComputeFromOI            matlab.ui.container.Menu
        menuAnComputeFromScene         matlab.ui.container.Menu
        menuHelp                       matlab.ui.container.Menu
        menuHelpSensorOnline           matlab.ui.container.Menu
        menuHelpPixelOnline            matlab.ui.container.Menu
        menuHelpISETOnlineManual       matlab.ui.container.Menu
        menuAppNotes                   matlab.ui.container.Menu
        axes2                          matlab.ui.control.UIAxes
        imgCFA                         matlab.ui.control.UIAxes
        ExposurePanel                  matlab.ui.container.Panel
        btnShowCFAExpDurations         matlab.ui.control.Button
        editExpTime                    matlab.ui.control.EditField
        txtExposureUnits               matlab.ui.control.Label
        editNExposures                 matlab.ui.control.EditField
        editExpFactor                  matlab.ui.control.EditField
        popupExpMode                   matlab.ui.control.DropDown
        btnComputeImage                matlab.ui.control.Button
        editReadNoise                  matlab.ui.control.EditField
        txtReadNoise                   matlab.ui.control.Label
        editDarkCurrent                matlab.ui.control.EditField
        txtDarkCurrent                 matlab.ui.control.Label
        editISARows                    matlab.ui.control.EditField
        editISAcols                    matlab.ui.control.EditField
        txtRow                         matlab.ui.control.Label
        txtConvGain                    matlab.ui.control.Label
        editConvGain                   matlab.ui.control.EditField
        txtISA                         matlab.ui.control.Label
        txtPixel                       matlab.ui.control.Label
        editVoltageSwing               matlab.ui.control.EditField
        text12                         matlab.ui.control.Label
        editOffsetFPN                  matlab.ui.control.EditField
        editGainFPN                    matlab.ui.control.EditField
        txtFPN                         matlab.ui.control.Label
        txtISADescription              matlab.ui.control.Label
        txtPixelDescription            matlab.ui.control.Label
        txtDkCurrent                   matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        text20                         matlab.ui.control.Label
        text21                         matlab.ui.control.Label
        text25                         matlab.ui.control.Label
        editGam                        matlab.ui.control.EditField
        txtDisplayGamma                matlab.ui.control.Label
        txtPRNU                        matlab.ui.control.Label
        text33                         matlab.ui.control.Label
        txtMVperSec                    matlab.ui.control.Label
        txtConsistency                 matlab.ui.control.Label
        txtMessage                     matlab.ui.control.Label
        sliderSelectBracketedExposure  matlab.ui.control.Slider
        txtBracketExposure             matlab.ui.control.Label
        popISA                         matlab.ui.control.DropDown
        popupSelect                    matlab.ui.control.DropDown
        popQuantization                matlab.ui.control.DropDown
        popScaleSize                   matlab.ui.control.DropDown
        btnNext                        matlab.ui.control.Button
        btnPrev                        matlab.ui.control.Button
        txtScale                       matlab.ui.control.Label
    end

    
    methods (Access = private)
        function createNewSensor(app, hObject, eventdata, handles)
            % New ISA
            % Defaults to current values, except new color, as per sensorCreate.
            
            newISA = ieGetObject('ISA');
            newVal = vcNewObjectValue('ISA');
            
            sensorArrayNames = get(handles.popISA,'String');
            thisArrayName = sensorArrayNames{get(handles.popISA,'Value')};
            switch lower(thisArrayName)
                case {'bayer-grbg'}
                    newISA = sensorCreate('bayer-grbg');
                case {'bayer-rggb'}
                    newISA = sensorCreate('bayer-rggb');
                case {'bayer-bggr'}
                    newISA = sensorCreate('bayer-bggr');
                case 'bayer-ycmy'
                    newISA = sensorCreate('bayer-ycmy');
                case {'Four Color'}
                    newISA = sensorCreate('fourcolor');
                case 'monochrome'
                    newISA = sensorCreate('monochrome');
                otherwise
                    warning('Creating default sensor.')
                    newISA = sensorCreate('default');
            end
            
            vcReplaceAndSelectObject(newISA,newVal);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end
        
        function menuAnHistogram_Callback(app, hObject, eventdata, handles)
            % Analyze Histogram Menu
            return
        end
        
        function menuIm_Callback(app, hObject, eventdata, handles)
            return
        end
        
        function menuLoadSF_Callback(app, hObject, eventdata, handles)
            % Load spectral functions heading.
            return
        end
        
        function popFormat_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function popupColor_CreateFcn(app, hObject, eventdata, handles)
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
            return
        end
        
        function sensor = sensorAdjustBracketTimes(app, handles, sensor)
            % Examine the bracket settings edit boxes and adjust the exposure times
            %
            % Another display mode is needed for multiple exposure times that are not
            % necessarily in bracketed mode.
            
            % This can be called by editing the exp time box or the nExposures or the
            % scale factor on exposures.
            %
            % If it is called from the exp time box, then we get the sensor passed in.
            % Otherwise we use the default sensor.
            if ieNotDefined('sensor'), sensor = ieGetObject('sensor'); end
            
            % Require odd exposure number.  Update the edit box with the right number
            nExposures = str2double(get(handles.editNExposures,'String'));
            if ~isodd(nExposures), nExposures = nExposures + 1; end
            set(handles.editNExposures,'String',num2str(nExposures));
            
            % Create the exposure list from GUI data
            sFactor         = str2double(get(handles.editExpFactor,'String'));
            centralExposure = sensorGet(sensor,'Geometric Mean Exposure Time');
            nBelow          = floor(nExposures/2);
            shortExposure   = centralExposure/(sFactor^nBelow);
            expTimes = zeros(1,nExposures);
            for ii=1:nExposures
                expTimes(ii) = shortExposure*sFactor^(ii-1);
            end
            
            % Update the sensor
            sensor        = sensorSet(sensor,'Exp Time',expTimes);
            exposurePlane = floor(nExposures/2) + 1;
            sensor = sensorSet(sensor,'Exposure Plane',exposurePlane);
            
            % Slider (lower left) for bracketed exposures now set in
            % sensorEditsAndButtons
            
            return
        end
        
        function sensor = sensorAdjustCFATimes(app, handles, sensor)
            % Adjust the sensor exposure time slot to match the CFA size, putting the
            % sensor into the CFA exposure mode.
            %
            
            if ieNotDefined('sensor'), sensor = ieGetObject('sensor'); end
            
            mSize = size(sensorGet(sensor,'pattern'));
            eTimes = sensorGet(sensor,'expTimes');
            
            % If the eTimes has the wrong size, use the geometric mean of the current
            % eTime values and assign it to a matrix of the right size.
            if ~isequal(size(eTimes),mSize)
                eTimes = ones(mSize)*sensorGet(sensor,'geometricMeanExposuretime');
                sensor = sensorSet(sensor,'expTime',eTimes);
            end
            
            return
        end
        
        function sensorDelete(app, hObject, eventdata, handles)
            
            vcDeleteSelectedObject('ISA');
            [val,isa] = vcGetSelectedObject('ISA');
            if isempty(val)
                isa = sensorCreate('default');
                vcReplaceAndSelectObject(isa,1);
            end
            
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end
        
        function sensorRefresh(app, hObject, eventdata, handles)
            sensorEditsAndButtons(handles);
            return
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.sensorImageWindow.CloseRequestFcn = 'sensorClose';
            app.sensorImageWindow.KeyPressFcn = 'sensorKeyPress';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sensorImageWindow_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            if isempty(varargin) % Do nothing
            elseif isstruct(varargin{1}) && ...
                    isfield(varargin{1},'type') && ...
                    (strcmp(varargin{1}.type,'sensor'))
                % We have a sensor as input.  Put it in the database.
                ieAddObject(varargin{1});
            end
            
            sensorOpen(hObject,eventdata,handles)
            sensorRefresh(app, hObject, eventdata, handles);
            
            % If the person has set a position and size preference, put the window
            % there
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if ~isempty(wPos{4}), set(hObject,'Position',wPos{4}); end
            end
            return
        end

        % Button pushed function: btnComputeImage
        function btnComputeImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Button press computes the image from the optics data
            %
            
            OI = ieGetObject('OI');
            if isempty(oiGet(OI,'photons'))
                ieInWindowMessage('No optical image photon data.',handles);
                return;
            else
                ieInWindowMessage([],handles);
            end
            [val,ISA] = vcGetSelectedObject('ISA');
            
            % The custom compute button is checked inside of sensorCompute.
            ISA = sensorCompute(ISA,OI);
            
            % For the moment, data and ISA are consistent.
            ISA = sensorSet(ISA,'consistency',1);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Push button with the + on the right of the selection popup
            s  = ieSessionGet('selected','sensor');
            nS = ieSessionGet('nobjects','sensor');
            s = min(s + 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ISA',s);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Push button with the - on the left of the selection popup
            s  = ieSessionGet('selected','sensor');
            nS = ieSessionGet('nobjects','sensor');
            s = min(s - 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ISA',s);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Button pushed function: btnShowCFAExpDurations
        function btnShowCFAExpDurations_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Bring up a gui that shows the CFA exposures
            
            sensor = ieGetObject('sensor');
            
            % Make sure we are in the CFA format
            sensor = sensorAdjustCFATimes(app, handles,sensor);
            
            % Put the exposureData matrix into the base workspace in ms
            fmt    = '%.1f';
            prompt = 'Time (ms)';
            defMatrix = sensorGet(sensor,'expTime')*1e3;
            saturation = 0.3;
            filterRGB = sensorFilterRGB(sensor,saturation);
            ieReadSmallMatrix(size(defMatrix),defMatrix,fmt,prompt,[],'msExposureData',filterRGB);
            
            %Read base space data in seconds
            secExposureData = evalin('base','msExposureData')*1e-3;
            
            % Put the sensor back
            sensor = sensorSet(sensor,'expTime',secExposureData);
            vcReplaceObject(sensor);
            
            return
        end

        % Value changed function: editConvGain
        function editConvGain_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,ISA] = vcGetSelectedObject('ISA');
            
            % Interface is in microvolts per electron, typical value is 10-100 uV/e-
            % We store the value in standard units:  V/e-
            ISA.pixel.conversionGain = str2double(get(hObject,'String'))*10^(-6);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: editDarkCurrent
        function editDarkCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            %  The name of this routine should be changed.  We used to specify dark
            %  current, but now we specify dark voltage
            %  This should be editDarkVoltage_Callback().
            %
            % We specify dark voltage (no longer current) in mV/pixel/sec.
            % The parameters darkcurrent, darkcurrentdensity are now derived from this
            % and other sensor properties.
            
            [val,ISA] = vcGetSelectedObject('ISA');
            pixel = sensorGet(ISA,'pixel');
            
            % Value entered is in millivolts.  Value stored is in Volts.
            dk = str2double(get(hObject,'String'))*10^-3;
            pixel =  pixelSet(pixel,'darkvoltage',dk);
            
            ISA = sensorSet(ISA,'pixel',pixel);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: editDeleteSome
        function editDeleteSome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit delete some sensors
            %
            vcDeleteSomeObjects('sensor');
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: editExpFactor
        function editExpFactor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Set the scale factor between exposures in bracketing mode
            %
            % Hints: get(hObject,'String') returns contents of editExpFactor as text
            %        str2double(get(hObject,'String')) returns contents of editExpFactor as a double
            sensor = sensorAdjustBracketTimes(app, handles);
            sensor = sensorClearData(sensor);
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: editExpTime
        function editExpTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,sensor] = vcGetSelectedObject('sensor');
            
            % Interface is in several possible time units. We read in the those units,
            % but we store exposure time in seconds.
            str = get(handles.txtExposureUnits,'string');
            switch str
                case '(sec)'
                    sFactor = 1;
                case '(us)'
                    sFactor = 1e-6;
                case '(ms)'
                    % (ms)
                    sFactor = 1e-3;
                otherwise
                    sFactor = 1e-3;
                    warning('unexpected time string %s\n',str);
            end
            sensor = sensorSet(sensor,'expTime',str2double(get(hObject,'String'))*sFactor);
            
            % If the expTime is in bracketed mode, create the vector
            if isequal(get(handles.popupExpMode,'Val'),2)
                sensor = sensorAdjustBracketTimes(app, handles,sensor);
            elseif isequal(get(handles.popupExpMode,'Val'),1)
                % Do nothing
            else
                error('Exposure popup value (expPopup)%f\n',get(handles.popupExpMode,'Val'))
            end
            
            % Turn off auto-exposure
            sensor = sensorSet(sensor,'autoexposure','off');
            vcReplaceObject(sensor,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: editGainFPN
        function editGainFPN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,sensor] = vcGetSelectedObject('ISA');
            
            % Standard deviation of the slope of the photoresponse function.
            % These numbers are stored as a percentage, just as in the interface.
            % When we compute the standard deviation of the slope, we calculate
            % gainImage = 1 + randn()*(sigmaGainFPN/100)
            %
            % This produces a normal random variable with a standard deviation equal to
            % the percent in the window.
            %
            gainSD = str2double(get(hObject,'String'));
            sensor = sensorSet(sensor,'gainSD',gainSD);
            sensor = sensorSet(sensor,'gainFPNimage',[]);
            
            vcReplaceObject(sensor,val);
            
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: editGam
        function editGam_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Handle value is read during refresh.
            
            % Don't change the red consistency button
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'consistency',-1);
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject,eventdata,handles);
            return
        end

        % Value changed function: editISARows
        function editISARows_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit number of sensor rows
            [val,sensor] = vcGetSelectedObject('sensor');
            
            targetSize = str2double(get(hObject,'String'));
            sensor = sensorSet(sensor,'rows',targetSize);
            str = sprintf('%.0f',sensorGet(sensor,'rows'));
            set(gcbo,'string',str);
            
            %
            % unitBlockRows = sensorGet(sensor,'unitblockrows');
            % targetSize = str2double(get(hObject,'String'));
            % sensor.rows = round(targetSize/unitBlockRows)*unitBlockRows;
            
            str = sprintf('%.0f',sensorGet(sensor,'rows'));
            set(gcbo,'string',str);
            
            sensor = sensorClearData(sensor);
            vcReplaceObject(sensor,val);
            
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: editISAcols
        function editISAcols_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit box - adjust number of columns
            [val,sensor] = vcGetSelectedObject('ISA');
            
            targetSize = str2double(get(hObject,'String'));
            sensor = sensorSet(sensor,'cols',targetSize);
            str = sprintf('%.0f',sensorGet(sensor,'cols'));
            set(gcbo,'string',str);
            
            sensor = sensorClearData(sensor);
            vcReplaceObject(sensor,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: editNExposures
        function editNExposures_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Set the number of exposures in the bracketing mode
            sensor = ieGetObject('sensor');
            sensor = sensorAdjustBracketTimes(app, handles,sensor);
            sensor = sensorClearData(sensor);
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: editOffsetFPN
        function editOffsetFPN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % DSNU measurement in mv
            %
            [val,ISA] = vcGetSelectedObject('ISA');
            
            % SD displayed in millivolts, stored in volts
            sd = str2double(get(hObject,'String'))*10^-3;
            ISA = sensorSet(ISA,'offsetsd',sd);
            ISA = sensorSet(ISA,'offsetfpnimage',[]);
            
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: editReadNoise
        function editReadNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,ISA] = vcGetSelectedObject('ISA');
            pixel = sensorGet(ISA,'pixel');
            
            rn = str2double(get(hObject,'String'));   % Display is mV, stored in Volts.
            pixel = pixelSet(pixel,'readnoisemillivolts',rn);
            ISA = sensorSet(ISA,'pixel',pixel);
            
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: editVoltageSwing
        function editVoltageSwing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,ISA] = vcGetSelectedObject('ISA');
            ISA.pixel.voltageSwing = str2double(get(hObject,'String'));
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: humanCones631
        function humanCones631_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Human cones | Cones-631
            params.sz = [128,192];
            params.rgbDensities = [0.0 .6 .3 .1]; % Empty, L,M,S
            params.coneAperture = [2.5 2.5]*1e-6;     % In meters
            pixel = [];
            sensor = sensorCreate('human',pixel,params);
            vcAddAndSelectObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: humanConesKLMS1631
        function humanConesKLMS1631_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Human cones | Cones-631
            params.sz = [128,192];
            params.rgbDensities = [0.1 .6 .3 .1]; % Empty, L,M,S
            params.coneAperture = [2.5 2.5]*1e-6;     % In meters
            pixel = [];
            sensor = sensorCreate('human',pixel,params);
            vcAddAndSelectObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuAnBasicE
        function menuAnBasicE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorStats(ieGetObject('sensor'),'basic','electrons');
            return
        end

        % Menu selected function: menuAnBasic
        function menuAnBasicV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            sensorStats(ieGetObject('sensor'),'basic','volts');
            return
        end

        % Menu selected function: menuAnColCCM
        function menuAnColCCM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color | Color Conversion Matrix
            
            sensor = ieGetObject('sensor');
            [L,corners] = sensorCCM(sensor); %#ok<ASGLU>
            
            fprintf('    ==  MCC to XYZ_D65 matrix  ==\n');
            disp(L)
            
            % Store the selection of the corners
            sensor = sensorSet(sensor,'mcc corner points',corners);
            vcReplaceObject(sensor);
            
            return
        end

        % Menu selected function: menuAnColorRB
        function menuAnColorRB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color | RB Analysis
            sensorPlotColor(ieGetObject('ISA'),'rb');
            return
        end

        % Menu selected function: menuAnColorRG
        function menuAnColorRG_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Color | RG Analysis
            sensorPlotColor(ieGetObject('ISA'),'rg');
            return
        end

        % Menu selected function: menuAnColor
        function menuAnColor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuAnComputeFromOI
        function menuAnComputeFromOI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            btnComputeImage_Callback(hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuAnComputeFromScene
        function menuAnComputeFromScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Recompute the sensor data starting all the way back with the current
            % scene and optical image.
            [val,scene] = vcGetSelectedObject('scene');
            if isempty(scene)
                warndlg('Creating default scene');
                scene = sceneCreate; val = 1;
                vcReplaceAndSelectObject(scene,val);
            end
            
            [val,oi] = vcGetSelectedObject('oi');
            if isempty(oi),
                warndlg('Creating default OI');
                oi = oiCreate; val = 1;
            end
            oi = oiCompute(scene,oi);
            vcReplaceAndSelectObject(oi,val);
            
            btnComputeImage_Callback(hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuAnExposureValue
        function menuAnExposureValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | SNR | Exposure Value
            
            oi     = ieGetObject('oi');
            optics = oiGet(oi,'optics');
            sensor = ieGetObject('sensor');
            EV = exposureValue(optics,sensor);
            
            str = sprintf('Exposure value (log2(f/#^2 / T)):    %.2f',EV);
            ieInWindowMessage(str,ieSessionGet('sensorwindowhandles'));
            fprintf('\n\n'); disp(str);
            return
        end

        % Menu selected function: menuAnLineH
        function menuAnLineH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line | Horizontal | Volts
            sensorPlot(ieGetObject('sensor'),'volts hline');
            return
        end

        % Menu selected function: menuAnLineV
        function menuAnLineV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line | Vertical | Electrons
            sensorPlot(ieGetObject('sensor'),'electrons vline');
            %OLD:  sensorPlotLine(ieGetObject('ISA'),'v','volts','space');
            return
        end

        % Menu selected function: menuAnLine
        function menuAnLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze->Line menu
            return
        end

        % Menu selected function: menuAnPOShowUL
        function menuAnPOShowUL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ISA = ieGetObject('ISA');
            mlPrint(sensorGet(ISA,'microLens'));
            return
        end

        % Menu selected function: menuAnPO
        function menuAnPO_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analysis->Pixel Optics
            return
        end

        % Menu selected function: menuAnPhotExp
        function menuAnPhotExp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | SNR | Photometric Exp
            
            str = sprintf('Photometric exposure (lux-sec): %.2f',...
                photometricExposure(ieGetObject('OI'),ieGetObject('ISA')));
            
            % Display in window message
            ieInWindowMessage(str,ieSessionGet('sensorwindowhandles'));
            fprintf('\n\n'); disp(str);
            return
        end

        % Menu selected function: menuAnPixHistQ
        function menuAnPixHistQ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %plotSensorHistogram('e');
            sensorPlot(ieGetObject('sensor'),'electrons histogram');
            return
        end

        % Menu selected function: menuAnHistogramVolts
        function menuAnPixHistV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line | Vertical | Volts
            sensorPlot(ieGetObject('sensor'),'volts histogram');
            return
        end

        % Menu selected function: menuAnPixOptLoadUL
        function menuAnPixOptLoadUL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Pixel Optics | Load uL
            
            fullName = vcSelectDataFile('stayPut','r');
            if isempty(fullName), disp('User canceled'); return; end
            tmp = load(fullName);
            if isfield(tmp,'ml')
                ISA = ieGetObject('ISA');
                ISA = sensorSet(ISA,'microLens',tmp.ml);
                vcReplaceObject(ISA);
            else
                error('No microlens structure (named ml) in the file.');
            end
            
            return
        end

        % Menu selected function: menuAnPixOptSaveUl
        function menuAnPixOptSaveUl_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Pixel Optics | Save uL
            
            ISA = ieGetObject('ISA');
            ml = sensorGet(ISA,'microLens');
            fullName = vcSelectDataFile('stayPut','w');
            if isempty(fullName), disp('User canceled'); return; end
            save(fullName,'ml');
            
            return
        end

        % Menu selected function: menuAnPixelSNR
        function menuAnPixelSNR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Graph the pixel SNR as a function of voltage swing
            sensorPlot(ieGetObject('sensor'),'pixel snr');
            % plotPixelSNR;
            return
        end

        % Menu selected function: menuAnROIStats
        function menuAnROIStats_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analysis->ROI statistics
            return
        end

        % Menu selected function: menuAnSNR
        function menuAnSNR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze SNR menu
            return
        end

        % Menu selected function: menuAnSensorSNR
        function menuAnSensorSNR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            plotSensorSNR;
            return
        end

        % Menu selected function: menuAnalyzeMicroLens
        function menuAnalyzeMicroLens_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Pixel Optics | Microlens window
            if isempty(which('microLensWindow'))
                warndlg('No micro lens analysis software on your path.  Contact ImagEval for a license.');
                return;
            else
                % Should test for license here
            end
            
            microLensWindow;
            return
        end

        % Menu selected function: menuAnalyzePOVignetting
        function menuAnalyzePOVignetting_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Pixel Optics | Relative Illumination
            sensor = ieGetObject('sensor');
            sensorPlot(sensor,'etendue');
            return
        end

        % Menu selected function: menuAnalyze
        function menuAnalyze_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze menu
            return
        end

        % Menu selected function: menuAppNotes
        function menuAppNotes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Documentation (web)
            ieManualViewer('imageval code');
            return
        end

        % Menu selected function: menuColorFilters
        function menuColorFilters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Load Color Filters
            [val,ISA] = vcGetSelectedObject('ISA');
            ISA = sensorReadFilter('colorfilters',ISA);
            ISA = sensorClearData(ISA);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuCopySensor
        function menuCopySensor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('ISA');
            
            newName = ieReadString('New ISA name','new-isa');
            if isempty(newName),  return;
            else    sensor = sensorSet(sensor,'name',newName);
            end
            
            vcAddAndSelectObject('ISA',sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuDesignCFA
        function menuDesignCFA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % I first use the 'Design CFA' option in the Sensor Menu to design the CFA
            % I desire. I make sure I position the individual color filters
            % appropriately in the 2x2 square as 'RGGB'.
            %
            % Then after I load all the respective transmissivity values for each of
            % the filters, I save the CFA to a file (say we call this file
            % micronRGGB.mat).
            %
            % Now during a simulation run, when I load this CFA using the "Load CFA"
            % option in the Sensor menu, the "Standard CFA" cell in the upper right
            % hand side of the ISET-Sensor Window gets automatically updated as "Other"
            % instead of 'bayer-rggb' as I would expect.
            %
            sensorDesignCFA;
            return
        end

        % Menu selected function: menuEdgeOp
        function menuEdgeOp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Start a new process to initiate the edge operator window.
            % This window works with monochrome sensor images and investigate how
            % various operators perform with different types of sensors.
            edgeOperatorWindow;
            return
        end

        % Menu selected function: menuEditClearMessage
        function menuEditClearMessage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ieInWindowMessage('',ieSessionGet('sensorWindowHandles'),[]);
            return
        end

        % Menu selected function: menuEditCreate
        function menuEditCreate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            createNewSensor(app, hObject, eventdata, handles);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorDelete(app, hObject,eventdata,handles);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Change font size
            ieFontSizeSet(handles.sensorImageWindow);
            return
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,sensor] = vcGetSelectedObject('ISA');
            
            newName = ieReadString('New sensor name','new-isa');
            if isempty(newName),  return;
            else    sensor = sensorSet(sensor,'name',newName);
            end
            
            vcReplaceObject(sensor,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuEditResWave
        function menuEditResWave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Resample Wavelength
            isa = ieGetObject('isa');
            isa = sensorResampleWave(isa);
            vcReplaceObject(isa);
            return
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Viewer
            sensor = ieGetObject('ISA');
            img = sensorData2Image(sensor,'volts');
            ieViewer(img);
            return
        end

        % Menu selected function: menuEditZoom
        function menuEditZoom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Zoom
            % Toggle the zoom state.  The zoom state is stored in the status of the
            % checkbox of the zoom menu item.
            if isequal(get(hObject,'checked'),'off'),
                zoom('on');
                set(hObject,'checked','on');
            else % Must be on
                zoom('off');
                set(hObject,'checked','off');
            end
            
            return
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFFTVert
        function menuFFTVert_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlotLine(ieGetObject('sensor'),'v','volts','fft');
            return
        end

        % Menu selected function: menuFFThor
        function menuFFThor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlotLine(ieGetObject('sensor'),'h','volts','fft');
            return
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorClose
            return
        end

        % Menu selected function: menuFileLoadVoltsMat
        function menuFileLoadVoltsMat_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Load Volts (MAT file)
            % Load in new voltage data from a MAT file
            %
            fullName = vcSelectDataFile('stayput','r','mat');
            if isempty(fullName), return; end
            
            tmp = load(fullName,'volts');
            isa = ieGetObject('ISA');
            isa = sensorSet(isa,'volts',tmp.volts);
            
            vcReplaceAndSelectObject(isa);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuFileLoad
        function menuFileLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFileResh
        function menuFileRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuFileSaveVoltsMat
        function menuFileSaveVoltsMat_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Save Volts (MAT file)
            isa = ieGetObject('ISA');
            volts = sensorGet(isa,'volts');
            if isempty(volts), errordlg('No voltage data.'); end
            
            fullName = vcSelectDataFile('stayput','w','mat');
            if ~isempty(fullName), save(fullName,'volts'); end
            
            return
        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFileSpecsheet
        function menuFileSpecsheet_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Spec sheet
            % Write out an Excel (or text?) spec sheet describing the sensor
            %
            
            disp('Spec sheet not yet implemented')
            
            % The idea is to create a set of sensor spec values and then use
            % xlswrite to printout an Excel spreadsheet summarizing them.
            
            return
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuHelpISETOnlineManual
        function menuHelpISETOnlineManual_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | ISET (online)
            ieManualViewer('iset functions')
            return
        end

        % Menu selected function: menuHelpPixelOnline
        function menuHelpPixelOnline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Pixel (online)
            ieManualViewer('pixel functions');
            return
        end

        % Menu selected function: menuHelpSensorOnline
        function menuHelpSensorOnline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Sensor (online)
            ieManualViewer('sensor functions');
            return
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuHorLineDV
        function menuHorLineDV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % sensorPlotLine(ieGetObject('sensor'),'h','dv','space');
            sensorPlot(ieGetObject('sensor'),'dv hline');
            return
        end

        % Menu selected function: menuHorLineE
        function menuHorLineE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line | Horizontal | Electrons
            sensorPlot(ieGetObject('sensor'),'electrons hline');
            return
        end

        % Menu selected function: menuHorizontal
        function menuHorizontal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuISOSat
        function menuISOSat_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            speed = ISOspeed('saturation');
            str = sprintf('ISO speed (saturation):    %.0f\n\n',speed);
            str = [str,sprintf('Measured for a uniform D65 optical image.\n\n')];
            str = [str,sprintf('Larger means saturates at lower lux-sec level.\n\n')];
            fprintf('\n\n');
            disp(str)
            
            hdl = vcNewGraphWin;
            p = get(hdl,'Position');
            set(hdl,'Position',[p(1),p(2),p(3)/2,p(4)/2]);
            set(hdl,'Menubar','none')
            text(0,0.5,str,'fontsize',14); axis off;
            
            return
        end

        % Menu selected function: menuImDSNU
        function menuImDSNU_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'dsnu');
            return
        end

        % Menu selected function: menuImPRNU
        function menuImPRNU_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'prnu');
            return
        end

        % Menu selected function: menuImShotNoise
        function menuImShotNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'shotnoise');
            return
        end

        % Menu selected function: menuInfraRed
        function menuInfraRed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,ISA] = vcGetSelectedObject('ISA');
            ISA = sensorReadFilter('infrared',ISA);
            ISA = sensorClearData(ISA);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuLoadCFA
        function menuLoadCFA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,ISA] = vcGetSelectedObject('ISA');
            ISA = sensorReadFilter('cfa',ISA);
            ISA = sensorClearData(ISA);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuPDQE
        function menuPDQE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,ISA] = vcGetSelectedObject('ISA');
            ISA = sensorReadFilter('pdspectralqe',ISA);
            ISA = sensorClearData(ISA);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuPDSize
        function menuPDSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Design photodetector geometry
            pixelGeometryWindow;
            return
        end

        % Menu selected function: menuPixelLuxSec
        function menuPixelLuxSec_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pixelSNRluxsec;
            return
        end

        % Menu selected function: menuPlotColorFilters
        function menuPlotColorFilters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'color filters');
            return
        end

        % Menu selected function: menuPlotHumanCone
        function menuPlotHumanCone_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Human Cone
            
            sensor = ieGetObject('sensor');
            if sensorCheckHuman(sensor), sensorConePlot(sensor)
            else ieInWindowMessage('Not a human cone sensor',handles,3);
            end
            
            return
        end

        % Menu selected function: menuPlotIR
        function menuPlotIR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'irfilter');
            return
        end

        % Menu selected function: menuPlotNewGraphWindow
        function menuPlotNewGraphWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcNewGraphWin;
            return
        end

        % Menu selected function: menuPlotPDSpectralQE
        function menuPlotPDSpectralQE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'pixel spectral QE');
            return
        end

        % Menu selected function: menuPlotPixelSR
        function menuPlotPixelSR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'pixel spectral sr');
            return
        end

        % Menu selected function: menuPlotSensorImageTSize
        function menuPlotSensorImageTSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | SensorImage (True Size)
            gam      = str2double(get(handles.editGam,'String'));
            scaleMax = get(handles.btnDisplayScale,'Value');
            sensor   = ieGetObject('sensor');
            
            % Get voltages or digital values
            bits     = sensorGet(sensor,'bits');
            if isempty(bits)
                img      = sensorData2Image(sensor,'volts',gam,scaleMax);
            else
                img      = sensorData2Image(sensor,'dv',gam,scaleMax);
            end
            
            if ismatrix(img)
                % imtool needs monochrome images scaled between 0 and 1
                w = vcNewGraphWin; img = img/max(img(:));
                imshow(img); truesize(w);
                set(w,'Name',sensorGet(sensor,'name'));
            else
                ieViewer(img);
            end
            
            
            return
        end

        % Menu selected function: menuPlotSpecResp
        function menuPlotSpecResp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'sensor spectral qe');
            return
        end

        % Menu selected function: menuPlotSpectra
        function menuPlotSpectra_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot-> SpectralInformation
            return
        end

        % Menu selected function: menuPlot
        function menuPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Menu Plot
            return
        end

        % Menu selected function: menuSaveImage
        function menuSaveImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Save Display (RGB Image)
            %
            % An option to save other data types will be needed some day, including DV
            % in particular.
            
            [val,isa] = vcGetSelectedObject('ISA');
            gam = str2double(get(handles.editGam,'String'));
            scaleMax = get(handles.btnDisplayScale,'Value');
            
            sensorSaveImage(isa,[],'volts',gam,scaleMax);
            
            return
        end

        % Menu selected function: menuSensorCDS
        function menuSensorCDS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %  Set check on CDS menu item.  We should probably display this in the
            %  information box at the right, also.
            
            ISA = ieGetObject('ISA');
            state = get(hObject,'Check');
            
            switch state
                case 'on'
                    set(hObject,'Check','off');
                    ISA = sensorSet(ISA,'cds',0);
                case 'off'
                    set(hObject,'Check','on');
                    ISA = sensorSet(ISA,'cds',1);
            end
            vcReplaceObject(ISA);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorCIFHalfInch
        function menuSensorCIFHalfInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('cif'),sensorFormats('halfinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorCIF
        function menuSensorCIF_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            return
        end

        % Menu selected function: menuSensorColFPN
        function menuSensorColFPN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('ISA');
            state = get(hObject,'Check');
            
            switch state
                case 'on'
                    set(hObject,'Check','off');
                    sensor = sensorSet(sensor,'columnFPN',[]);
                    sensor = sensorSet(sensor,'columnDSNU',[]);
                    sensor = sensorSet(sensor,'columnPRNU',[]);
                    % Should we clear the sensor data here?
                case 'off'
                    % Store the column FPN value.
                    set(hObject,'Check','on');
            
                    % Should we pull this out as a separate routine?
                    prompt={'Enter column DSNU (sd in millivolts)', ...
                            'Enter column PRNU (sd. around unity gain)'};
                    def={'1','0.01'};
                    dlgTitle= sprintf('ISET read number');
                    answer = inputdlg(prompt,dlgTitle,2,def);
            
                    if   isempty(answer),  val = []; return;
                    else
                        colOffsetFPN = eval(answer{1})/1000;     % Read in mV, stored in volts
                        colGainFPN   = eval(answer{2});          % Store as sd around unity gain
                    end
            
                    % Create and store the column noise parameters and an instance of
                    % the noise itself
                    nCol = sensorGet(sensor,'cols');
                    colDSNU = randn(1,nCol)*colOffsetFPN;       % Offset noise stored in volts
                    colPRNU = randn(1,nCol)*colGainFPN + 1;             % Column gain noise
            
                    % Set the parameters.  Could combine the two reads into one.
                    sensor = sensorSet(sensor,'columnFPN',[colOffsetFPN,colGainFPN]);
                    sensor = sensorSet(sensor,'columnDSNU',colDSNU);
                    sensor = sensorSet(sensor,'columnPRNU',colPRNU);
            
            end
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorExport
        function menuSensorExport_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Save | Save sensor (.mat)
            [val,isa] = vcGetSelectedObject('ISA');
            fullName = vcExportObject(isa);
            return
        end

        % Menu selected function: menuSensorHumanCones
        function menuSensorHumanCones_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuSensorHumanCones (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            return
        end

        % Menu selected function: menuSensorImport
        function menuSensorImport_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load  | Sensor (.mat)
            %
            newVal = vcImportObject('ISA');
            if isempty(newVal), return; end
            vcSetSelectedObject('ISA',newVal);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorLuxSec
        function menuSensorLuxSec_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorSNRluxsec;
            return
        end

        % Menu selected function: menuSensorMicrolens
        function menuSensorMicrolens_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Design Microlens
            if ~exist('microLensWindow.m','file')
                errordlg('You do not have the optics toolkit.  Please contact ImagEval.');
            else
                microLensWindow;
            end
            
            return
        end

        % Menu selected function: menuSensorPixelVignetting
        function menuSensorPixelVignetting_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Pixel OE Method
            %
            % Set check the Pixel OE computation.
            
            ISA = ieGetObject('ISA');
            
            % Set the vignetting
            pvFlag = sensorGet(ISA,'vignetting');
            str = sprintf('Help: 0=skip,1=bare,2=centered,3=optimal');
            pvFlag = ieReadNumber(str,pvFlag,'%.0f');
            if isempty(pvFlag), return; end
            
            ISA = sensorSet(ISA,'vignetting',pvFlag);
            ISA = sensorSet(ISA,'etendue',[]);
            
            vcReplaceObject(ISA);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorQCIFQuartInch
        function menuSensorQCIFQuartInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % QCIF format, half-inch sensor
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qcif'),sensorFormats('quarterinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorQQCIFQuartInch
        function menuSensorQQCIFQuartInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqcif'),sensorFormats('quarterinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorQQCIFSixteenthInch
        function menuSensorQQCIFSixteenthInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqcif'),sensorFormats('sixteenthinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorQQVGAQuartInch
        function menuSensorQQVGAQuartInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqvga'),sensorFormats('quarterinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorQQVGASixteenthInch
        function menuSensorQQVGASixteenthInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqvga'),sensorFormats('sixteenthinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorQVGAHalfInch
        function menuSensorQVGAHalfInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qvga'),sensorFormats('halfinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorQVGAQuartInch
        function menuSensorQVGAQuartInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qvga'),sensorFormats('quarterinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorSetComputeGrid
        function menuSensorSetComputeGrid_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor | Set Compute Grid
            % Sets pixel samples in signalCurrent
            
            sensor = ieGetObject('ISA');
            currentPixelSamples = sensorGet(sensor,'nSamplesPerPixel');
            nPixelSamples = ieReadNumber('Enter odd integer specifying number of samples/pixel (default=1)',currentPixelSamples,'%.0f');
            
            if isempty(nPixelSamples), return;
            elseif nPixelSamples < 1, nPixelSamples = 1;
            elseif ~mod(nPixelSamples,2), nPixelSamples = nPixelSamples+1;
            end
            
            sensor = sensorSet(sensor,'nSamplesPerPixel',nPixelSamples);
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: menuSensorVGAHalfInch
        function menuSensorVGAHalfInch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('vga'),sensorFormats('halfinch'));
            vcReplaceObject(isa,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Menu selected function: menuSensorVGA
        function menuSensorVGA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Sensor->VGA
            return
        end

        % Menu selected function: menuSensor
        function menuSensor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuVertLineDV
        function menuVertLineDV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % sensorPlotLine(ieGetObject('sensor'),'v','dv','space');
            sensorPlot(ieGetObject('sensor'),'dv vline');
            return
        end

        % Menu selected function: menuVertLineE
        function menuVertLineE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line | Vertical | Electrons
            sensorPlot(ieGetObject('sensor'),'electrons vline');
            return
        end

        % Menu selected function: menuVertical
        function menuVertical_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: plotMccOverlay
        function plotMccOverlay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | MCC overlay off
            % Delete the MCC boxes showing the selection
            
            sensor = ieGetObject('sensor');
            macbethDrawRects(sensor,'off');
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: plotSpecCFApattern
        function plotSpecCFApattern_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Spectral Information | CFA Pattern
            sensorPlot([],'CFA');
            return
        end

        % Value changed function: popISA
        function popISA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Create sensor with one of a set of standard CFA types
            % Called by popup at right that defines the color filter array types.
            %
            % When this change is made, the current data are
            % emptied and the colorOrder, pattern, and unitBlock fields are changed.
            
            % Get the current image sensor array.
            [val,sensor] = vcGetSelectedObject('ISA');
            
            % Read the named CFA choice
            sensorArrayNames = get(hObject,'String');
            thisArrayName = sensorArrayNames{get(hObject,'Value')};
            
            % Depending on the choice, create a default ISA array so you can have the
            % proper fields for copying into the current array.
            switch ieParamFormat(thisArrayName)
                case 'bayerrgb'
                    newSensor = sensorCreate;  % Default is RGB
                case 'bayercmy'
                    newSensor = sensorCreate('cmy');
                case 'rgbw'
                    newSensor = sensorCreate('rgbw');
                case 'monochrome'
                    newSensor = sensorCreate('monochrome');
                otherwise
                    ieInWindowMessage('No Other sensor created.',handles,2);
                    sensorRefresh(app, hObject, eventdata, handles);
                    return;
            end
            
            % Copy the newSensor fields into the currently selected sensor
            sensor = sensorSet(sensor,'name',newSensor.name);
            sensor = sensorSet(sensor,'color',newSensor.color);
            sensor = sensorSet(sensor,'colorfilterarray',newSensor.cfa);
            
            % Empty the data field.
            sensor = sensorClearData(sensor);
            vcReplaceObject(sensor,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: popQuantization
        function popQuantization_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            contents = get(hObject,'String');
            qMethod = contents{get(hObject,'Value')};
            [val,isa] = vcGetSelectedObject('ISA');
            
            isa = sensorSet(isa,'quantization',qMethod);
            
            isa = sensorClearData(isa);
            vcReplaceObject(isa,val);
            
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: popScaleSize
        function popScaleSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            contents = get(hObject,'String');
            factor   = contents{get(hObject,'Value')};
            
            switch lower(factor)
                case 'x'
                    return;
                case 'x 2'
                    s = 2;
                case 'x 4'
                    s = 4;
                case 'x 1/2'
                    s = 1/2;
                case 'x 1/4'
                    s = 1/4;
                otherwise
            end
            
            % Get the current image sensor array
            [val,sensor] = vcGetSelectedObject('ISA');
            
            % Define target size to be consistent with desired scale and CFA
            cfaSize = sensorGet(sensor,'cfaSize');
            targetSize = ceil(s*sensorGet(sensor,'size') ./ cfaSize).* cfaSize;
            
            % If for some reason ceil(sz/cfaSize) is zero, we set size to one pixel
            % cfa.
            if targetSize(1) == 0, targetSize = cfaSize; end
            
            % Set size
            % Data are cleared
            sensor = sensorSet(sensor,'size',targetSize);
            
            vcReplaceObject(sensor,val);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Value changed function: popupExpMode
        function popupExpMode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Exposure popup control
            %
            % Hints: contents = get(hObject,'String') returns popupExpMode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupExpMode
            
            sensor = ieGetObject('sensor');
            
            % Determine which case we popped into
            contents = get(hObject,'String');
            switch contents{get(hObject,'Value')}
                case 'Single'
                    set(handles.btnAutoExp,'visible','on');
                    set(handles.editExpTime,'visible','on');
                    set(handles.btnShowCFAExpDurations,'visible','off');
                    set(handles.editNExposures,'visible','off');
                    set(handles.editExpFactor,'visible','off');
                    set(handles.sliderSelectBracketedExposure,'visible','off');
                    set(handles.txtBracketExposure,'visible','off');
            
                    eTime  = sensorGet(sensor,'geometricMeanExposureTime');
                    sensor = sensorSet(sensor,'expTime',eTime);
            
                case 'Bracketing'
                    set(handles.editExpTime,'visible','on');
                    set(handles.sliderSelectBracketedExposure,'visible','on');
                    set(handles.editNExposures,'visible','on');
                    set(handles.editExpFactor,'visible','on');
                    set(handles.btnShowCFAExpDurations,'visible','off');
            
                    set(handles.btnAutoExp,'visible','off');
                    set(handles.txtBracketExposure,'visible','on');
            
                    % Manage the bracket times
                    sensor = sensorAdjustBracketTimes(app, handles,sensor);
            
                case 'CFA Exposure'
                    set(handles.btnAutoExp,'visible','on');
                    set(handles.btnShowCFAExpDurations,'visible','on');
                    set(handles.editExpTime,'visible','off');
                    set(handles.editNExposures,'visible','off');
                    set(handles.editExpFactor,'visible','off');
                    set(handles.sliderSelectBracketedExposure,'visible','off');
                    set(handles.txtBracketExposure,'visible','off');
            
                    sensor = sensorAdjustCFATimes(app, handles,sensor);
            
                otherwise
                    error('Unknown exposure condition %s\n',contents{get(hObject,'Value')});
            end
            
            sensor = sensorClearData(sensor);
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: popupSelect
        function popupSelect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Main popup window at top.  Create a New ISA or select a different one.
            contents = get(hObject,'String');
            
            switch  (contents{get(hObject,'Value')})
            
                case 'New'
                    createNewSensor(app, hObject, eventdata, handles);
            
                otherwise,
                    % The first two entries is always New.  The selections,
                    % therefore, begin with the entry number in the list - 1.
                    val = get(hObject,'Value') - 1;
                    vcSetSelectedObject('ISA',val);
                    sensorRefresh(app, hObject, eventdata, handles);
            end
            
            return
        end

        % Menu selected function: sensorEditClearData
        function sensorEditClearData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | clear data
            [val,ISA] = vcGetSelectedObject('ISA');
            ISA = sensorClearData(ISA);
            vcReplaceObject(ISA,val);
            sensorRefresh(app, hObject, eventdata, handles);
            return
        end

        % Value changed function: sliderSelectBracketedExposure
        function sliderSelectBracketedExposure_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Slider on the lower left of the window
            % Chooses which of the exposures to display
            sensor = ieGetObject('sensor');
            
            exposurePlane = get(handles.sliderSelectBracketedExposure,'value');
            sensor = sensorSet(sensor,'exposurePlane',exposurePlane);
            sensor = sensorSet(sensor,'consistency',-1);  % Don't change the red consistency button
            
            vcReplaceObject(sensor);
            sensorRefresh(app, hObject, eventdata, handles);
            
            return
        end

        % Menu selected function: LoadSaveSensorMenu
        function LoadSaveSensorMenuSelected(app, event)

            ieSetProperties('sensor');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.sensorImageWindow = uifigure('Visible', 'off');
            app.sensorImageWindow.Name = 'ISET-Sensor';
            app.sensorImageWindow.Tag = 'sensorImageWindow';
            app.menuFile = uimenu(app.sensorImageWindow);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileLoad = uimenu(app.menuFile);
            app.menuFileLoad.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoad_Callback, true);
            app.menuFileLoad.Text = 'Load';
            app.menuFileLoad.Tag = 'menuFileLoad';
            app.menuSensorImport = uimenu(app.menuFileLoad);
            app.menuSensorImport.MenuSelectedFcn = createCallbackFcn(app, @menuSensorImport_Callback, true);
            app.menuSensorImport.Text = 'Load sensor (.mat)';
            app.menuSensorImport.Tag = 'menuSensorImport';
            app.menuFileLoadVoltsMat = uimenu(app.menuFileLoad);
            app.menuFileLoadVoltsMat.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadVoltsMat_Callback, true);
            app.menuFileLoadVoltsMat.Separator = 'on';
            app.menuFileLoadVoltsMat.Text = 'Load volts (.mat)';
            app.menuFileLoadVoltsMat.Tag = 'menuFileLoadVoltsMat';
            app.menuFileSave = uimenu(app.menuFile);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Text = 'Save';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuSensorExport = uimenu(app.menuFileSave);
            app.menuSensorExport.MenuSelectedFcn = createCallbackFcn(app, @menuSensorExport_Callback, true);
            app.menuSensorExport.Text = 'Save sensor (.mat)';
            app.menuSensorExport.Tag = 'menuSensorExport';
            app.menuFileSaveVoltsMat = uimenu(app.menuFileSave);
            app.menuFileSaveVoltsMat.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveVoltsMat_Callback, true);
            app.menuFileSaveVoltsMat.Text = 'Save volts (.mat)';
            app.menuFileSaveVoltsMat.Tag = 'menuFileSaveVoltsMat';
            app.menuSaveImage = uimenu(app.menuFileSave);
            app.menuSaveImage.MenuSelectedFcn = createCallbackFcn(app, @menuSaveImage_Callback, true);
            app.menuSaveImage.Separator = 'on';
            app.menuSaveImage.Text = 'Save display (.tif)';
            app.menuSaveImage.Tag = 'menuSaveImage';
            app.menuFileSpecsheet = uimenu(app.menuFile);
            app.menuFileSpecsheet.MenuSelectedFcn = createCallbackFcn(app, @menuFileSpecsheet_Callback, true);
            app.menuFileSpecsheet.Separator = 'on';
            app.menuFileSpecsheet.Text = 'Spec sheet';
            app.menuFileSpecsheet.Tag = 'menuFileSpecsheet';
            app.menuFileResh = uimenu(app.menuFile);
            app.menuFileResh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileResh.Separator = 'on';
            app.menuFileResh.Text = 'Refresh';
            app.menuFileResh.Tag = 'menuFileResh';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.sensorImageWindow);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditName = uimenu(app.menuEdit);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = '(Re-) Name sensor';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditCreate = uimenu(app.menuEdit);
            app.menuEditCreate.MenuSelectedFcn = createCallbackFcn(app, @menuEditCreate_Callback, true);
            app.menuEditCreate.Separator = 'on';
            app.menuEditCreate.Text = 'Create new sensor';
            app.menuEditCreate.Tag = 'menuEditCreate';
            app.menuCopySensor = uimenu(app.menuEdit);
            app.menuCopySensor.MenuSelectedFcn = createCallbackFcn(app, @menuCopySensor_Callback, true);
            app.menuCopySensor.Text = 'Copy current sensor';
            app.menuCopySensor.Tag = 'menuCopySensor';
            app.menuEditDelete = uimenu(app.menuEdit);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current sensor';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.editDeleteSome = uimenu(app.menuEdit);
            app.editDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @editDeleteSome_Callback, true);
            app.editDeleteSome.Text = 'Delete some sensors';
            app.editDeleteSome.Tag = 'editDeleteSome';
            app.menuEditResWave = uimenu(app.menuEdit);
            app.menuEditResWave.MenuSelectedFcn = createCallbackFcn(app, @menuEditResWave_Callback, true);
            app.menuEditResWave.Separator = 'on';
            app.menuEditResWave.Text = 'Resample wavelength';
            app.menuEditResWave.Tag = 'menuEditResWave';
            app.sensorEditClearData = uimenu(app.menuEdit);
            app.sensorEditClearData.MenuSelectedFcn = createCallbackFcn(app, @sensorEditClearData_Callback, true);
            app.sensorEditClearData.Text = 'Clear data';
            app.sensorEditClearData.Tag = 'sensorEditClearData';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditClearMessage = uimenu(app.menuEdit);
            app.menuEditClearMessage.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearMessage_Callback, true);
            app.menuEditClearMessage.Text = 'Clear window message';
            app.menuEditClearMessage.Tag = 'menuEditClearMessage';
            app.menuEditZoom = uimenu(app.menuEdit);
            app.menuEditZoom.MenuSelectedFcn = createCallbackFcn(app, @menuEditZoom_Callback, true);
            app.menuEditZoom.Separator = 'on';
            app.menuEditZoom.Text = 'Zoom';
            app.menuEditZoom.Tag = 'menuEditZoom';
            app.menuEditViewer = uimenu(app.menuEdit);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Separator = 'on';
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.LoadSaveSensorMenu = uimenu(app.menuEdit);
            app.LoadSaveSensorMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSaveSensorMenuSelected, true);
            app.LoadSaveSensorMenu.Text = 'Load / Save Sensor...';
            app.menuPlot = uimenu(app.sensorImageWindow);
            app.menuPlot.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_Callback, true);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuImDSNU = uimenu(app.menuPlot);
            app.menuImDSNU.MenuSelectedFcn = createCallbackFcn(app, @menuImDSNU_Callback, true);
            app.menuImDSNU.Text = 'DSNU';
            app.menuImDSNU.Tag = 'menuImDSNU';
            app.menuImPRNU = uimenu(app.menuPlot);
            app.menuImPRNU.MenuSelectedFcn = createCallbackFcn(app, @menuImPRNU_Callback, true);
            app.menuImPRNU.Text = 'PRNU';
            app.menuImPRNU.Tag = 'menuImPRNU';
            app.menuImShotNoise = uimenu(app.menuPlot);
            app.menuImShotNoise.MenuSelectedFcn = createCallbackFcn(app, @menuImShotNoise_Callback, true);
            app.menuImShotNoise.Text = 'Shot noise';
            app.menuImShotNoise.Tag = 'menuImShotNoise';
            app.menuPlotSpectra = uimenu(app.menuPlot);
            app.menuPlotSpectra.MenuSelectedFcn = createCallbackFcn(app, @menuPlotSpectra_Callback, true);
            app.menuPlotSpectra.Separator = 'on';
            app.menuPlotSpectra.Text = 'Spectral information';
            app.menuPlotSpectra.Tag = 'menuPlotSpectra';
            app.menuPlotColorFilters = uimenu(app.menuPlotSpectra);
            app.menuPlotColorFilters.MenuSelectedFcn = createCallbackFcn(app, @menuPlotColorFilters_Callback, true);
            app.menuPlotColorFilters.Text = 'Color filters';
            app.menuPlotColorFilters.Tag = 'menuPlotColorFilters';
            app.menuPlotIR = uimenu(app.menuPlotSpectra);
            app.menuPlotIR.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIR_Callback, true);
            app.menuPlotIR.Text = 'Other filter (e.g., infrared)';
            app.menuPlotIR.Tag = 'menuPlotIR';
            app.plotSpecCFApattern = uimenu(app.menuPlotSpectra);
            app.plotSpecCFApattern.MenuSelectedFcn = createCallbackFcn(app, @plotSpecCFApattern_Callback, true);
            app.plotSpecCFApattern.Text = 'CFA pattern';
            app.plotSpecCFApattern.Tag = 'plotSpecCFApattern';
            app.menuPlotPDSpectralQE = uimenu(app.menuPlotSpectra);
            app.menuPlotPDSpectralQE.MenuSelectedFcn = createCallbackFcn(app, @menuPlotPDSpectralQE_Callback, true);
            app.menuPlotPDSpectralQE.Text = 'PD spectral QE';
            app.menuPlotPDSpectralQE.Tag = 'menuPlotPDSpectralQE';
            app.menuPlotSpecResp = uimenu(app.menuPlotSpectra);
            app.menuPlotSpecResp.MenuSelectedFcn = createCallbackFcn(app, @menuPlotSpecResp_Callback, true);
            app.menuPlotSpecResp.Text = 'Sensor spectral QE';
            app.menuPlotSpecResp.Tag = 'menuPlotSpecResp';
            app.menuPlotPixelSR = uimenu(app.menuPlotSpectra);
            app.menuPlotPixelSR.MenuSelectedFcn = createCallbackFcn(app, @menuPlotPixelSR_Callback, true);
            app.menuPlotPixelSR.Separator = 'on';
            app.menuPlotPixelSR.Text = 'Pixel SR (amps/watt)';
            app.menuPlotPixelSR.Tag = 'menuPlotPixelSR';
            app.menuPlotSensorImageTSize = uimenu(app.menuPlot);
            app.menuPlotSensorImageTSize.MenuSelectedFcn = createCallbackFcn(app, @menuPlotSensorImageTSize_Callback, true);
            app.menuPlotSensorImageTSize.Text = 'Sensor image (true size)';
            app.menuPlotSensorImageTSize.Tag = 'menuPlotSensorImageTSize';
            app.plotMccOverlay = uimenu(app.menuPlot);
            app.plotMccOverlay.MenuSelectedFcn = createCallbackFcn(app, @plotMccOverlay_Callback, true);
            app.plotMccOverlay.Text = 'MCC overlay off';
            app.plotMccOverlay.Tag = 'plotMccOverlay';
            app.menuPlotHumanCone = uimenu(app.menuPlot);
            app.menuPlotHumanCone.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHumanCone_Callback, true);
            app.menuPlotHumanCone.Separator = 'on';
            app.menuPlotHumanCone.Text = 'Human cone';
            app.menuPlotHumanCone.Tag = 'menuPlotHumanCone';
            app.menuPlotNewGraphWindow = uimenu(app.menuPlot);
            app.menuPlotNewGraphWindow.MenuSelectedFcn = createCallbackFcn(app, @menuPlotNewGraphWindow_Callback, true);
            app.menuPlotNewGraphWindow.Separator = 'on';
            app.menuPlotNewGraphWindow.Text = 'New graph window';
            app.menuPlotNewGraphWindow.Tag = 'menuPlotNewGraphWindow';
            app.menuSensor = uimenu(app.sensorImageWindow);
            app.menuSensor.MenuSelectedFcn = createCallbackFcn(app, @menuSensor_Callback, true);
            app.menuSensor.Text = 'Sensor';
            app.menuSensor.Tag = 'menuSensor';
            app.menuSensorCIF = uimenu(app.menuSensor);
            app.menuSensorCIF.MenuSelectedFcn = createCallbackFcn(app, @menuSensorCIF_Callback, true);
            app.menuSensorCIF.Text = 'CIF formats';
            app.menuSensorCIF.Tag = 'menuSensorCIF';
            app.menuSensorQQCIFSixteenthInch = uimenu(app.menuSensorCIF);
            app.menuSensorQQCIFSixteenthInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQQCIFSixteenthInch_Callback, true);
            app.menuSensorQQCIFSixteenthInch.Text = 'Q-Q-CIF (1/16")';
            app.menuSensorQQCIFSixteenthInch.Tag = 'menuSensorQQCIFSixteenthInch';
            app.menuSensorQQCIFQuartInch = uimenu(app.menuSensorCIF);
            app.menuSensorQQCIFQuartInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQQCIFQuartInch_Callback, true);
            app.menuSensorQQCIFQuartInch.Text = 'Q-Q-CIF (1/4 ")';
            app.menuSensorQQCIFQuartInch.Tag = 'menuSensorQQCIFQuartInch';
            app.menuSensorQCIFQuartInch = uimenu(app.menuSensorCIF);
            app.menuSensorQCIFQuartInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQCIFQuartInch_Callback, true);
            app.menuSensorQCIFQuartInch.Text = 'QCIF (1/4")';
            app.menuSensorQCIFQuartInch.Tag = 'menuSensorQCIFQuartInch';
            app.menuSensorCIFHalfInch = uimenu(app.menuSensorCIF);
            app.menuSensorCIFHalfInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorCIFHalfInch_Callback, true);
            app.menuSensorCIFHalfInch.Text = 'CIF (1/2")';
            app.menuSensorCIFHalfInch.Tag = 'menuSensorCIFHalfInch';
            app.menuSensorVGA = uimenu(app.menuSensor);
            app.menuSensorVGA.MenuSelectedFcn = createCallbackFcn(app, @menuSensorVGA_Callback, true);
            app.menuSensorVGA.Text = 'VGA formats';
            app.menuSensorVGA.Tag = 'menuSensorVGA';
            app.menuSensorQQVGASixteenthInch = uimenu(app.menuSensorVGA);
            app.menuSensorQQVGASixteenthInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQQVGASixteenthInch_Callback, true);
            app.menuSensorQQVGASixteenthInch.Text = 'Q-Q-VGA (1/16")';
            app.menuSensorQQVGASixteenthInch.Tag = 'menuSensorQQVGASixteenthInch';
            app.menuSensorQQVGAQuartInch = uimenu(app.menuSensorVGA);
            app.menuSensorQQVGAQuartInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQQVGAQuartInch_Callback, true);
            app.menuSensorQQVGAQuartInch.Text = 'Q-Q-VGA (1/4")';
            app.menuSensorQQVGAQuartInch.Tag = 'menuSensorQQVGAQuartInch';
            app.menuSensorQVGAQuartInch = uimenu(app.menuSensorVGA);
            app.menuSensorQVGAQuartInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQVGAQuartInch_Callback, true);
            app.menuSensorQVGAQuartInch.Text = 'QVGA (1/4")';
            app.menuSensorQVGAQuartInch.Tag = 'menuSensorQVGAQuartInch';
            app.menuSensorQVGAHalfInch = uimenu(app.menuSensorVGA);
            app.menuSensorQVGAHalfInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorQVGAHalfInch_Callback, true);
            app.menuSensorQVGAHalfInch.Text = 'QVGA (1/2")';
            app.menuSensorQVGAHalfInch.Tag = 'menuSensorQVGAHalfInch';
            app.menuSensorVGAHalfInch = uimenu(app.menuSensorVGA);
            app.menuSensorVGAHalfInch.MenuSelectedFcn = createCallbackFcn(app, @menuSensorVGAHalfInch_Callback, true);
            app.menuSensorVGAHalfInch.Text = 'VGA (1/2")';
            app.menuSensorVGAHalfInch.Tag = 'menuSensorVGAHalfInch';
            app.menuSensorHumanCones = uimenu(app.menuSensor);
            app.menuSensorHumanCones.MenuSelectedFcn = createCallbackFcn(app, @menuSensorHumanCones_Callback, true);
            app.menuSensorHumanCones.Text = 'Human cones';
            app.menuSensorHumanCones.Tag = 'menuSensorHumanCones';
            app.humanCones631 = uimenu(app.menuSensorHumanCones);
            app.humanCones631.MenuSelectedFcn = createCallbackFcn(app, @humanCones631_Callback, true);
            app.humanCones631.Text = 'Cones-LMS-631';
            app.humanCones631.Tag = 'humanCones631';
            app.humanConesKLMS1631 = uimenu(app.menuSensorHumanCones);
            app.humanConesKLMS1631.MenuSelectedFcn = createCallbackFcn(app, @humanConesKLMS1631_Callback, true);
            app.humanConesKLMS1631.Text = 'Cones-KLMS-1631';
            app.humanConesKLMS1631.Tag = 'humanConesKLMS1631';
            app.menuLoadCFA = uimenu(app.menuSensor);
            app.menuLoadCFA.MenuSelectedFcn = createCallbackFcn(app, @menuLoadCFA_Callback, true);
            app.menuLoadCFA.Separator = 'on';
            app.menuLoadCFA.Text = 'Load CFA';
            app.menuLoadCFA.Tag = 'menuLoadCFA';
            app.menuColorFilters = uimenu(app.menuSensor);
            app.menuColorFilters.MenuSelectedFcn = createCallbackFcn(app, @menuColorFilters_Callback, true);
            app.menuColorFilters.Text = 'Load color filters';
            app.menuColorFilters.Tag = 'menuColorFilters';
            app.menuPDQE = uimenu(app.menuSensor);
            app.menuPDQE.MenuSelectedFcn = createCallbackFcn(app, @menuPDQE_Callback, true);
            app.menuPDQE.Text = 'Load photodetector QE';
            app.menuPDQE.Tag = 'menuPDQE';
            app.menuInfraRed = uimenu(app.menuSensor);
            app.menuInfraRed.MenuSelectedFcn = createCallbackFcn(app, @menuInfraRed_Callback, true);
            app.menuInfraRed.Text = 'Load infrared filter';
            app.menuInfraRed.Tag = 'menuInfraRed';
            app.menuPDSize = uimenu(app.menuSensor);
            app.menuPDSize.MenuSelectedFcn = createCallbackFcn(app, @menuPDSize_Callback, true);
            app.menuPDSize.Separator = 'on';
            app.menuPDSize.Text = 'Design photodetector geometry';
            app.menuPDSize.Tag = 'menuPDSize';
            app.menuSensorMicrolens = uimenu(app.menuSensor);
            app.menuSensorMicrolens.MenuSelectedFcn = createCallbackFcn(app, @menuSensorMicrolens_Callback, true);
            app.menuSensorMicrolens.Text = 'Design microlens';
            app.menuSensorMicrolens.Tag = 'menuSensorMicrolens';
            app.menuDesignCFA = uimenu(app.menuSensor);
            app.menuDesignCFA.MenuSelectedFcn = createCallbackFcn(app, @menuDesignCFA_Callback, true);
            app.menuDesignCFA.Text = 'Design CFA';
            app.menuDesignCFA.Tag = 'menuDesignCFA';
            app.menuSensorCDS = uimenu(app.menuSensor);
            app.menuSensorCDS.MenuSelectedFcn = createCallbackFcn(app, @menuSensorCDS_Callback, true);
            app.menuSensorCDS.Separator = 'on';
            app.menuSensorCDS.Text = 'CDS On/Off';
            app.menuSensorCDS.Tag = 'menuSensorCDS';
            app.menuSensorColFPN = uimenu(app.menuSensor);
            app.menuSensorColFPN.MenuSelectedFcn = createCallbackFcn(app, @menuSensorColFPN_Callback, true);
            app.menuSensorColFPN.Text = 'Column FPN';
            app.menuSensorColFPN.Tag = 'menuSensorColFPN';
            app.menuSensorPixelVignetting = uimenu(app.menuSensor);
            app.menuSensorPixelVignetting.MenuSelectedFcn = createCallbackFcn(app, @menuSensorPixelVignetting_Callback, true);
            app.menuSensorPixelVignetting.Text = 'Pixel OE method';
            app.menuSensorPixelVignetting.Tag = 'menuSensorPixelVignetting';
            app.menuSensorSetComputeGrid = uimenu(app.menuSensor);
            app.menuSensorSetComputeGrid.MenuSelectedFcn = createCallbackFcn(app, @menuSensorSetComputeGrid_Callback, true);
            app.menuSensorSetComputeGrid.Text = 'Set compute grid';
            app.menuSensorSetComputeGrid.Tag = 'menuSensorSetComputeGrid';
            app.menuAnalyze = uimenu(app.sensorImageWindow);
            app.menuAnalyze.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyze_Callback, true);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.menuAnLine = uimenu(app.menuAnalyze);
            app.menuAnLine.MenuSelectedFcn = createCallbackFcn(app, @menuAnLine_Callback, true);
            app.menuAnLine.Text = 'Line';
            app.menuAnLine.Tag = 'menuAnLine';
            app.menuHorizontal = uimenu(app.menuAnLine);
            app.menuHorizontal.MenuSelectedFcn = createCallbackFcn(app, @menuHorizontal_Callback, true);
            app.menuHorizontal.Text = 'Horizontal';
            app.menuHorizontal.Tag = 'menuHorizontal';
            app.menuAnLineH = uimenu(app.menuHorizontal);
            app.menuAnLineH.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineH_Callback, true);
            app.menuAnLineH.Text = 'Volts';
            app.menuAnLineH.Tag = 'menuAnLineH';
            app.menuHorLineE = uimenu(app.menuHorizontal);
            app.menuHorLineE.MenuSelectedFcn = createCallbackFcn(app, @menuHorLineE_Callback, true);
            app.menuHorLineE.Text = 'Electrons';
            app.menuHorLineE.Tag = 'menuHorLineE';
            app.menuHorLineDV = uimenu(app.menuHorizontal);
            app.menuHorLineDV.MenuSelectedFcn = createCallbackFcn(app, @menuHorLineDV_Callback, true);
            app.menuHorLineDV.Text = 'Digtal count';
            app.menuHorLineDV.Tag = 'menuHorLineDV';
            app.menuFFThor = uimenu(app.menuHorizontal);
            app.menuFFThor.MenuSelectedFcn = createCallbackFcn(app, @menuFFThor_Callback, true);
            app.menuFFThor.Text = 'FFT amp';
            app.menuFFThor.Tag = 'menuFFThor';
            app.menuVertical = uimenu(app.menuAnLine);
            app.menuVertical.MenuSelectedFcn = createCallbackFcn(app, @menuVertical_Callback, true);
            app.menuVertical.Text = 'Vertical';
            app.menuVertical.Tag = 'menuVertical';
            app.menuAnLineV = uimenu(app.menuVertical);
            app.menuAnLineV.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineV_Callback, true);
            app.menuAnLineV.Text = 'Volts';
            app.menuAnLineV.Tag = 'menuAnLineV';
            app.menuVertLineE = uimenu(app.menuVertical);
            app.menuVertLineE.MenuSelectedFcn = createCallbackFcn(app, @menuVertLineE_Callback, true);
            app.menuVertLineE.Text = 'Electrons';
            app.menuVertLineE.Tag = 'menuVertLineE';
            app.menuVertLineDV = uimenu(app.menuVertical);
            app.menuVertLineDV.MenuSelectedFcn = createCallbackFcn(app, @menuVertLineDV_Callback, true);
            app.menuVertLineDV.Text = 'Digital count';
            app.menuVertLineDV.Tag = 'menuVertLineDV';
            app.menuFFTVert = uimenu(app.menuVertical);
            app.menuFFTVert.MenuSelectedFcn = createCallbackFcn(app, @menuFFTVert_Callback, true);
            app.menuFFTVert.Text = 'FFT amp';
            app.menuFFTVert.Tag = 'menuFFTVert';
            app.menuAnROIStats = uimenu(app.menuAnalyze);
            app.menuAnROIStats.MenuSelectedFcn = createCallbackFcn(app, @menuAnROIStats_Callback, true);
            app.menuAnROIStats.Text = 'ROI';
            app.menuAnROIStats.Tag = 'menuAnROIStats';
            app.menuAnBasic = uimenu(app.menuAnROIStats);
            app.menuAnBasic.MenuSelectedFcn = createCallbackFcn(app, @menuAnBasicV_Callback, true);
            app.menuAnBasic.Text = 'Mean, SD (v)';
            app.menuAnBasic.Tag = 'menuAnBasic';
            app.menuAnBasicE = uimenu(app.menuAnROIStats);
            app.menuAnBasicE.MenuSelectedFcn = createCallbackFcn(app, @menuAnBasicE_Callback, true);
            app.menuAnBasicE.Text = 'Mean, SD (e-)';
            app.menuAnBasicE.Tag = 'menuAnBasicE';
            app.menuAnHistogramVolts = uimenu(app.menuAnROIStats);
            app.menuAnHistogramVolts.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixHistV_Callback, true);
            app.menuAnHistogramVolts.Separator = 'on';
            app.menuAnHistogramVolts.Text = 'Histogram (v)';
            app.menuAnHistogramVolts.Tag = 'menuAnHistogramVolts';
            app.menuAnPixHistQ = uimenu(app.menuAnROIStats);
            app.menuAnPixHistQ.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixHistQ_Callback, true);
            app.menuAnPixHistQ.Text = 'Histogram (e-)';
            app.menuAnPixHistQ.Tag = 'menuAnPixHistQ';
            app.menuAnPO = uimenu(app.menuAnalyze);
            app.menuAnPO.MenuSelectedFcn = createCallbackFcn(app, @menuAnPO_Callback, true);
            app.menuAnPO.Text = 'Pixel optics';
            app.menuAnPO.Tag = 'menuAnPO';
            app.menuAnalyzePOVignetting = uimenu(app.menuAnPO);
            app.menuAnalyzePOVignetting.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzePOVignetting_Callback, true);
            app.menuAnalyzePOVignetting.Text = 'Relative illumination';
            app.menuAnalyzePOVignetting.Tag = 'menuAnalyzePOVignetting';
            app.menuAnalyzeMicroLens = uimenu(app.menuAnPO);
            app.menuAnalyzeMicroLens.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeMicroLens_Callback, true);
            app.menuAnalyzeMicroLens.Separator = 'on';
            app.menuAnalyzeMicroLens.Text = 'Micro lens window';
            app.menuAnalyzeMicroLens.Tag = 'menuAnalyzeMicroLens';
            app.menuAnPixOptLoadUL = uimenu(app.menuAnPO);
            app.menuAnPixOptLoadUL.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixOptLoadUL_Callback, true);
            app.menuAnPixOptLoadUL.Separator = 'on';
            app.menuAnPixOptLoadUL.Text = 'Load uL ';
            app.menuAnPixOptLoadUL.Tag = 'menuAnPixOptLoadUL';
            app.menuAnPixOptSaveUl = uimenu(app.menuAnPO);
            app.menuAnPixOptSaveUl.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixOptSaveUl_Callback, true);
            app.menuAnPixOptSaveUl.Text = 'Save uL';
            app.menuAnPixOptSaveUl.Tag = 'menuAnPixOptSaveUl';
            app.menuAnPOShowUL = uimenu(app.menuAnPO);
            app.menuAnPOShowUL.MenuSelectedFcn = createCallbackFcn(app, @menuAnPOShowUL_Callback, true);
            app.menuAnPOShowUL.Text = 'Show uL properties';
            app.menuAnPOShowUL.Tag = 'menuAnPOShowUL';
            app.menuAnSNR = uimenu(app.menuAnalyze);
            app.menuAnSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnSNR_Callback, true);
            app.menuAnSNR.Text = 'SNR';
            app.menuAnSNR.Tag = 'menuAnSNR';
            app.menuAnPixelSNR = uimenu(app.menuAnSNR);
            app.menuAnPixelSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixelSNR_Callback, true);
            app.menuAnPixelSNR.Text = 'Pixel vs. volts';
            app.menuAnPixelSNR.Tag = 'menuAnPixelSNR';
            app.menuAnSensorSNR = uimenu(app.menuAnSNR);
            app.menuAnSensorSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnSensorSNR_Callback, true);
            app.menuAnSensorSNR.Text = 'Sensor vs. volts';
            app.menuAnSensorSNR.Tag = 'menuAnSensorSNR';
            app.menuPixelLuxSec = uimenu(app.menuAnSNR);
            app.menuPixelLuxSec.MenuSelectedFcn = createCallbackFcn(app, @menuPixelLuxSec_Callback, true);
            app.menuPixelLuxSec.Separator = 'on';
            app.menuPixelLuxSec.Text = 'Pixel vs. lux-Sec';
            app.menuPixelLuxSec.Tag = 'menuPixelLuxSec';
            app.menuSensorLuxSec = uimenu(app.menuAnSNR);
            app.menuSensorLuxSec.MenuSelectedFcn = createCallbackFcn(app, @menuSensorLuxSec_Callback, true);
            app.menuSensorLuxSec.Text = 'Sensor  vs. lux-Sec';
            app.menuSensorLuxSec.Tag = 'menuSensorLuxSec';
            app.menuISOSat = uimenu(app.menuAnSNR);
            app.menuISOSat.MenuSelectedFcn = createCallbackFcn(app, @menuISOSat_Callback, true);
            app.menuISOSat.Separator = 'on';
            app.menuISOSat.Text = 'ISO speed (sat)';
            app.menuISOSat.Tag = 'menuISOSat';
            app.menuAnExposureValue = uimenu(app.menuAnSNR);
            app.menuAnExposureValue.MenuSelectedFcn = createCallbackFcn(app, @menuAnExposureValue_Callback, true);
            app.menuAnExposureValue.Text = 'Exposure value';
            app.menuAnExposureValue.Tag = 'menuAnExposureValue';
            app.menuAnPhotExp = uimenu(app.menuAnSNR);
            app.menuAnPhotExp.MenuSelectedFcn = createCallbackFcn(app, @menuAnPhotExp_Callback, true);
            app.menuAnPhotExp.Text = 'Photometric exp (lux-sec)';
            app.menuAnPhotExp.Tag = 'menuAnPhotExp';
            app.menuAnColor = uimenu(app.menuAnalyze);
            app.menuAnColor.MenuSelectedFcn = createCallbackFcn(app, @menuAnColor_Callback, true);
            app.menuAnColor.Separator = 'on';
            app.menuAnColor.Text = 'Color';
            app.menuAnColor.Tag = 'menuAnColor';
            app.menuAnColorRB = uimenu(app.menuAnColor);
            app.menuAnColorRB.MenuSelectedFcn = createCallbackFcn(app, @menuAnColorRB_Callback, true);
            app.menuAnColorRB.Text = 'RB analysis';
            app.menuAnColorRB.Tag = 'menuAnColorRB';
            app.menuAnColorRG = uimenu(app.menuAnColor);
            app.menuAnColorRG.MenuSelectedFcn = createCallbackFcn(app, @menuAnColorRG_Callback, true);
            app.menuAnColorRG.Text = 'RG analysis';
            app.menuAnColorRG.Tag = 'menuAnColorRG';
            app.menuAnColCCM = uimenu(app.menuAnColor);
            app.menuAnColCCM.MenuSelectedFcn = createCallbackFcn(app, @menuAnColCCM_Callback, true);
            app.menuAnColCCM.Text = 'MCC to XYZ_D65 matrix';
            app.menuAnColCCM.Tag = 'menuAnColCCM';
            app.menuEdgeOp = uimenu(app.menuAnalyze);
            app.menuEdgeOp.MenuSelectedFcn = createCallbackFcn(app, @menuEdgeOp_Callback, true);
            app.menuEdgeOp.Separator = 'on';
            app.menuEdgeOp.Text = 'Edge window';
            app.menuEdgeOp.Tag = 'menuEdgeOp';
            app.menuAnComputeFromOI = uimenu(app.menuAnalyze);
            app.menuAnComputeFromOI.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromOI_Callback, true);
            app.menuAnComputeFromOI.Separator = 'on';
            app.menuAnComputeFromOI.Text = 'Compute from OI';
            app.menuAnComputeFromOI.Tag = 'menuAnComputeFromOI';
            app.menuAnComputeFromScene = uimenu(app.menuAnalyze);
            app.menuAnComputeFromScene.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromScene_Callback, true);
            app.menuAnComputeFromScene.Text = 'Compute from scene';
            app.menuAnComputeFromScene.Tag = 'menuAnComputeFromScene';
            app.menuHelp = uimenu(app.sensorImageWindow);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpSensorOnline = uimenu(app.menuHelp);
            app.menuHelpSensorOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpSensorOnline_Callback, true);
            app.menuHelpSensorOnline.Text = 'Sensor functions';
            app.menuHelpSensorOnline.Tag = 'menuHelpSensorOnline';
            app.menuHelpPixelOnline = uimenu(app.menuHelp);
            app.menuHelpPixelOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpPixelOnline_Callback, true);
            app.menuHelpPixelOnline.Text = 'Pixel functions';
            app.menuHelpPixelOnline.Tag = 'menuHelpPixelOnline';
            app.menuHelpISETOnlineManual = uimenu(app.menuHelp);
            app.menuHelpISETOnlineManual.MenuSelectedFcn = createCallbackFcn(app, @menuHelpISETOnlineManual_Callback, true);
            app.menuHelpISETOnlineManual.Text = 'ISET functions';
            app.menuHelpISETOnlineManual.Tag = 'menuHelpISETOnlineManual';
            app.menuAppNotes = uimenu(app.menuHelp);
            app.menuAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuAppNotes_Callback, true);
            app.menuAppNotes.Text = 'Documentation (web)';
            app.menuAppNotes.Tag = 'menuAppNotes';
            app.axes2 = uiaxes(app.sensorImageWindow);
            app.axes2.XLim = [0 1];
            app.axes2.YLim = [0 1];
            app.axes2.ZLim = [0 1];
            app.axes2.CLim = [0 1];
            app.axes2.ALim = [0 1];
            app.axes2.XTickLabel = '';
            app.axes2.YTick = zeros(1,0);
            app.axes2.YTickLabel = '';
            app.axes2.ZTick = [0 0.5 1];
            app.axes2.ZTickLabel = '';
            app.axes2.Tag = 'axes2';
            app.imgCFA = uiaxes(app.sensorImageWindow);
            app.imgCFA.Tag = 'imgCFA';
            app.ExposurePanel = uipanel(app.sensorImageWindow);
            app.ExposurePanel.Tag = 'ExposurePanel';
            app.btnShowCFAExpDurations = uibutton(app.ExposurePanel, 'push');
            app.btnShowCFAExpDurations.ButtonPushedFcn = createCallbackFcn(app, @btnShowCFAExpDurations_Callback, true);
            app.btnShowCFAExpDurations.Tag = 'btnShowCFAExpDurations';
            app.btnShowCFAExpDurations.Visible = 'off';
            app.btnShowCFAExpDurations.Tooltip = 'Show pattern of cfa exposures';
            app.btnShowCFAExpDurations.Text = 'CFA Exps';
            app.editExpTime = uieditfield(app.ExposurePanel, 'text');
            app.editExpTime.ValueChangedFcn = createCallbackFcn(app, @editExpTime_Callback, true);
            app.editExpTime.Tag = 'editExpTime';
            app.editExpTime.Tooltip = 'Set (central) exposure time ';
            app.editExpTime.Value = '0';
            app.txtExposureUnits = uilabel(app.ExposurePanel);
            app.txtExposureUnits.Tag = 'txtExposureUnits';
            app.txtExposureUnits.Text = '(ms)';
            app.editNExposures = uieditfield(app.ExposurePanel, 'text');
            app.editNExposures.ValueChangedFcn = createCallbackFcn(app, @editNExposures_Callback, true);
            app.editNExposures.Tag = 'editNExposures';
            app.editNExposures.Visible = 'off';
            app.editNExposures.Tooltip = 'Number of exposure times';
            app.editNExposures.Value = '3';
            app.editExpFactor = uieditfield(app.ExposurePanel, 'text');
            app.editExpFactor.ValueChangedFcn = createCallbackFcn(app, @editExpFactor_Callback, true);
            app.editExpFactor.Tag = 'editExpFactor';
            app.editExpFactor.Visible = 'off';
            app.editExpFactor.Tooltip = 'Scale factor between exposure times';
            app.editExpFactor.Value = '2';
            app.popupExpMode = uidropdown(app.ExposurePanel);
            app.popupExpMode.Items = {'Single', 'Bracketing', 'CFA Exposure'};
            app.popupExpMode.ValueChangedFcn = createCallbackFcn(app, @popupExpMode_Callback, true);
            app.popupExpMode.Tag = 'popupExpMode';
            app.popupExpMode.Tooltip = 'Select exposure mode';
            app.popupExpMode.Value = 'Single';
            app.btnComputeImage = uibutton(app.sensorImageWindow, 'push');
            app.btnComputeImage.ButtonPushedFcn = createCallbackFcn(app, @btnComputeImage_Callback, true);
            app.btnComputeImage.Tag = 'btnComputeImage';
            app.btnComputeImage.Tooltip = 'Compute sensor  image from current optical image';
            app.btnComputeImage.Text = 'Compute Sensor  Image';
            app.editReadNoise = uieditfield(app.sensorImageWindow, 'text');
            app.editReadNoise.ValueChangedFcn = createCallbackFcn(app, @editReadNoise_Callback, true);
            app.editReadNoise.Tag = 'editReadNoise';
            app.editReadNoise.Tooltip = 'Set read noise';
            app.editReadNoise.Value = '0';
            app.txtReadNoise = uilabel(app.sensorImageWindow);
            app.txtReadNoise.Tag = 'txtReadNoise';
            app.txtReadNoise.Tooltip = 'read noise std dev in mV (noise floor)';
            app.txtReadNoise.Text = 'Read Noise (sd)';
            app.editDarkCurrent = uieditfield(app.sensorImageWindow, 'text');
            app.editDarkCurrent.ValueChangedFcn = createCallbackFcn(app, @editDarkCurrent_Callback, true);
            app.editDarkCurrent.Tag = 'editDarkCurrent';
            app.editDarkCurrent.Tooltip = 'Set dark current level';
            app.editDarkCurrent.Value = '0';
            app.txtDarkCurrent = uilabel(app.sensorImageWindow);
            app.txtDarkCurrent.Tag = 'txtDarkCurrent';
            app.txtDarkCurrent.Tooltip = 'pixel dark voltage';
            app.txtDarkCurrent.Text = 'Dk Voltage';
            app.editISARows = uieditfield(app.sensorImageWindow, 'text');
            app.editISARows.ValueChangedFcn = createCallbackFcn(app, @editISARows_Callback, true);
            app.editISARows.Tag = 'editISARows';
            app.editISARows.Tooltip = 'set sensor rows';
            app.editISARows.Value = '72';
            app.editISAcols = uieditfield(app.sensorImageWindow, 'text');
            app.editISAcols.ValueChangedFcn = createCallbackFcn(app, @editISAcols_Callback, true);
            app.editISAcols.Tag = 'editISAcols';
            app.editISAcols.Tooltip = 'set sensor cols';
            app.editISAcols.Value = '88';
            app.txtRow = uilabel(app.sensorImageWindow);
            app.txtRow.Tag = 'txtRow';
            app.txtRow.Tooltip = 'Adjust the number of pixels in the ISA';
            app.txtRow.Text = 'Sensor pixels  (r,c)';
            app.txtConvGain = uilabel(app.sensorImageWindow);
            app.txtConvGain.Tag = 'txtConvGain';
            app.txtConvGain.Tooltip = 'pixel conversion gain';
            app.txtConvGain.Text = 'Conv. Gain';
            app.editConvGain = uieditfield(app.sensorImageWindow, 'text');
            app.editConvGain.ValueChangedFcn = createCallbackFcn(app, @editConvGain_Callback, true);
            app.editConvGain.Tag = 'editConvGain';
            app.editConvGain.Tooltip = 'Set conversion gain';
            app.editConvGain.Value = '1';
            app.txtISA = uilabel(app.sensorImageWindow);
            app.txtISA.Tag = 'txtISA';
            app.txtISA.Tooltip = 'Select from standard CFA types';
            app.txtISA.Text = 'Standard CFA';
            app.txtPixel = uilabel(app.sensorImageWindow);
            app.txtPixel.Tag = 'txtPixel';
            app.txtPixel.Tooltip = 'Pixel properties';
            app.txtPixel.Text = 'Pixel';
            app.editVoltageSwing = uieditfield(app.sensorImageWindow, 'text');
            app.editVoltageSwing.ValueChangedFcn = createCallbackFcn(app, @editVoltageSwing_Callback, true);
            app.editVoltageSwing.Tag = 'editVoltageSwing';
            app.editVoltageSwing.Tooltip = 'Set voltage swing';
            app.editVoltageSwing.Value = '1';
            app.text12 = uilabel(app.sensorImageWindow);
            app.text12.Tag = 'text12';
            app.text12.Tooltip = 'also called saturation voltage';
            app.text12.Text = 'Volt Swing';
            app.editOffsetFPN = uieditfield(app.sensorImageWindow, 'text');
            app.editOffsetFPN.ValueChangedFcn = createCallbackFcn(app, @editOffsetFPN_Callback, true);
            app.editOffsetFPN.Tag = 'editOffsetFPN';
            app.editOffsetFPN.Tooltip = 'Set dark signal non-uniformity level';
            app.editOffsetFPN.Value = '0';
            app.editGainFPN = uieditfield(app.sensorImageWindow, 'text');
            app.editGainFPN.ValueChangedFcn = createCallbackFcn(app, @editGainFPN_Callback, true);
            app.editGainFPN.Tag = 'editGainFPN';
            app.editGainFPN.Tooltip = 'Set pixel response non-uniformity';
            app.editGainFPN.Value = '0';
            app.txtFPN = uilabel(app.sensorImageWindow);
            app.txtFPN.Tag = 'txtFPN';
            app.txtFPN.Tooltip = 'Dark signal non-uniformity (Offset Std. Dev.)';
            app.txtFPN.Text = 'DSNU';
            app.txtISADescription = uilabel(app.sensorImageWindow);
            app.txtISADescription.Tag = 'txtISADescription';
            app.txtISADescription.Tooltip = 'Image sensor array description';
            app.txtISADescription.Text = 'ISA';
            app.txtPixelDescription = uilabel(app.sensorImageWindow);
            app.txtPixelDescription.Tag = 'txtPixelDescription';
            app.txtPixelDescription.Tooltip = 'Pixel description';
            app.txtPixelDescription.Text = 'Pixel';
            app.txtDkCurrent = uilabel(app.sensorImageWindow);
            app.txtDkCurrent.Tag = 'txtDkCurrent';
            app.txtDkCurrent.Tooltip = 'millivolts/pixel/sec';
            app.txtDkCurrent.Text = 'mV/pixel/sec';
            app.text19 = uilabel(app.sensorImageWindow);
            app.text19.Tag = 'text19';
            app.text19.Tooltip = 'millivolts';
            app.text19.Text = 'mV';
            app.text20 = uilabel(app.sensorImageWindow);
            app.text20.Tag = 'text20';
            app.text20.Tooltip = 'microvolt/electron';
            app.text20.Text = 'uV/e-';
            app.text21 = uilabel(app.sensorImageWindow);
            app.text21.Tag = 'text21';
            app.text21.Tooltip = 'volts';
            app.text21.Text = 'V';
            app.text25 = uilabel(app.sensorImageWindow);
            app.text25.Tag = 'text25';
            app.text25.Tooltip = 'dark signal std. dev in millivolts';
            app.text25.Text = 'mv';
            app.editGam = uieditfield(app.sensorImageWindow, 'text');
            app.editGam.ValueChangedFcn = createCallbackFcn(app, @editGam_Callback, true);
            app.editGam.Tag = 'editGam';
            app.editGam.Tooltip = 'Set display gamma - data are not affected';
            app.editGam.Value = '1';
            app.txtDisplayGamma = uilabel(app.sensorImageWindow);
            app.txtDisplayGamma.Tag = 'txtDisplayGamma';
            app.txtDisplayGamma.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.txtDisplayGamma.Text = 'Gamma';
            app.txtPRNU = uilabel(app.sensorImageWindow);
            app.txtPRNU.Tag = 'txtPRNU';
            app.txtPRNU.Tooltip = 'Photoresponse non-uniformity (Gain Std. Dev.)';
            app.txtPRNU.Text = 'PRNU';
            app.text33 = uilabel(app.sensorImageWindow);
            app.text33.Tag = 'text33';
            app.text33.Tooltip = 'Select quantization method.  Analog setting means use continuous voltage.';
            app.text33.Text = 'Quantization';
            app.txtMVperSec = uilabel(app.sensorImageWindow);
            app.txtMVperSec.Tag = 'txtMVperSec';
            app.txtMVperSec.Tooltip = 'percent st. dev. of photoresponse slopes';
            app.txtMVperSec.Text = '%';
            app.txtConsistency = uilabel(app.sensorImageWindow);
            app.txtConsistency.Tag = 'txtConsistency';
            app.txtConsistency.Text = '';
            app.txtMessage = uilabel(app.sensorImageWindow);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.sliderSelectBracketedExposure = uislider(app.sensorImageWindow);
            app.sliderSelectBracketedExposure.Limits = [1 3];
            app.sliderSelectBracketedExposure.MajorTicks = [];
            app.sliderSelectBracketedExposure.ValueChangedFcn = createCallbackFcn(app, @sliderSelectBracketedExposure_Callback, true);
            app.sliderSelectBracketedExposure.MinorTicks = [];
            app.sliderSelectBracketedExposure.Tag = 'sliderSelectBracketedExposure';
            app.sliderSelectBracketedExposure.Visible = 'off';
            app.sliderSelectBracketedExposure.Tooltip = 'Select exposure from bracketed collection';
            app.sliderSelectBracketedExposure.Value = 1;
            app.txtBracketExposure = uilabel(app.sensorImageWindow);
            app.txtBracketExposure.Tag = 'txtBracketExposure';
            app.txtBracketExposure.Visible = 'off';
            app.txtBracketExposure.Tooltip = 'Select exposure';
            app.txtBracketExposure.Text = 'Exposure';
            app.popISA = uidropdown(app.sensorImageWindow);
            app.popISA.Items = {'Bayer RGB', 'Bayer CMY', 'RGBW', 'Monochrome', 'Other'};
            app.popISA.ValueChangedFcn = createCallbackFcn(app, @popISA_Callback, true);
            app.popISA.Tag = 'popISA';
            app.popISA.Tooltip = 'Standard CFA  types';
            app.popISA.Value = 'Bayer RGB';
            app.popupSelect = uidropdown(app.sensorImageWindow);
            app.popupSelect.Items = {'New', ''};
            app.popupSelect.ValueChangedFcn = createCallbackFcn(app, @popupSelect_Callback, true);
            app.popupSelect.Tag = 'popupSelect';
            app.popupSelect.Tooltip = 'Select sensor or create new';
            app.popupSelect.Value = 'New';
            app.popQuantization = uidropdown(app.sensorImageWindow);
            app.popQuantization.Items = {'Analog', '4 Bit', '8 Bit', '10 Bit', '12 Bit'};
            app.popQuantization.ValueChangedFcn = createCallbackFcn(app, @popQuantization_Callback, true);
            app.popQuantization.Tag = 'popQuantization';
            app.popQuantization.Tooltip = 'Set quantization method choice';
            app.popQuantization.Value = 'Analog';
            app.popScaleSize = uidropdown(app.sensorImageWindow);
            app.popScaleSize.Items = {'X', 'X 4', 'X 2', 'x 1/2', 'x 1/4'};
            app.popScaleSize.ValueChangedFcn = createCallbackFcn(app, @popScaleSize_Callback, true);
            app.popScaleSize.Tag = 'popScaleSize';
            app.popScaleSize.Tooltip = 'Scale row and col dimensions';
            app.popScaleSize.Value = 'X';
            app.btnNext = uibutton(app.sensorImageWindow, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Tooltip = 'Show next sensor';
            app.btnNext.Text = '->';
            app.btnPrev = uibutton(app.sensorImageWindow, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Tooltip = 'Show previous sensor';
            app.btnPrev.Text = '<-';
            app.txtScale = uilabel(app.sensorImageWindow);
            app.txtScale.Tag = 'txtScale';
            app.txtScale.Text = 'Scale';
            % Show the figure after all components are created
            app.sensorImageWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensorImageWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.sensorImageWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)sensorImageWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sensorImageWindow)
        end
    end
end