classdef pixelGeometryWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        btnDone                         matlab.ui.control.Button
        PixeldimensionsumPanel          matlab.ui.container.Panel
        editHeight                      matlab.ui.control.EditField
        editWidth                       matlab.ui.control.EditField
        editWidthGap                    matlab.ui.control.EditField
        editHeightGap                   matlab.ui.control.EditField
        txtHeight                       matlab.ui.control.Label
        txtWidth                        matlab.ui.control.Label
        txtHeightGap                    matlab.ui.control.Label
        txtWidthGap                     matlab.ui.control.Label
        PhotodetectordimensionsumPanel  matlab.ui.container.Panel
        editPDHeight                    matlab.ui.control.EditField
        editPDWidth                     matlab.ui.control.EditField
        editPDXPos                      matlab.ui.control.EditField
        editPDYpos                      matlab.ui.control.EditField
        txtPDHeight                     matlab.ui.control.Label
        txtPDWidth                      matlab.ui.control.Label
        txtHeightPosition               matlab.ui.control.Label
        txtWidthPosition                matlab.ui.control.Label
        edit1FillFactor                 matlab.ui.control.EditField
        txtPrct                         matlab.ui.control.Label
        txtFillFactor                   matlab.ui.control.Label
        PixelgeometryLabel              matlab.ui.control.Label
    end

    
    methods (Access = private)
        function refresh(app,varargin)
            % Always applies to the currently selected sensor in the
            % sensorWindow;
            sensor = ieGetObject('sensor');
            
            % if get(handles.btnMethod,'Value'), turnOffManual(app);
            % else, turnOnManual(app); end
            
            PIXEL = sensorGet(sensor,'pixel');
            
            % Pixel parameters
            str = sprintf('%.1f',pixelGet(PIXEL,'height','um'));
            app.editHeight.Value = str;
            str = sprintf('%.1f',pixelGet(PIXEL,'width','um'));
            app.editWidth.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'widthGap','um'));
            app.editWidthGap.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'heightGap','um'));
            app.editHeightGap.Value = str;
            
            % Photodetector parameters
            str = sprintf('%.2f',pixelGet(PIXEL,'pdHeight','um'));
            app.editPDHeight.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'pdWidth','um'));
            app.editPDWidth.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'pdXpos','um'));
            app.editPDXPos.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'pdYpos','um'));
            app.editPDYpos.Value = str;
            
            % Fill factor
            str = sprintf('%.0f',pixelGet(PIXEL,'fillfactor')*100);
            app.edit1FillFactor.Value = str;
            
            ieReplaceObject(sensor);
            
        end
        
        function turnOffManual(app)
            
            app.editPDHeight.Visible      = 'off';
            app.editPDWidth.Visible       = 'off';
            app.editPDXPos.Visible        = 'off';
            app.editPDYpos.Visible        = 'off';
            app.txtPDWidth.Visible        = 'off';
            app.txtPDHeight.Visible       = 'off';
            app.txtPhotodetector.Visible  = 'off';
            app.txtHeightPosition.Visible = 'off';
            app.txtWidthPosition.Visible  = 'off';
            
            % And turn on the centered detector
            app.txtFillFactor.Visible   = 'on';
            app.txtPrct.Visible         = 'on';
            app.edit1FillFactor.Visible = 'on';
            
        end
        
        function turnOnManual(app)
            
            app.editPDHeight.Visible = 'on';
            app.editPDWidth.Visible  = 'on';
            app.editPDXPos.Visible   = 'on';
            app.editPDYpos.Visible   = 'on';
            app.txtPDWidth.Visible   = 'on';
            app.txtPDHeight.Visible  = 'on';
            app.txtPhotodetector.Visible  = 'on';
            app.txtHeightPosition.Visible = 'on';
            app.txtWidthPosition.Visible  = 'on';
            
            % And turn off the centered detector
            app.txtFillFactor.Visible = 'off';
            app.txtPrct.Visible       = 'off';
            app.edit1FillFactor.Visible = 'off';
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pixelGeometryWindow_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % set(handles.btnMethod,'Value',1);
            % ieFontInit(app);
            
            % Bring this figure to the front
            refresh(app);
            
        end

        % Button pushed function: btnDone
        function btnDone_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % Now, bring forward the sensor image window and refresh its parameters
            
            % sensor = ieGetObject('sensor');
            sensorW = sensorWindow();
            sensorW.refresh;
            delete(app);
        end

        % Value changed function: edit1FillFactor
        function edit1FillFactor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % Use the fill factor value to set up a centered photodetector position
            %
            
            sensor = ieGetObject('sensor');
            fillfactor = str2double(get(handles.edit1FillFactor,'String'))/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            refresh(app);

        end

        % Value changed function: editHeightGap
        function editHeightGap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel height gap',str2double(get(hObject,'String'))*(1e-6));
            % sensor.pixel.heightGap = str2double(get(hObject,'String'))*(10^(-6));
            if get(handles.btnMethod,'Value')
                fillfactor = str2double(get(handles.edit1FillFactor,'String'))/100;
                sensor = pixelCenterFillPD(sensor,fillfactor);
            end
            ieReplaceObject(sensor);
            refresh(app);

        end

        % Value changed function: editHeight
        function editHeight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel height',str2double(get(hObject,'String'))*(1e-6));
            if get(handles.btnMethod,'Value')
                fillfactor = str2double(get(handles.edit1FillFactor,'String'))/100;
                sensor = pixelCenterFillPD(sensor,fillfactor);
            end
            ieReplaceObject(sensor);
            refresh(app);
        end

        % Value changed function: editPDHeight
        function editPDHeight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel pd height',str2double(get(hObject,'String'))*(1e-6));
            % sensor.pixel.pdHeight = str2double(get(hObject,'String'))*(10^(-6));
            ieReplaceObject(sensor);
            refresh(app);

        end

        % Value changed function: editPDWidth
        function editPDWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel pd width',str2double(get(hObject,'String'))*(1e-6));
            % sensor.pixel.pdWidth = str2double(get(hObject,'String'))*(10^(-6));
            ieReplaceObject(sensor);
            
            return
        end

        % Value changed function: editPDXPos
        function editPDXPos_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pd xpos',str2double(get(hObject,'String'))*(1e-6));
            % sensor.pixel.pdXpos = str2double(get(hObject,'String'))*(10^(-6));
            ieReplaceObject(sensor);
            refresh(app);
            
        end

        % Value changed function: editPDYpos
        function editPDYpos_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pd ypos',str2double(get(hObject,'String'))*(1e-6));
            % isa.pixel.pdYpos = str2double(get(hObject,'String'))*(10^(-6));
            ieReplaceObject(sensor);
            refresh(app);
            
        end

        % Value changed function: editWidthGap
        function editWidthGap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel width gap',str2double(get(hObject,'String'))*(1e-6));
            
            % sensor.pixel.widthGap = str2double(get(hObject,'String'))*(1e-6);
            if get(handles.btnMethod,'Value')
                fillfactor = str2double(get(handles.edit1FillFactor,'String'))/100;
                sensor = pixelCenterFillPD(sensor,fillfactor);
            end
            ieReplaceObject(sensor);
            refresh(app);
            
            return
        end

        % Value changed function: editWidth
        function editWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel width',str2double(get(hObject,'String'))*(1e-6));
            if get(handles.btnMethod,'Value')
                fillfactor = str2double(get(handles.edit1FillFactor,'String'))/100;
                sensor = pixelCenterFillPD(sensor,fillfactor);
            end
            ieReplaceObject(sensor);
            refresh(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'pixelGeometryWindow';
            app.figure1.Tag = 'figure1';
            app.btnDone = uibutton(app.figure1, 'push');
            app.btnDone.ButtonPushedFcn = createCallbackFcn(app, @btnDone_Callback, true);
            app.btnDone.Tag = 'btnDone';
            app.btnDone.Text = 'Done';
            app.PixeldimensionsumPanel = uipanel(app.figure1);
            app.PixeldimensionsumPanel.Title = 'Pixel dimensions (um)';
            app.editHeight = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editHeight.ValueChangedFcn = createCallbackFcn(app, @editHeight_Callback, true);
            app.editHeight.Tag = 'editHeight';
            app.editHeight.Value = '5';
            app.editWidth = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editWidth.ValueChangedFcn = createCallbackFcn(app, @editWidth_Callback, true);
            app.editWidth.Tag = 'editWidth';
            app.editWidth.Value = '5';
            app.editWidthGap = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editWidthGap.ValueChangedFcn = createCallbackFcn(app, @editWidthGap_Callback, true);
            app.editWidthGap.Tag = 'editWidthGap';
            app.editWidthGap.Value = '0';
            app.editHeightGap = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editHeightGap.ValueChangedFcn = createCallbackFcn(app, @editHeightGap_Callback, true);
            app.editHeightGap.Tag = 'editHeightGap';
            app.editHeightGap.Value = '0';
            app.txtHeight = uilabel(app.PixeldimensionsumPanel);
            app.txtHeight.Tag = 'txtHeight';
            app.txtHeight.Text = 'Height';
            app.txtWidth = uilabel(app.PixeldimensionsumPanel);
            app.txtWidth.Tag = 'txtWidth';
            app.txtWidth.Text = 'Width';
            app.txtHeightGap = uilabel(app.PixeldimensionsumPanel);
            app.txtHeightGap.Tag = 'txtHeightGap';
            app.txtHeightGap.Text = 'H Gap';
            app.txtWidthGap = uilabel(app.PixeldimensionsumPanel);
            app.txtWidthGap.Tag = 'txtWidthGap';
            app.txtWidthGap.Text = 'W Gap';
            app.PhotodetectordimensionsumPanel = uipanel(app.figure1);
            app.PhotodetectordimensionsumPanel.Title = 'Photodetector dimensions (um)';
            app.editPDHeight = uieditfield(app.PhotodetectordimensionsumPanel, 'text');
            app.editPDHeight.ValueChangedFcn = createCallbackFcn(app, @editPDHeight_Callback, true);
            app.editPDHeight.Tag = 'editPDHeight';
            app.editPDHeight.Value = '.25';
            app.editPDWidth = uieditfield(app.PhotodetectordimensionsumPanel, 'text');
            app.editPDWidth.ValueChangedFcn = createCallbackFcn(app, @editPDWidth_Callback, true);
            app.editPDWidth.Tag = 'editPDWidth';
            app.editPDWidth.Value = '.25';
            app.editPDXPos = uieditfield(app.PhotodetectordimensionsumPanel, 'text');
            app.editPDXPos.ValueChangedFcn = createCallbackFcn(app, @editPDXPos_Callback, true);
            app.editPDXPos.Tag = 'editPDXPos';
            app.editPDXPos.Value = '.12';
            app.editPDYpos = uieditfield(app.PhotodetectordimensionsumPanel, 'text');
            app.editPDYpos.ValueChangedFcn = createCallbackFcn(app, @editPDYpos_Callback, true);
            app.editPDYpos.Tag = 'editPDYpos';
            app.editPDYpos.Value = '.12';
            app.txtPDHeight = uilabel(app.PhotodetectordimensionsumPanel);
            app.txtPDHeight.Tag = 'txtPDHeight';
            app.txtPDHeight.Text = 'PD Height';
            app.txtPDWidth = uilabel(app.PhotodetectordimensionsumPanel);
            app.txtPDWidth.Tag = 'txtPDWidth';
            app.txtPDWidth.Text = 'PD Width';
            app.txtHeightPosition = uilabel(app.PhotodetectordimensionsumPanel);
            app.txtHeightPosition.Tag = 'txtHeightPosition';
            app.txtHeightPosition.Text = 'Y Position';
            app.txtWidthPosition = uilabel(app.PhotodetectordimensionsumPanel);
            app.txtWidthPosition.Tag = 'txtWidthPosition';
            app.txtWidthPosition.Text = 'X Position';
            app.edit1FillFactor = uieditfield(app.PhotodetectordimensionsumPanel, 'text');
            app.edit1FillFactor.ValueChangedFcn = createCallbackFcn(app, @edit1FillFactor_Callback, true);
            app.edit1FillFactor.Tag = 'edit1FillFactor';
            app.edit1FillFactor.Tooltip = 'Set percentage of pixel area occupied by the detector';
            app.edit1FillFactor.Value = '50';
            app.txtPrct = uilabel(app.PhotodetectordimensionsumPanel);
            app.txtPrct.Tag = 'txtPrct';
            app.txtPrct.Text = '%';
            app.txtFillFactor = uilabel(app.PhotodetectordimensionsumPanel);
            app.txtFillFactor.Tag = 'txtFillFactor';
            app.txtFillFactor.Tooltip = 'Percentage of pixel area occupied by the detector';
            app.txtFillFactor.Text = 'Fill factor';
            app.PixelgeometryLabel = uilabel(app.figure1);
            app.PixelgeometryLabel.Text = 'Pixel geometry';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pixelGeometryWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)pixelGeometryWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end