classdef ieSetProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SavePropertiesButton  matlab.ui.control.Button
        LoadPropertiesButton  matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        objType = ''; % the kind of object we are working with
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, objType)
            
            app.UIFigure.Name = "Load and Set ISET Objects";
            % Need a switch for who called us, just do OI for now!
            app.UITable.ColumnEditable = [false true]; % will be false true false once we add units
            
            app.objType = objType;
            switch objType
                case 'oi'
                    oi = ieGetObject('oi');
                    iePTable(oi.optics,'uitable',app.UITable,'format','embed');
                case 'ip'
                    % tbd
                case 'sensor'
                    sensor = ieGetObject('sensor');
                    iePTable(sensor,'uitable',app.UITable,'format','embed');
                otherwise
                    warning('Unsupported datatype for properties load/save...')
            end
            
            
        end

        % Button pushed function: SavePropertiesButton
        function SavePropertiesButtonPushed(app, event)
            ourData = app.UITable.Data;
            
            % see what our JSON data looks like:
            outputString = jsonencode(array2table(ourData));
            [saveFileName, saveFilePath] = uiputfile("*.json","Save Properties");
            saveFile = fopen(fullfile(saveFilePath, saveFileName), "w");
            fprintf(saveFile, outputString);
            fclose(saveFile);
            
        end

        % Button pushed function: LoadPropertiesButton
        function LoadPropertiesButtonPushed(app, event)
            
            % see what our JSON data looks like:
            [loadFileName, loadFilePath] = uigetfile("*.json","Load Properties");
            if isfile(fullfile(loadFilePath, loadFileName))
                stringData = fileread(fullfile(loadFilePath, loadFileName));
                ourData = jsondecode(stringData);
                app.UITable.Data = transpose(struct2cell(ourData));
            else
                uialert(app.UIFigure,"No file selected.");
            end
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices; % [row col]
            newData = event.NewData; % new value char array if it is two numbers like row/col
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.LoadPropertiesButton = uibutton(app.UIFigure, 'push');
            app.LoadPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPropertiesButtonPushed, true);
            app.LoadPropertiesButton.Text = 'Load Properties ...';
            app.SavePropertiesButton = uibutton(app.UIFigure, 'push');
            app.SavePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePropertiesButtonPushed, true);
            app.SavePropertiesButton.Text = 'Save Properties...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ieSetProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end