classdef imageSetHarmonic_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        editFreq      matlab.ui.control.EditField
        editContrast  matlab.ui.control.EditField
        text2         matlab.ui.control.Label
        editRow       matlab.ui.control.EditField
        editCol       matlab.ui.control.EditField
        text3         matlab.ui.control.Label
        text4         matlab.ui.control.Label
        editPh        matlab.ui.control.EditField
        text5         matlab.ui.control.Label
        editAng       matlab.ui.control.EditField
        text6         matlab.ui.control.Label
        btnDone       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imageSetHarmonic_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % Choose default command line output for imageSetHarmonic
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes imageSetHarmonic wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            return
        end

        % Button pushed function: btnDone
        function btnDone_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if ieNotDefined('closeMe'), closeMe = 1; end
            
            global parms
            
            parms.freq =      str2num(get(handles.editFreq,'String'));
            parms.contrast =  str2num(get(handles.editContrast,'String'));
            parms.ph =        str2num(get(handles.editPh,'String'));
            parms.ang =       str2num(get(handles.editAng,'String'));
            parms.row =       str2num(get(handles.editRow,'String'));
            parms.col =       str2num(get(handles.editCol,'String'));
            parms.GaborFlag = get(handles.btnGabor,'Value');
            
            if closeMe, close(gcbf); end
            
            return
        end

        % Value changed function: editAng
        function editAng_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Value changed function: editCol
        function editCol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Value changed function: editContrast
        function editContrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Value changed function: editFreq
        function editFreq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Value changed function: editPh
        function editPh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Value changed function: editRow
        function editRow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'imageSetHarmonic';
            app.figure1.Tag = 'figure1';
            app.editFreq = uieditfield(app.figure1, 'text');
            app.editFreq.ValueChangedFcn = createCallbackFcn(app, @editFreq_Callback, true);
            app.editFreq.Tag = 'editFreq';
            app.editFreq.Value = '1';
            app.editContrast = uieditfield(app.figure1, 'text');
            app.editContrast.ValueChangedFcn = createCallbackFcn(app, @editContrast_Callback, true);
            app.editContrast.Tag = 'editContrast';
            app.editContrast.Value = '1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Tooltip = 'Set spatial frequency';
            app.text2.Text = 'Freq (c/image)';
            app.editRow = uieditfield(app.figure1, 'text');
            app.editRow.ValueChangedFcn = createCallbackFcn(app, @editRow_Callback, true);
            app.editRow.Tag = 'editRow';
            app.editRow.Value = '64';
            app.editCol = uieditfield(app.figure1, 'text');
            app.editCol.ValueChangedFcn = createCallbackFcn(app, @editCol_Callback, true);
            app.editCol.Tag = 'editCol';
            app.editCol.Value = '64';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Tooltip = 'Image row and col size';
            app.text3.Text = 'Row, Col';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Tooltip = 'Set contrast (0-1)';
            app.text4.Text = 'Contrast (0,1)';
            app.editPh = uieditfield(app.figure1, 'text');
            app.editPh.ValueChangedFcn = createCallbackFcn(app, @editPh_Callback, true);
            app.editPh.Tag = 'editPh';
            app.editPh.Value = '0';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Tooltip = 'Set phase (0=cos, pi/2 = sin)';
            app.text5.Text = 'Phase (0-2pi)';
            app.editAng = uieditfield(app.figure1, 'text');
            app.editAng.ValueChangedFcn = createCallbackFcn(app, @editAng_Callback, true);
            app.editAng.Tag = 'editAng';
            app.editAng.Value = '0';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Tooltip = 'Set phase (0=cos, pi/2 = sin)';
            app.text6.Text = 'Angle (0-pi)';
            app.btnDone = uibutton(app.figure1, 'push');
            app.btnDone.ButtonPushedFcn = createCallbackFcn(app, @btnDone_Callback, true);
            app.btnDone.Tag = 'btnDone';
            app.btnDone.Text = 'Done';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageSetHarmonic_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)imageSetHarmonic_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end