classdef plotSpectralImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        WavelengthLabel   matlab.ui.control.Label
        waveSlider        matlab.ui.control.Slider
        MovieButton       matlab.ui.control.Button
        ColorimageButton  matlab.ui.control.Button
        ColorSwitchLabel  matlab.ui.control.Label
        ColorSwitch       matlab.ui.control.Switch
        imageAxis         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wave % N x 1 description of wavelengths
        data % (row, col, N) radiance or irradiance image data
    end
    
    methods (Access = private)
        
        function spectralImage(app)
            % First draft.  Something like this
            % Find a color and then create the wavelength image in the
            % image.
            
            % Read the slider and figure out the wavelength
            thisWave  = app.waveSlider.Value;
            mx = max(app.data(:));
            img = app.data(:,:,app.wave==thisWave);
            img = img/mx;
            sz = size(img);
            
            switch(app.ColorSwitch.Value)
                case 'On'
                    % Figure out a reasonable color for this wavelength
                    tmp = double(app.wave==thisWave);
                    tmp = reshape(tmp,1,1,numel(app.wave));
                    rgb = xyz2srgb(ieXYZFromPhotons(tmp,app.wave));
                    rgb = squeeze(rgb); rgb = rgb/max(rgb(:));
                    % Make an rgb image this color
                    thisImg = reshape(img(:)*rgb(:)',sz(1),sz(2),3);
                case 'Off'
                    %
                    rgb = [1,1,1];
                    thisImg = reshape(img(:)*rgb(:)',sz(1),sz(2),3);
            end
            
            image(app.imageAxis, thisImg);
            
        end
        
        function spectralMovie(app)
            % Show as a color movie.  Maybe it should be black and white
            % It is scaled by the max of the entire datacube.
            for ii = 1:numel(app.wave)
                app.waveSlider.Value = app.wave(ii);
                spectralImage(app);
                pause(0.2);
                %{
                tmp = double(app.wave==thisWave);
                tmp = reshape(tmp,1,1,numel(app.wave));
                rgb = xyz2srgb(ieXYZFromEnergy(tmp,app.wave));
                rgb = squeeze(rgb); rgb = rgb/max(rgb(:));
                
                img = app.data(:,:,app.wave==thisWave);
                img = img/mx;
                
                % Make an rgb image this color
                thisImg = reshape(img(:)*rgb(:)',sz(1),sz(2),3);
                image(app.imageAxis, thisImg); drawnow;
                %}
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)
            % Decide if the input is a scene or an oi
            % Store the wavelengths and radiance or irradiance data in the
            % app
            switch obj.type
                case 'scene'
                    app.wave = double(sceneGet(obj,'wave'));
                    app.data = double(sceneGet(obj,'photons'));
                    app.imageAxis.Title.String = 'Radiance (quanta)';
                case 'opticalimage'
                    app.wave = double(oiGet(obj,'wave'));
                    app.data = double(oiGet(obj,'photons'));
                    app.imageAxis.Title.String = 'Irradiance (quanta)';
                otherwise
                    error('Input must be a scene or an optical image.');
            end
            
            % Adjust the slider based on the wavelength range of the obj.
            % We use the major ticks at every 50 nm.
            app.waveSlider.Limits = double([app.wave(1),app.wave(end)]);
            minWave = round(app.wave(1)/50)*50;
            maxWave = round(app.wave(end)/50)*50;
            app.waveSlider.MajorTicks = linspace(minWave,maxWave,5);
            app.waveSlider.MinorTicks = [];
            
            axis(app.imageAxis,'tight');
            
            % Put a middle wavelength image into the window
            midWave = round((minWave + maxWave)/2);
            app.waveSlider.Value = midWave;  % Find a method for this number
            
            % From now on, everything is a callback.
            spectralImage(app);
        end

        % Button pushed function: MovieButton
        function movieButton(app, event)
            % Show a movie in the image axis
            spectralMovie(app);
        end

        % Button pushed function: ColorimageButton
        function colorImageButton(app, event)
            photons = app.data;
            wList   = app.wave;
            sz      = size(app.data);
            gam = 1.0;
            rgb = imageSPD(photons,wList,gam,sz(1),sz(2),-1);
            image(app.imageAxis,rgb);
        end

        % Value changed function: waveSlider
        function waveSliderCallback(app, event)
            value = app.waveSlider.Value;
            [~,idx] = min(abs(value - app.wave));
            thisWave = app.wave(idx);
            app.waveSlider.Value = thisWave;
            spectralImage(app);
        end

        % Value changed function: ColorSwitch
        function colorOnOff(app, event)
            spectralImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WavelengthLabel = uilabel(app.UIFigure);
            app.WavelengthLabel.Text = 'Wavelength';
            app.waveSlider = uislider(app.UIFigure);
            app.waveSlider.ValueChangedFcn = createCallbackFcn(app, @waveSliderCallback, true);
            app.MovieButton = uibutton(app.UIFigure, 'push');
            app.MovieButton.ButtonPushedFcn = createCallbackFcn(app, @movieButton, true);
            app.MovieButton.Text = 'Movie';
            app.ColorimageButton = uibutton(app.UIFigure, 'push');
            app.ColorimageButton.ButtonPushedFcn = createCallbackFcn(app, @colorImageButton, true);
            app.ColorimageButton.Text = 'Color image';
            app.ColorSwitchLabel = uilabel(app.UIFigure);
            app.ColorSwitchLabel.Text = 'Color';
            app.ColorSwitch = uiswitch(app.UIFigure, 'slider');
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @colorOnOff, true);
            app.ColorSwitch.Value = 'On';
            app.imageAxis = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotSpectralImage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end