classdef sensorChannelImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ChannelLabel  matlab.ui.control.Label
        SliderImage   matlab.ui.control.Slider
        UIAxesImage   matlab.ui.control.UIAxes
        UIAxesGraph   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sensor = []; % The ISETCam sensor
        imgs = [];   % Derived from sensor via sensorDemosaic
        
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function channelImage(app)
            % Render a color image of the ith channel in the axis.
            ii = app.SliderImage.Value;
            if size(app.imgs,3) == 3
                thisImg = app.imgs;
                thisImg(:,:,1:3 ~= ii) = 0;
                image(app.UIAxesImage,thisImg);
            else
                lst = 1:numel(sensorGet(app.sensor,'pattern'));
                T = sensorDisplayTransform(app.sensor);
                thisImg = app.imgs;
                thisImg(:,:,lst ~= ii) = 0;
                thisImg = imageLinearTransform(thisImg,T);
                image(app.UIAxesImage, thisImg);
            end
        end
        
        function channelPlot(app)
            % Plot the channel color filter curve in the graph
            ii = app.SliderImage.Value;
            
            wave = sensorGet(app.sensor,'wave');
            cf = sensorGet(app.sensor,'color filters');
            plot(app.UIAxesGraph,wave,cf(:,ii));
            % app.UIAxesGraph.XTick = [0.1 max(cf(:,ii))];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, sensor)
            % We send in sensor and create and store the imgs
            
            % Save data
            app.sensor = sensor;
            app.imgs = sensorDemosaic(sensor);
            
            % Adjust UI elements
            app.SliderImage.Limits =[1,size(app.imgs,3)];
            app.SliderImage.MajorTicks = 1:size(app.imgs,3);
            
            wave = sensorGet(app.sensor,'wave');
            app.UIAxesGraph.XLim = [min(wave)-10, max(wave)+10];
            app.UIAxesGraph.XTick = [min(wave), max(wave)];
            app.UIAxesGraph.XTickLabel = {num2str(min(wave)),num2str(max(wave))};
            app.UIAxesGraph.YLim = [0 1];
            app.UIAxesGraph.YTick = [0 0.5 1];
            app.UIAxesGraph.YTickLabel = {'0' '0.5','1'};
            
            axis(app.UIAxesImage,'tight');
            
            channelImage(app);
            channelPlot(app);
            
            
        end

        % Value changed function: SliderImage
        function channelSlider(app, event)
            value = app.SliderImage.Value;
            
            % Find the closest major tick
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            
            % Set the value to that tick
            app.SliderImage.Value = event.Source.MajorTicks(minIdx);
            
            % Selected the value to the major tick
            % value = event.Source.MajorTicks(minIdx);
            
            channelImage(app);
            channelPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChannelLabel = uilabel(app.UIFigure);
            app.ChannelLabel.Text = 'Channel';
            app.SliderImage = uislider(app.UIFigure);
            app.SliderImage.Limits = [0 20];
            app.SliderImage.ValueChangedFcn = createCallbackFcn(app, @channelSlider, true);
            app.SliderImage.MinorTicks = [];
            app.UIAxesImage = uiaxes(app.UIFigure);
            zlabel(app.UIAxesImage, 'Z')
            app.UIAxesImage.XTick = [];
            app.UIAxesImage.YTick = [];
            app.UIAxesGraph = uiaxes(app.UIFigure);
            xlabel(app.UIAxesGraph, 'Wave (nm)')
            ylabel(app.UIAxesGraph, 'Transmissivity')
            zlabel(app.UIAxesGraph, 'Z')
            app.UIAxesGraph.XTick = [400 500 600 700];
            app.UIAxesGraph.XTickLabel = {'400'; '500'; '600'; '700'};
            app.UIAxesGraph.YTick = [0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensorChannelImage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end