classdef webImageBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadlocalfolderMenu          matlab.ui.container.Menu
        LoadimageasISETsceneMenu     matlab.ui.container.Menu
        SaveimageslocallyMenu        matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        WavelengthRangeMenu          matlab.ui.container.Menu
        nmto700nmby10nanometersMenu  matlab.ui.container.Menu
        nmto700nmby1nanometerMenu    matlab.ui.container.Menu
        MaxSearchResultsMenu         matlab.ui.container.Menu
        maxSearchResults20           matlab.ui.container.Menu
        maxSearchResults50           matlab.ui.container.Menu
        maxSearchResults100          matlab.ui.container.Menu
        LocalCacheMenu               matlab.ui.container.Menu
        KeepdownloadedimagesandscenesMenu  matlab.ui.container.Menu
        DeletedownloadedimagesandscenesMenu  matlab.ui.container.Menu
        mnuLimitFlickrSize           matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        OnSearchingMenu              matlab.ui.container.Menu
        FooterPanel                  matlab.ui.container.Panel
        Image2                       matlab.ui.control.Image
        LoadLocalFolderButton        matlab.ui.control.Button
        OpenFolderinMatlabButton     matlab.ui.control.Button
        ClickonanimagetoopenasanISETSceneLabel  matlab.ui.control.Label
        HeaderPanel                  matlab.ui.container.Panel
        Image3                       matlab.ui.control.Image
        btnSearchHelp                matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        SearchButton                 matlab.ui.control.Button
        EnterkeywordsEditField       matlab.ui.control.EditField
        EnterkeywordsLabel           matlab.ui.control.Label
        SelectImageSourceDropDown    matlab.ui.control.DropDown
        SelectImageSourceLabel       matlab.ui.control.Label
        ISETImageExplorerLabel       matlab.ui.control.Label
        ViewerPanel                  matlab.ui.container.Panel
        ContextMenu                  matlab.ui.container.ContextMenu
        TitleMenu                    matlab.ui.container.Menu
        DescriptionMenu              matlab.ui.container.Menu
    end

    
    properties (Access= public)
        ImageArray matlab.ui.control.Image
    end
    properties (Access = private)
        fname
        imgGridColumns
        imgGridRows
        currentFolder
        currentImageFiles; % want array of loaded images, but no native image object
        desireThumbnailSize = 100; % pixels in each dimension
        imageSpacer = 10;
        blankimage=zeros(20,20,3); % seems like there should be a better way to clear an image!!
        dataType = ''; % set to the type of data we are likely to be bringing back!
        flickrObj; % our handle to use Flickr
        pixabayObj; % our handle for Pixabay
        hyperObj; % our handle to look for Hyperspectral data
        multiObj; % for multispectral resources
        hdrObj; % handle for HDR data
        locObj; % handle for Library of Congress Data
        defaultWavelist = 400:10:700;
        openISETtext = "Click on one to open as an ISET scene."
        viewerBorder = 30; % amount to leave to the right of images
        localImageCache = fullfile(isetRootPath, 'local', 'images');
        maxImageResolution = 1024; % allow user to set to speed performance
        
        % menu text that we need to be able to match later
        flickrMenuText = 'Flickr (RGB images)';
        pixabayMenuText = 'Pixabay (RGB images)';
        hyperMenuText = 'Hyperspectral scenes';
        multiMenuText = 'Multispectral scenes';
        hdrMenuText = 'HDR images and scenes';
        locMenuText = 'Library Of Congress (photos)';
    end
    
    methods (Access = private)
        function ImageClickedCallback(app, event)
            persistent inImageClicked;
            if inImageClicked == true
                uialert(app.UIFigure,"Already loading a scene...", "Extra click?", 'Icon', 'info');
            else
                inImageClicked = true;
            end
            switch app.dataType
                case 'Flickr'
                    app.UIFigure.Pointer = 'watch';
                    app.flickrObj.displayScene(event.Source.UserData, 'RGB');
                    app.UIFigure.Pointer = 'arrow';
                    
                case 'Pixabay'
                    app.UIFigure.Pointer = 'watch';
                    app.pixabayObj.displayScene(event.Source.UserData, 'RGB');
                    app.UIFigure.Pointer = 'arrow';
                    
                case 'LOC'
                    app.UIFigure.Pointer = 'watch';
                    app.locObj.displayScene(event.Source.UserData, 'RGB');
                    app.UIFigure.Pointer = 'arrow';
                    
                case 'Hyperspectral'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading image...','Message','Multi-spectral images can be large, so be patient.');
                    % we can't load hyperspectral data directly, as it
                    % doesn't come in as the correct type
                    %imageData = app.hyperObj.getImage(event.Source.UserData, 'large');
                    % for some reason certain images don't get the proper
                    % object
                    try
                        app.hyperObj.displayScene(event.Source.UserData, 'Hyperspectral');
                        close(dlg);
                    catch
                        close(dlg);
                        warning("Unable to resolve image URL.");
                    end
                case 'Multispectral'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading image...','Message','Multi-spectral images can be large, so be patient.');
                    try
                        app.multiObj.displayScene(event.Source.UserData, 'Multispectral');
                        close(dlg);
                    catch
                        close(dlg);
                        warning("Unable to resolve image URL.");
                    end
                case 'HDR'
                    % In the future, we might be using EXR files and then
                    % we will have to deal with sceneFromFile and catch the
                    % exr extension. (see exrread).
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading image...','Message','HDR images can be large, so be patient.');
                    app.hdrObj.displayScene(event.Source.UserData, 'HDR');
                    close(dlg);
                    
                otherwise % case 'File'
                    app.UIFigure.Pointer = 'watch';
                    scene = sceneFromFile(event.Source.ImageSource,'rgb',[],[],getpref('ISET','openRGBwavelist', app.defaultWavelist));
                    sceneWindow(scene);
                    app.UIFigure.Pointer = 'arrow';
            end
            
            inImageClicked = false;
        end
        
        function [imageWidth, imageHeight] =  calcImageSize(app)
            gridSize = app.ViewerPanel.InnerPosition;
            gridWidth = gridSize(3);
            gridHeight = gridSize(4);
            app.imgGridRows = max(1,floor(gridHeight / app.desireThumbnailSize));
            app.imgGridColumns = max(1,floor(gridWidth / app.desireThumbnailSize));
            app.imageSpacer = 10; % In Case
            imageWidth = floor(gridWidth / app.imgGridColumns) - app.imageSpacer;
            imageHeight = floor(gridHeight / app.imgGridRows) - app.imageSpacer;
        end
        
        function [imageFiles] = loadImages(app, folder)
            jpgFiles = dir(fullfile(folder,'*.jpg'));
            jpegFiles = dir(fullfile(folder,'*.jpeg'));
            pngFiles = dir(fullfile(folder,'*.png'));
            gifFiles = dir(fullfile(folder,'*.gif'));
            
            % there must be an easier way to sort out all image files!!
            imageFiles = [jpgFiles;jpegFiles;pngFiles;gifFiles]; % try to simply concat
        end
        
        function folderPopulateBrowser(app, imageFileList, reload)
            
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(imageFileList) / app.imgGridColumns);
            
            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading folder...','Message',app.currentFolder);
                
                clearImageArray(app, app.ImageArray);
                for i = 1:length(imageFileList)
                    app.ImageArray(i) = uiimage(app.ViewerPanel);
                    % try stashing the photo object or the filepath in the
                    % UserData instead of just the index into the array
                    app.ImageArray(i).UserData = fullfile(imageFileList(i).folder,imageFileList(i).name);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);
                    % see if we can get a context menu
                    app.ImageArray(i).ContextMenu = app.ContextMenu;
                    
                    ourImageFile = fullfile(imageFileList(i).folder,imageFileList(i).name);
                    app.ImageArray(i).Tooltip = imageFileList(i).name;
                    app.ImageArray(i).ImageSource = ourImageFile;
                    app.ImageArray(i).Visible = true;
                end
                close(dlg);
                
            end
            app.UIFigure.Pointer = 'watch';
            redrawGrid(app, imageWidth, imageHeight, length(imageFileList));
            app.UIFigure.Pointer = 'arrow';
        end
        
        % imageArray is the Photos struct we have gotten back from Flickr
        % or Pixabay
        function sharingSitePopulateBrowser(app, passedObj, ourImageArray, reload)
            
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            if isequal(passedObj, app.flickrObj)
                app.imgGridRows = ceil(length(ourImageArray.photo) / app.imgGridColumns);
                imageCount = length(ourImageArray.photo);
            elseif isequal(passedObj, app.pixabayObj)
                app.imgGridRows = ceil(length(ourImageArray.hits) / app.imgGridColumns);
                imageCount = length(ourImageArray.hits);
            else
                msgbox("Unknown service.");
                return
            end
            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading Images from the web...','Message','Search Results');
                
                clearImageArray(app, app.ImageArray)
                for ii = 1:imageCount
                    if ii > length(app.ImageArray)
                        app.ImageArray(ii) = uiimage(app.ViewerPanel);
                    end
                    app.ImageArray(ii).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);
                    if isequal(passedObj, app.flickrObj)
                        app.ImageArray(ii).UserData = ourImageArray.photo(ii);
                        app.ImageArray(ii).Tooltip = passedObj.getImageTitle(ourImageArray.photo(ii));
                        ourImageURL = passedObj.getImageURL(ourImageArray.photo(ii), 'thumbnail');
                    elseif isequal(passedObj, app.pixabayObj)
                        app.ImageArray(ii).UserData = ourImageArray.hits(ii);
                        app.ImageArray(ii).Tooltip = passedObj.getImageTitle(ourImageArray.hits(ii));
                        ourImageURL = passedObj.getImageURL(ourImageArray.hits(ii), 'thumbnail');
                    end
                    % try to create a per image context menu, web photo sharing sites only
                    % gives us titles so far!
                    aContextMenu = uicontextmenu(app.UIFigure);
                    uimenu(aContextMenu, "Text", app.ImageArray(ii).Tooltip);
                    removeMenu = uimenu(aContextMenu, "Text", "Remove Image from Collection");
                    aContextMenu.UserData = ii; % we need a hook back to which item to dump!
                    removeMenu.UserData = ii; % we need a hook back to which item to dump!
                    removeMenu.MenuSelectedFcn = createCallbackFcn(app, @sharingSiteRemoveMenuCallback, true);
                    app.ImageArray(ii).ContextMenu = aContextMenu;
                    
                    app.ImageArray(ii).Visible = true;
                    try
                        ourImage = webread(ourImageURL);
                        if ismatrix(ourImage)
                            % probably grayscale and image control needs
                            % 'color'
                            ourImage = cat(3, ourImage, ourImage, ourImage);
                        end
                        app.ImageArray(ii).ImageSource = ourImage;
                    catch
                        warning("Can't get image!")
                    end
                    
                end
                close(dlg);
                app.ClickonanimagetoopenasanISETSceneLabel.Text = append("Found ", string(imageCount), ...
                    " images. ", app.openISETtext);
            end
            app.UIFigure.Pointer = 'watch';
            redrawGrid(app, imageWidth, imageHeight, imageCount);
            app.UIFigure.Pointer = 'arrow';
        end
        
        function sharingSiteRemoveMenuCallback(app, event)
            if ~isequal(event.Source.UserData, [])
                imageIndex = event.Source.UserData;
                % not sure what to set it to?
                app.ImageArray(imageIndex).ImageSource = app.blankimage;
                app.ImageArray(imageIndex).UserData = [];
                
            end
        end
        
        % should be melded with other populates once we figure them out!
        % imageArray is the Photos struct we have gotten back from LOC
        function locPopulateBrowser(app, ourImageArray, reload)
            
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(ourImageArray) / app.imgGridColumns);
            
            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading Images from LOC...','Message','Search Results');
                
                clearImageArray(app, app.ImageArray)
                for i = 1:length(ourImageArray)
                    if i > length(app.ImageArray)
                        app.ImageArray(i) = uiimage(app.ViewerPanel);
                    end
                    app.ImageArray(i).UserData = ourImageArray(i);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);
                    app.ImageArray(i).Tooltip = app.locObj.getImageTitle(ourImageArray(i));
                    
                    app.ImageArray(i).ContextMenu = app.ContextMenu;
                    
                    ourImageURL = app.locObj.getImageURL(ourImageArray(i), 'thumbnail');
                    app.ImageArray(i).Visible = true;
                    try
                        ourImage = webread(ourImageURL);
                    catch
                        warning("Can't get image!")
                    end
                    if ismatrix(ourImage)
                        % probably grayscale and image control needs
                        % 'color'
                        ourImage = cat(3, ourImage, ourImage, ourImage);
                    end
                    app.ImageArray(i).ImageSource = ourImage;
                    % NOT KEEPING META DATA AROUND YET, BUT SHOULD SHOW
                    % THUMBNAILS?
                end
                close(dlg);
                app.ClickonanimagetoopenasanISETSceneLabel.Text = append("Found ", string(length(ourImageArray)), ...
                    " images. ", app.openISETtext);
            end
            app.UIFigure.Pointer = 'watch';
            redrawGrid(app, imageWidth, imageHeight, length(ourImageArray));
            app.UIFigure.Pointer = 'arrow';
        end
        
        % Handles the cases where we are getting ISET scenes from the web
        function webPopulateBrowser(app, ourObj, ourImageArray, reload)
            
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(ourImageArray) / app.imgGridColumns);
            
            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading Scenes ...','Message','Search Results');
                
                clearImageArray(app, app.ImageArray);
                for i = 1:length(ourImageArray)
                    if i > length(app.ImageArray)
                        app.ImageArray(i) = uiimage(app.ViewerPanel);
                    end
                    app.ImageArray(i).UserData = ourImageArray(i);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);
                    
                    % try to create a per image context menu
                    aContextMenu = uicontextmenu(app.UIFigure);
                    
                    uimenu(aContextMenu, "Text", strcat("Title:       ", ourImageArray(i).Name));
                    uimenu(aContextMenu, "Text", strcat("Description: ", ourImageArray(i).Description));
                    uimenu(aContextMenu, "Text", strcat("Location:    ", ourImageArray(i).URL));
                    keywordString = strjoin(string(ourImageArray(i).Keywords));
                    uimenu(aContextMenu, "Text", strcat("Keywords:    ", keywordString));
                    app.ImageArray(i).ContextMenu = aContextMenu;
                    
                    ourImageURL = ourObj.getImageURL(ourImageArray(i), 'thumbnail');
                    app.ImageArray(i).Tooltip = ourObj.getImageTitle(ourImageArray(i));
                    try
                        ourImage = webread(ourImageURL,'ContentType','image');
                    catch
                        warning(strcat("Can't get image: ", ourImageURL));
                    end
                    if ismatrix(ourImage)
                        % probably grayscale and image control needs
                        % 'color'
                        ourImage = cat(3, ourImage, ourImage, ourImage);
                    end
                    app.ImageArray(i).ImageSource = ourImage;
                end
                close(dlg);
                
            end
            app.ClickonanimagetoopenasanISETSceneLabel.Text = append("Found ", string(length(ourImageArray)), ...
                " images. ", app.openISETtext);
            app.UIFigure.Pointer = 'watch';
            redrawGrid(app, imageWidth, imageHeight, length(ourImageArray));
            app.UIFigure.Pointer = 'arrow';
        end
        
        function clearImageArray(app, imageArray)
            for i = 1:length(imageArray)
                % can we empty the thing?
                imageArray(i).ImageSource = app.blankimage; % fingers crossed
                imageArray(i).UserData = [];
                imageArray(i).Visible = false;
                % can we clear the context menu here, what do we set it to?
                
            end
            
        end
        % djc -- if we now have less images, grid may be too large or start
        % from the bottom left instead of the top left. Annoying:)
        function redrawGrid(app, imageWidth, imageHeight, numImages)
            % this is kind of stupid, we either need to get or set but not
            % both!
            [imageWidth, imageHeight] =  calcImageSize(app);
            app.imgGridRows = ceil(numImages / app.imgGridColumns);
            index = 1;
            for i = app.imgGridRows:-1:1
                for j = 1:app.imgGridColumns
                    if index <= numImages
                        % allocate new image control if needed
                        app.ImageArray(index).Position = [app.imageSpacer+(j-1)*(imageWidth+app.imageSpacer) app.imageSpacer+(i-1)*(imageHeight+app.imageSpacer) imageWidth imageHeight];
                        app.ImageArray(index).Visible = true;
                        index = index + 1;
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dbApp = ieSessionGet('imageexplore window');
            if isempty(dbApp) || ~isvalid(dbApp)
                % Store this app in the database
                ieSessionSet('imageexplore window',app);
            end
            
            % If the person has set a position and size preference, put the window
            % there
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if numel(wPos) > 6 && ~isempty(wPos{7}) % this is so gross!
                    movegui(app.UIFigure,wPos{7});
                else
                    movegui(app.UIFigure,"west");
                end
            end
            
             if ~verLessThan('matlab','9.9') && getpref('ISET','customicons', true)
                app.UIFigure.Icon = 'ImageIcon.png';
            end
            
            % initialize until we figure out what we're doing
            [imageWidth, imageHeight] = calcImageSize(app);
            % hack to set blankimage to background gray
            app.blankimage(:,:,1:3) = app.ViewerPanel.BackgroundColor(1);
            app.SelectImageSourceDropDown.Items = {app.flickrMenuText, app.pixabayMenuText, app.hyperMenuText,  ...
                app.multiMenuText, app.hdrMenuText, app.locMenuText, ''};
            app.SelectImageSourceDropDown.Value = app.flickrMenuText;
            
            % can't figure out how to change the display name of the
            % UIFigure in the Designer!
            app.UIFigure.Name = "ISET Image and Scene Explorer";
            % these are our defaults for now, so if we change the default we
            % need to change this too!
            if isequal(getpref('ISET','openRGBwavelist',400:10:700),400:10:700)
                app.nmto700nmby10nanometersMenu.Checked = 'on';
            else
                app.nmto700nmby1nanometerMenu.Checked = 'on';
            end
            
            if getpref('ISET','keepDownloads', false) == false
                app.DeletedownloadedimagesandscenesMenu.Checked = 'on';
            else
                app.KeepdownloadedimagesandscenesMenu.Checked = 'on';
            end
            
            maxResults = getpref('ISET','maxSearchResults', 20);
            if maxResults == 100
                app.maxSearchResults100.Checked = 'on';
            elseif maxResults == 50
                app.maxSearchResults50.Checked = 'on';
            else
                app.maxSearchResults20.Checked = 'on';
            end
            
            % re-use property setting if not found
            app.maxImageResolution = getpref('ISET','maxSharingSiteImageResolution',app.maxImageResolution);
            
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            % updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: OpenFolderinMatlabButton
        function OpenFolderinMatlabButtonPushed(app, event)
            % djc hack to get a random folder
            if isdeployed
                uialert(app.UIFigure, 'Image Browser not supported in runtime. Sorry.', 'Image Browser not Available', 'Icon', 'info');
            else
                imageBrowser(app.currentFolder);
            end
            
        end

        % Callback function: LoadLocalFolderButton, 
        % LoadlocalfolderMenu
        function LoadLocalFolderButtonPushed(app, event)
            app.dataType = 'File';
            ourDir = uigetdir(); % can give it a default
            if ~isequal(ourDir,0)
                app.currentFolder = ourDir;
                app.UIFigure.Pointer = 'watch';
                app.currentImageFiles = loadImages(app, ourDir);
                folderPopulateBrowser(app, app.currentImageFiles, true);
                app.OpenFolderinMatlabButton.Enable = true;
                app.OpenFolderinMatlabButton.Visible = true;
                % should really also turn it off later on!
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            uialert(app.UIFigure,'Figure Button down called.', "Okay?", 'Icon', 'info');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web("https://vistalab.stanford.edu/",'-browser');
            
        end

        % Size changed function: ViewerPanel
        function ViewerPanelSizeChanged(app, event)
            % for some reason the panel doesn't downsize when the window
            % gets smaller, so I think we need to do it manually!
            persistent savedPanelPosition;
            persistent savedWindowPosition;
            
            
            if isvalid(app.ViewerPanel) % we also get called after it is deleted
                
                if isempty(savedPanelPosition)
                    savedPanelPosition = [0 0 0 0];
                end
                if isempty(savedWindowPosition)
                    savedWindowPosition = [0 0 0 0];
                end
                panelPosition = app.ViewerPanel.Position;
                windowPosition = app.UIFigure.Position;
                % check if either our w/h or Window w/h have changed:
                if not(isequal(panelPosition(3), savedPanelPosition(3))) || ...
                        not(isequal(panelPosition(4), savedPanelPosition(4))) || ...
                        not(isequal(windowPosition(3), savedWindowPosition(3))) || ...
                        not(isequal(windowPosition(4), savedWindowPosition(4)))
                    try
                        app.UIFigure.Pointer = 'watch';
                        savedPanelPosition = panelPosition; % keep to compare next time
                        savedWindowPosition = windowPosition; % keep to compare next time

                        %if the window is resized, this might already be
                        %set for us before we get here!
                        %if position(3) > app.UIFigure.Position(3) - ourBorder
                        %position(3) = app.UIFigure.Position(3) - ourBorder; % hack:)
                        %app.ViewerPanel.Position(3) = position(3);
                        %end
                        %if position(4) > app.UIFigure.Position(4) - ourBorder
                        %    position(4) = app.UIFigure.Position(4) - ourBorder; % hack:)
                        %    app.ViewerPanel.Position(4) = position(4);
                        %end
                        if ~isempty(app.ImageArray) % first time we get called image controls haven't been created yet
                            % only want to repaint if we have changed the number of
                            % rows or columns
                            prevRows = app.imgGridRows; prevColumns = app.imgGridColumns;
                            [imageWidth, imageHeight] = calcImageSize(app);
                            if app.imgGridColumns ~= prevColumns || ...
                                    app.imgGridRows ~= prevRows
                                % does this still work?
                                %folderPopulateBrowser(app, app.currentImageFiles, false);
                                % assume we just need to draw the images
                                % already in the grid:
                                redrawGrid(app, imageWidth, imageHeight, length(app.ImageArray));
                            end
                        end
                    catch ME
                        app.UIFigure.Pointer = 'arrow';
                        rethrow(ME);
                    end
                    app.UIFigure.Pointer = 'arrow';
                end
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            panelSpacing = 8; % pixel count for separating elements
            figPos = app.UIFigure.Position;
            
            % we'd like to reposition the panels so that the Header
            % panel is at the top, the Footer at the bottom, and
            % allow the Viewer panel to expand/contract
            curHeaderPos = app.HeaderPanel.Position;
            headerYPos = figPos(4) - panelSpacing - curHeaderPos(4); % at the top
            app.HeaderPanel.Position = [curHeaderPos(1) headerYPos curHeaderPos(3) curHeaderPos(4)];
            app.HeaderPanel.Visible = true; % okay to display now
            
            % put the Footer at the bottom
            % maybe it never moves from there?
            curFooterPos = app.FooterPanel.Position;
            %app.FooterPanel.Position = [curFooterPos(1) panelSpacing curFooterPos(3) curFooterPos(4)];
            
            % put the browser window in the space that's left
            curViewerPos = app.ViewerPanel.Position;
            viewerHeight = headerYPos - 4*panelSpacing - curFooterPos(4);
            viewerWidth = app.UIFigure.Position(3) - app.viewerBorder; % hack:)
            
            app.ViewerPanel.Position = [curViewerPos(1) curViewerPos(2) viewerWidth viewerHeight];
            % we call this proactively in case we don't get resized
            % Automatically. It caches, so won't redraw if not changed
            ViewerPanelSizeChanged(app, event);
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            switch(app.SelectImageSourceDropDown.Value)
                case {app.flickrMenuText, app.pixabayMenuText}
                    if isequal(app.SelectImageSourceDropDown.Value, app.flickrMenuText)
                        app.dataType = 'Flickr';
                        if ~exist('app.flickrObj', "var")
                            app.flickrObj = webFlickr();
                        end
                        ourObj = app.flickrObj;
                    else
                        app.dataType = 'Pixabay';
                        if ~exist('app.pixabayObj', "var")
                            app.pixabayObj = webPixabay();
                        end
                        ourObj = app.pixabayObj;
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    app.UIFigure.Pointer = 'watch';
                    resultList = ourObj.search(ourTags);
                    % now we need to populate our browser with the results!
                    if isequal(app.dataType, 'Flickr')
                        if ~isempty(resultList.photos.photo)
                            sharingSitePopulateBrowser(app, ourObj, resultList.photos, true);
                        else
                            uialert(app.UIFigure,"No search results.","Sharing site search...", 'Icon', 'info');
                        end
                    else %Pixabay
                        if ~isempty(resultList.hits)
                            sharingSitePopulateBrowser(app, ourObj, resultList, true);
                        else
                            uialert(app.UIFigure,"No search results.","Sharing site search...", 'Icon', 'info');
                        end
                    end
                    app.UIFigure.Pointer = 'arrow';
                    
                case app.locMenuText
                    app.dataType = 'LOC';
                    if ~exist('app.locObj', "var")
                        app.locObj = webLOC();
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    app.UIFigure.Pointer = 'watch';
                    resultList = app.locObj.search(ourTags);
                    % now we need to populate our browser with the results!
                    % NEEDS TO BE FIXED HERE!!!
                    if ~isempty(resultList)
                        locPopulateBrowser(app, resultList, true);
                    else
                        uialert(app.UIFigure,"No search results from Library of Congress.","LOC search...", 'Icon', 'info');
                    end
                    app.UIFigure.Pointer = 'arrow';
                    
                case app.hyperMenuText
                    app.dataType = 'Hyperspectral';
                    if ~exist('app.hyperObj', "var")
                        app.hyperObj = webData('Hyperspectral');
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    if isempty(ourTags)
                        ourTags = " ";
                    end
                    app.UIFigure.Pointer = 'watch';
                    resultList = app.hyperObj.search(ourTags);
                    if ~isempty(resultList)
                        webPopulateBrowser(app, app.hyperObj, resultList, true);
                    else
                        uialert(app.UIFigure,"No matching Hyperspectral images found.","Hyperspectral search...", 'Icon', 'info');
                    end
                    app.UIFigure.Pointer = 'arrow';
                    
                case app.multiMenuText
                    app.dataType = 'Multispectral';
                    if ~exist('app.multiObj', "var")
                        app.multiObj = webData('Multispectral');
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    if isempty(ourTags)
                        ourTags = " ";
                    end
                    app.UIFigure.Pointer = 'watch';
                    resultList = app.multiObj.search(ourTags);
                    if ~isempty(resultList)
                        webPopulateBrowser(app, app.multiObj, resultList, true);
                    else
                        uialert(app.UIFigure,"No matching Multispectral images found.","Hyperspectral search...", 'Icon', 'info');
                    end
                    app.UIFigure.Pointer = 'arrow';
                    
                case app.hdrMenuText
                    app.dataType = 'HDR';
                    if ~exist('app.hdrObj', "var")
                        app.hdrObj = webData('HDR');
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    if isempty(ourTags)
                        ourTags = " ";
                    end
                    app.UIFigure.Pointer = 'watch';
                    resultList = app.hdrObj.search(ourTags);
                    if ~isempty(resultList)
                        webPopulateBrowser(app, app.hdrObj, resultList, true);
                    else
                        uialert(app.UIFigure,"No matching HDR images found.","HDR search...", 'Icon', 'info');
                    end
                    app.UIFigure.Pointer = 'arrow';
                    
                otherwise
                    uialert(app.UIFigure,"Service not supported.", "ISET Image Explorer.",'Icon', 'warning');
            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Menu selected function: DescriptionMenu
        function DescriptionMenuSelected(app, event)
            
        end

        % Menu selected function: TitleMenu
        function TitleMenuSelected(app, event)
            
        end

        % Menu selected function: nmto700nmby10nanometersMenu
        function nmto700nmby10nanometersMenuSelected(app, event)
            setpref('ISET','openRGBwavelist',400:10:700);
            app.nmto700nmby10nanometersMenu.Checked = 'on';
            app.nmto700nmby1nanometerMenu.Checked = 'off';
        end

        % Menu selected function: nmto700nmby1nanometerMenu
        function nmto700nmby1nanometerMenuSelected(app, event)
            setpref('ISET','openRGBwavelist',400:1:700);
            app.nmto700nmby1nanometerMenu.Checked = 'on';
            app.nmto700nmby10nanometersMenu.Checked = 'off';
        end

        % Menu selected function: maxSearchResults100, 
        % maxSearchResults20, maxSearchResults50
        function MenuSelected(app, event)
            % if we just want numbers could just convert the string to an
            % integer, but we might want some other possibilities later on.
            app.maxSearchResults20.Checked = 'off';
            app.maxSearchResults50.Checked = 'off';
            app.maxSearchResults100.Checked = 'off';
            
            event.Source.Checked = 'on';
            % how do we turn the others off? Can we make a group?
            switch event.Source.Text
                case "20"
                    maxImages = 20;
                case "50"
                    maxImages = 50;
                case "100"
                    maxImages = 100;
            end
            setpref('ISET','maxSearchResults', maxImages);
        end

        % Menu selected function: LoadimageasISETsceneMenu
        function LoadimageasISETsceneMenuSelected(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.fname = [p f]; % matlab sample code does this, but shouldn't they use a path function?
                scene = sceneFromFile(app.fname,'rgb',[],[],getpref('ISET','openRGBwavelist', app.defaultWavelist));
                scene = sceneSet(scene, 'name', f);
                sceneWindow(scene);
                
            end
        end

        % Menu selected function: KeepdownloadedimagesandscenesMenu
        function KeepdownloadedimagesandscenesMenuSelected(app, event)
            setpref('ISET','keepDownloads', true);
            app.KeepdownloadedimagesandscenesMenu.Checked = 'on';
            app.DeletedownloadedimagesandscenesMenu.Checked = 'off';
            
        end

        % Menu selected function: 
        % DeletedownloadedimagesandscenesMenu
        function DeletedownloadedimagesandscenesMenuSelected(app, event)
            setpref('ISET','keepDownloads', false);
            app.KeepdownloadedimagesandscenesMenu.Checked = 'off';
            app.DeletedownloadedimagesandscenesMenu.Checked = 'on';

        end

        % Callback function: OnSearchingMenu, btnSearchHelp
        function btnSearchHelpButtonPushed(app, event)
            helpdlg({'The ISET Explorer can look for images of several types:','',...
                'Flickr and Pixabay images are user-contributed RGB photos.',...
                '',...
                'Hyperspectral images are ISET format and ready-to-load as scenes.',...
                'Most are captured using wavelengths from 400nm to 900nm.', ...
                '',...
                'Multispectral images are also ISET ready.',...
                '',...
                'HDR images are either in ISET format or EXR format (future feature).',...
                'They are RGB, and by default load from 400nm to 700nm in 10nm steps.', ...
                '',...
                'Library of Congress images are RGB and retrieved using freeform search text.', ...
                '', ...
                'In all cases search terms can be separated with commas, and only images', ...
                'containing all the terms will be returned.', ...
                '',...
                'You can set how many images you want returned and the default Wavelength', ...
                'step size using the Settings menu.'}, "Search Help");
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % I'm not sure how we simply close us!
            closereq();
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Menu selected function: SaveimageslocallyMenu
        function SaveimageslocallyMenuSelected(app, event)
            
            % option to save certain types of images (Flickr, maybe LOC)
            % locally for use in future ML projects
            if ~isfolder(app.localImageCache)
                mkdir(app.localImageCache);
            end
            
            switch app.dataType
                case {'Flickr', 'Pixabay'}
                    ourDir = uigetdir(app.localImageCache); % can give it a default
                    if ourDir == 0
                        return
                    end
                    if ~isfolder(ourDir)
                        mkdir(ourDir);
                    end
                    progDlg = uiprogressdlg(app.UIFigure,"Title", "Saving images...", "Value",0);
                    for i = 1:length(app.ImageArray)
                        if ~isequal(app.ImageArray(i).UserData,[])
                            % sometimes already a string, but not always
                            ourPhotoTitle = string(app.ImageArray(i).UserData.id); % Titles aren't always filenames!
                            ourFileName = fullfile(ourDir, strcat(ourPhotoTitle,".jpg"));
                            try
                                if isequal(app.dataType, 'Flickr')
                                    websave(ourFileName, app.flickrObj.getImageURL(app.ImageArray(i).UserData,'large'));
                                else
                                    websave(ourFileName, app.pixabayObj.getImageURL(app.ImageArray(i).UserData,'large'));
                                end
                                % squish file if larger than user wants
                                tempImage = imread(ourFileName);
                                [rows, cols, ~] = size(tempImage);
                                if rows > app.maxImageResolution || cols > app.maxImageResolution
                                    if rows > cols
                                        tempImage = imresize(tempImage, [app.maxImageResolution NaN]);
                                    else
                                        tempImage = imresize(tempImage, [NaN app.maxImageResolution]);
                                    end
                                    imwrite(tempImage, ourFileName);
                                end
                                progDlg.Value = i / length(app.ImageArray);
                            catch
                                warning(strcat("Bad file name for writing: ", ourFileName));
                            end
                        end
                    end
                    close(progDlg);
                otherwise
                    uialert(app.UIFigure,"Save locally not supported for this image type.","Unable to Save Images");
            end
            
        end

        % Menu selected function: mnuLimitFlickrSize
        function mnuLimitFlickrSizeMenuSelected(app, event)
            % large images can be slow to process
            % so give the user the option to limit the size we
            % pass to ISET
            dims = [1 35];
            userResponse = inputdlg("Enter Maximum Image Resolution for images retrieved from Photo Sharing Sites:","Set Maximum H/W in Pixels", dims, {char(string(app.maxImageResolution))});
            if ~isempty(userResponse)
                userInput = str2double(userResponse{1});
                if userInput > 100 && userInput < 2048
                    app.maxImageResolution = userInput;
                    setpref('ISET','maxSharingSiteImageResolution',userInput);
                else
                    msgbox("Allowed values are between 100 and 2048.");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ISET Image Finder';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadlocalfolderMenu = uimenu(app.FileMenu);
            app.LoadlocalfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLocalFolderButtonPushed, true);
            app.LoadlocalfolderMenu.Text = 'Load local folder...';
            app.LoadimageasISETsceneMenu = uimenu(app.FileMenu);
            app.LoadimageasISETsceneMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageasISETsceneMenuSelected, true);
            app.LoadimageasISETsceneMenu.Text = 'Load image as ISET scene...';
            app.SaveimageslocallyMenu = uimenu(app.FileMenu);
            app.SaveimageslocallyMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveimageslocallyMenuSelected, true);
            app.SaveimageslocallyMenu.Separator = 'on';
            app.SaveimageslocallyMenu.Text = 'Save images locally...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.WavelengthRangeMenu = uimenu(app.SettingsMenu);
            app.WavelengthRangeMenu.Text = 'Wavelength Range';
            app.nmto700nmby10nanometersMenu = uimenu(app.WavelengthRangeMenu);
            app.nmto700nmby10nanometersMenu.MenuSelectedFcn = createCallbackFcn(app, @nmto700nmby10nanometersMenuSelected, true);
            app.nmto700nmby10nanometersMenu.Text = '400nm to 700nm by 10 nanometers';
            app.nmto700nmby1nanometerMenu = uimenu(app.WavelengthRangeMenu);
            app.nmto700nmby1nanometerMenu.MenuSelectedFcn = createCallbackFcn(app, @nmto700nmby1nanometerMenuSelected, true);
            app.nmto700nmby1nanometerMenu.Text = '400nm to 700nm by 1 nanometer';
            app.MaxSearchResultsMenu = uimenu(app.SettingsMenu);
            app.MaxSearchResultsMenu.Text = 'Max Search Results';
            app.maxSearchResults20 = uimenu(app.MaxSearchResultsMenu);
            app.maxSearchResults20.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.maxSearchResults20.Text = '20';
            app.maxSearchResults50 = uimenu(app.MaxSearchResultsMenu);
            app.maxSearchResults50.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.maxSearchResults50.Text = '50';
            app.maxSearchResults100 = uimenu(app.MaxSearchResultsMenu);
            app.maxSearchResults100.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.maxSearchResults100.Text = '100';
            app.LocalCacheMenu = uimenu(app.SettingsMenu);
            app.LocalCacheMenu.Text = 'Local Cache';
            app.KeepdownloadedimagesandscenesMenu = uimenu(app.LocalCacheMenu);
            app.KeepdownloadedimagesandscenesMenu.MenuSelectedFcn = createCallbackFcn(app, @KeepdownloadedimagesandscenesMenuSelected, true);
            app.KeepdownloadedimagesandscenesMenu.Text = 'Keep downloaded images and scenes';
            app.DeletedownloadedimagesandscenesMenu = uimenu(app.LocalCacheMenu);
            app.DeletedownloadedimagesandscenesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletedownloadedimagesandscenesMenuSelected, true);
            app.DeletedownloadedimagesandscenesMenu.Text = 'Delete downloaded images and scenes';
            app.mnuLimitFlickrSize = uimenu(app.SettingsMenu);
            app.mnuLimitFlickrSize.MenuSelectedFcn = createCallbackFcn(app, @mnuLimitFlickrSizeMenuSelected, true);
            app.mnuLimitFlickrSize.Tooltip = {'Rendering high-resolution scenes can be slow. You can limit the maximum resolution used from Flickr to speed things up.'; ''};
            app.mnuLimitFlickrSize.Text = 'Limit size of sharing site Images...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OnSearchingMenu = uimenu(app.HelpMenu);
            app.OnSearchingMenu.MenuSelectedFcn = createCallbackFcn(app, @btnSearchHelpButtonPushed, true);
            app.OnSearchingMenu.Text = 'On Searching';
            app.ViewerPanel = uipanel(app.UIFigure);
            app.ViewerPanel.SizeChangedFcn = createCallbackFcn(app, @ViewerPanelSizeChanged, true);
            app.HeaderPanel = uipanel(app.UIFigure);
            app.ISETImageExplorerLabel = uilabel(app.HeaderPanel);
            app.ISETImageExplorerLabel.Text = 'ISET Image Explorer';
            app.SelectImageSourceLabel = uilabel(app.HeaderPanel);
            app.SelectImageSourceLabel.Text = 'Select Image Source:';
            app.SelectImageSourceDropDown = uidropdown(app.HeaderPanel);
            app.SelectImageSourceDropDown.Items = {'Flickr (RGB images)', 'Pixabay (RGB images)', 'Hyperspectral', 'Multispectral', 'HDR'};
            app.SelectImageSourceDropDown.Tooltip = {'The source or type of images and scenes that can be used by ISET that you want to find.'};
            app.SelectImageSourceDropDown.Value = 'Flickr (RGB images)';
            app.EnterkeywordsLabel = uilabel(app.HeaderPanel);
            app.EnterkeywordsLabel.Text = 'Enter keywords (,):';
            app.EnterkeywordsEditField = uieditfield(app.HeaderPanel, 'text');
            app.EnterkeywordsEditField.Tooltip = {'Separate keywords with commas. Images containing all keywords will be returned.'};
            app.SearchButton = uibutton(app.HeaderPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Image = uiimage(app.HeaderPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tooltip = {'ISET Image Explorer is brought to you by Stanford University''s VISTA Lab'};
            app.Image.ImageSource = 'Vista_Lab_Logo.png';
            app.btnSearchHelp = uibutton(app.HeaderPanel, 'push');
            app.btnSearchHelp.ButtonPushedFcn = createCallbackFcn(app, @btnSearchHelpButtonPushed, true);
            app.btnSearchHelp.Icon = 'icon_info.png';
            app.btnSearchHelp.Text = '';
            app.Image3 = uiimage(app.HeaderPanel);
            app.Image3.ImageSource = 'ImageIcon.svg';
            app.FooterPanel = uipanel(app.UIFigure);
            app.ClickonanimagetoopenasanISETSceneLabel = uilabel(app.FooterPanel);
            app.ClickonanimagetoopenasanISETSceneLabel.Text = 'Click on an image to open as an ISET Scene';
            app.OpenFolderinMatlabButton = uibutton(app.FooterPanel, 'push');
            app.OpenFolderinMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderinMatlabButtonPushed, true);
            app.OpenFolderinMatlabButton.Enable = 'off';
            app.OpenFolderinMatlabButton.Visible = 'off';
            app.OpenFolderinMatlabButton.Tooltip = {'Manipulate the images in the folder using Matlab''s built-in image browser.'};
            app.OpenFolderinMatlabButton.Text = 'Open Folder in Matlab';
            app.LoadLocalFolderButton = uibutton(app.FooterPanel, 'push');
            app.LoadLocalFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocalFolderButtonPushed, true);
            app.LoadLocalFolderButton.Enable = 'off';
            app.LoadLocalFolderButton.Visible = 'off';
            app.LoadLocalFolderButton.Tooltip = {'You can also browse your local file system for a folder of images.'};
            app.LoadLocalFolderButton.Text = 'Load Local Folder';
            app.Image2 = uiimage(app.FooterPanel);
            app.Image2.ImageSource = 'pixabay_logo.jpg';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.TitleMenu = uimenu(app.ContextMenu);
            app.TitleMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleMenuSelected, true);
            app.TitleMenu.Text = 'Title';
            app.DescriptionMenu = uimenu(app.ContextMenu);
            app.DescriptionMenu.MenuSelectedFcn = createCallbackFcn(app, @DescriptionMenuSelected, true);
            app.DescriptionMenu.Text = 'Description';
            % Assign app.ContextMenu
            app.ViewerPanel.ContextMenu = app.ContextMenu;
            app.Image.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webImageBrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end